classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AccueilTab                      matlab.ui.container.Tab
        InformationsPanel_2             matlab.ui.container.Panel
        GridLayout13_2                  matlab.ui.container.GridLayout
        WpLpFiWfGpfLgWgLabel            matlab.ui.control.Label
        Label_676                       matlab.ui.control.Label
        Label_677                       matlab.ui.control.Label
        E6S6Label                       matlab.ui.control.Label
        HrFrLabel_2                     matlab.ui.control.Label
        CalculThoriqueLabel             matlab.ui.control.Label
        E5S5Label                       matlab.ui.control.Label
        ErLabel                         matlab.ui.control.Label
        HFrWpLpLabel                    matlab.ui.control.Label
        HWpLpLabel                      matlab.ui.control.Label
        ErFrLabel                       matlab.ui.control.Label
        E4S4Label                       matlab.ui.control.Label
        HLabel                          matlab.ui.control.Label
        HErLabel                        matlab.ui.control.Label
        WpLpLabel                       matlab.ui.control.Label
        InputLabel                      matlab.ui.control.Label
        OutputLabel                     matlab.ui.control.Label
        ErFrWpLpLabel                   matlab.ui.control.Label
        FrWpLpLabel                     matlab.ui.control.Label
        HrFrLabel                       matlab.ui.control.Label
        E3S3Label                       matlab.ui.control.Label
        E2S2Label                       matlab.ui.control.Label
        E1S1Label                       matlab.ui.control.Label
        E0S0Tab                         matlab.ui.container.Tab
        TabGroup2_9                     matlab.ui.container.TabGroup
        CalculTab_9                     matlab.ui.container.Tab
        DataPanel_6                     matlab.ui.container.Panel
        GridLayout2_21                  matlab.ui.container.GridLayout
        Z0EditField_0                   matlab.ui.control.NumericEditField
        Z0EditField_16Label             matlab.ui.control.Label
        Label_77                        matlab.ui.control.Label
        tEditField_0                    matlab.ui.control.NumericEditField
        tEditField_6Label               matlab.ui.control.Label
        FmaxEditField_0                 matlab.ui.control.NumericEditField
        FmaxEditField_6Label            matlab.ui.control.Label
        FminEditField_0                 matlab.ui.control.NumericEditField
        FminEditField_6Label            matlab.ui.control.Label
        mLabel_6                        matlab.ui.control.Label
        GHzLabel_21                     matlab.ui.control.Label
        GHzLabel_20                     matlab.ui.control.Label
        UITable_0                       matlab.ui.control.Table
        CalculButton_9                  matlab.ui.control.Button
        TransfrerversCSTButton_0        matlab.ui.control.Button
        ToutEffacerButton_0             matlab.ui.control.Button
        EffacerlaligneButton_0          matlab.ui.control.Button
        Image_9                         matlab.ui.control.Image
        DfautButton_6                   matlab.ui.control.Button
        RsultatsPanel                   matlab.ui.container.Panel
        GridLayout3_23                  matlab.ui.container.GridLayout
        WgEditField_0                   matlab.ui.control.NumericEditField
        WgEditField_19Label             matlab.ui.control.Label
        LgEditField_0                   matlab.ui.control.NumericEditField
        LgEditField_19Label             matlab.ui.control.Label
        GpfEditField_0                  matlab.ui.control.NumericEditField
        GpfEditField_19Label            matlab.ui.control.Label
        WfEditField_0                   matlab.ui.control.NumericEditField
        WfEditField_19Label             matlab.ui.control.Label
        FiEditField_0                   matlab.ui.control.NumericEditField
        FiEditField_19Label             matlab.ui.control.Label
        LpEditField_0                   matlab.ui.control.NumericEditField
        LpEditField_19Label             matlab.ui.control.Label
        WpEditField_0                   matlab.ui.control.NumericEditField
        WpEditField_19Label             matlab.ui.control.Label
        mmLabel_164                     matlab.ui.control.Label
        mmLabel_163                     matlab.ui.control.Label
        mmLabel_162                     matlab.ui.control.Label
        mmLabel_161                     matlab.ui.control.Label
        mmLabel_160                     matlab.ui.control.Label
        mmLabel_159                     matlab.ui.control.Label
        mmLabel_158                     matlab.ui.control.Label
        EntresPanel_14                  matlab.ui.container.Panel
        GridLayout2_20                  matlab.ui.container.GridLayout
        FrEditField_0                   matlab.ui.control.NumericEditField
        FrEditField_16Label             matlab.ui.control.Label
        ErEditField_0                   matlab.ui.control.NumericEditField
        ErEditField_16Label             matlab.ui.control.Label
        HEditField_0                    matlab.ui.control.NumericEditField
        HEditField_16Label              matlab.ui.control.Label
        GHzLabel_19                     matlab.ui.control.Label
        Label_76                        matlab.ui.control.Label
        mmLabel_157                     matlab.ui.control.Label
        UIAxesSij0                      matlab.ui.control.UIAxes
        E1S1Tab                         matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        EntrainementTab_5               matlab.ui.container.Tab
        ErreurmoyenneEditField_1        matlab.ui.control.EditField
        ErreurmoyenneEditField_3Label   matlab.ui.control.Label
        ValeurspardfautButton_1         matlab.ui.control.Button
        OptiLabel1                      matlab.ui.control.Label
        CouchesoptimalesLabel_1         matlab.ui.control.Label
        ControlPlotButtonGroup          matlab.ui.container.ButtonGroup
        PlotGeneralPerformanceButton_1  matlab.ui.control.ToggleButton
        PlotTrainingButton_1            matlab.ui.control.ToggleButton
        PlotPerformanceButton_1         matlab.ui.control.ToggleButton
        ErreurLabel_1                   matlab.ui.control.Label
        CommandesPanel                  matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        LpButton_1                      matlab.ui.control.Button
        WpButton_1                      matlab.ui.control.Button
        ChemSauvLabel_1                 matlab.ui.control.Label
        FichierdesauvegardeLabel_1      matlab.ui.control.Label
        BDdentranementLabel_1           matlab.ui.control.Label
        nomBdLabel_1                    matlab.ui.control.Label
        OptionsPanel                    matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        RechercheCouchenbNeuronnesoptimauxButton_1  matlab.ui.control.Button
        EntranerlerseauButton_1         matlab.ui.control.Button
        EnregistrerlerseauButton_1      matlab.ui.control.Button
        SlectionnerlabasededonneButton_1  matlab.ui.control.Button
        ParamtresPanel                  matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        CouchesFormatLabel_1            matlab.ui.control.Label
        CouchesneuronnesEditField_1     matlab.ui.control.EditField
        CouchesneuronnesEditField_7Label  matlab.ui.control.Label
        EpochsEditField_1               matlab.ui.control.NumericEditField
        EpochsEditField_3Label          matlab.ui.control.Label
        PourcentageEntrainementEditField_1  matlab.ui.control.NumericEditField
        PourcentageEntrainementEditFieldLabel  matlab.ui.control.Label
        PourcentagetestEditField_1      matlab.ui.control.NumericEditField
        PourcentagetestEditField_3Label  matlab.ui.control.Label
        PourcentagevalidationEditField_1  matlab.ui.control.NumericEditField
        PourcentagevalidationEditField_3Label  matlab.ui.control.Label
        UIAxes_1                        matlab.ui.control.UIAxes
        CalculTab                       matlab.ui.container.Tab
        RNNInputHErFrOutputWpLpPanel    matlab.ui.container.Panel
        bd_label_1                      matlab.ui.control.Label
        neuron_label_1                  matlab.ui.control.Label
        BasedeDonneeButton_17           matlab.ui.control.Button
        ReseaudeneuronneButton_17       matlab.ui.control.Button
        CalculButton_7                  matlab.ui.control.Button
        AutresparamtresPanel_5          matlab.ui.container.Panel
        GridLayout3_20                  matlab.ui.container.GridLayout
        WgEditField_1                   matlab.ui.control.NumericEditField
        WgEditField_17Label             matlab.ui.control.Label
        LgEditField_1                   matlab.ui.control.NumericEditField
        LgEditField_17Label             matlab.ui.control.Label
        GpfEditField_1                  matlab.ui.control.NumericEditField
        GpfEditField_17Label            matlab.ui.control.Label
        WfEditField_1                   matlab.ui.control.NumericEditField
        WfEditField_17Label             matlab.ui.control.Label
        FiEditField_1                   matlab.ui.control.NumericEditField
        FiEditField_17Label             matlab.ui.control.Label
        mmLabel_148                     matlab.ui.control.Label
        mmLabel_147                     matlab.ui.control.Label
        mmLabel_146                     matlab.ui.control.Label
        mmLabel_145                     matlab.ui.control.Label
        mmLabel_17                      matlab.ui.control.Label
        SortiesRNN12sortiesPanel        matlab.ui.container.Panel
        GridLayout3_19                  matlab.ui.container.GridLayout
        ErrmoyEditField_1               matlab.ui.control.NumericEditField
        Err_moyEditFieldLabel_7         matlab.ui.control.Label
        LpEditField_1                   matlab.ui.control.NumericEditField
        LpEditField_16Label             matlab.ui.control.Label
        WpEditField_1                   matlab.ui.control.NumericEditField
        WpEditField_16Label             matlab.ui.control.Label
        Label_641                       matlab.ui.control.Label
        mmLabel_143                     matlab.ui.control.Label
        DataPanel_3                     matlab.ui.container.Panel
        GridLayout2_16                  matlab.ui.container.GridLayout
        Z0EditField_1                   matlab.ui.control.NumericEditField
        Z0EditField_10Label             matlab.ui.control.Label
        Label_48                        matlab.ui.control.Label
        tEditField_1                    matlab.ui.control.NumericEditField
        tEditField_5Label               matlab.ui.control.Label
        FmaxEditField_1                 matlab.ui.control.NumericEditField
        FmaxEditField_5Label            matlab.ui.control.Label
        FminEditField_1                 matlab.ui.control.NumericEditField
        FminEditField_5Label            matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        GHzLabel_7                      matlab.ui.control.Label
        GHzLabel_6                      matlab.ui.control.Label
        UITable_1                       matlab.ui.control.Table
        TransfrerversCSTButton_1        matlab.ui.control.Button
        ToutEffacerButton_1             matlab.ui.control.Button
        EffacerlaligneButton_1          matlab.ui.control.Button
        Image_1                         matlab.ui.control.Image
        DfautButton_1                   matlab.ui.control.Button
        EntresPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        FrEditField_1                   matlab.ui.control.NumericEditField
        FrEditField_10Label             matlab.ui.control.Label
        ErEditField_1                   matlab.ui.control.NumericEditField
        ErEditField_10Label             matlab.ui.control.Label
        HEditField_1                    matlab.ui.control.NumericEditField
        HEditField_10Label              matlab.ui.control.Label
        GHzLabel_15                     matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        UIAxesSij1                      matlab.ui.control.UIAxes
        E2S2Tab                         matlab.ui.container.Tab
        TabGroup2_5                     matlab.ui.container.TabGroup
        EntrainementTab_6               matlab.ui.container.Tab
        CommandesPanel_2                matlab.ui.container.Panel
        GridLayout12_4                  matlab.ui.container.GridLayout
        Label_21                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        ErButton_2                      matlab.ui.control.Button
        HButton_2                       matlab.ui.control.Button
        ValeurspardfautButton_2         matlab.ui.control.Button
        OptiLabel1_2                    matlab.ui.control.Label
        CouchesoptimalesLabel_2         matlab.ui.control.Label
        ControlPlotButtonGroup_2        matlab.ui.container.ButtonGroup
        PlotGeneralPerformanceButton_2  matlab.ui.control.ToggleButton
        PlotTrainingButton_2            matlab.ui.control.ToggleButton
        PlotPerformanceButton_2         matlab.ui.control.ToggleButton
        ErreurLabel_2                   matlab.ui.control.Label
        ChemSauvLabel_2                 matlab.ui.control.Label
        FichierdesauvegardeLabel        matlab.ui.control.Label
        BDdentranementLabel_2           matlab.ui.control.Label
        nomBdLabel_2                    matlab.ui.control.Label
        ErreurmoyenneEditField_2        matlab.ui.control.EditField
        ErreurmoyenneEditField_2Label   matlab.ui.control.Label
        OptionsPanel_2                  matlab.ui.container.Panel
        GridLayout11_2                  matlab.ui.container.GridLayout
        RechercheCouchenbNeuronnesoptimauxButton_2  matlab.ui.control.Button
        EntranerlerseauButton_2         matlab.ui.control.Button
        EnregistrerlerseauButton_2      matlab.ui.control.Button
        SlectionnerlabasededonneButton_2  matlab.ui.control.Button
        ParamtresPanel_2                matlab.ui.container.Panel
        GridLayout10_2                  matlab.ui.container.GridLayout
        CouchesFormatLabel_2            matlab.ui.control.Label
        CouchesneuronnesEditField_2     matlab.ui.control.EditField
        CouchesneuronnesEditField_6Label  matlab.ui.control.Label
        EpochsEditField_2               matlab.ui.control.NumericEditField
        EpochsEditField_2Label          matlab.ui.control.Label
        PourcentageEntrainementEditField_2  matlab.ui.control.NumericEditField
        PourcentageEntrainementEditField_2Label  matlab.ui.control.Label
        PourcentagetestEditField_2      matlab.ui.control.NumericEditField
        PourcentagetestEditField_2Label  matlab.ui.control.Label
        PourcentagevalidationEditField_2  matlab.ui.control.NumericEditField
        PourcentagevalidationEditField_2Label  matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        CalculTab_6                     matlab.ui.container.Tab
        AutresparamtresPanel            matlab.ui.container.Panel
        GridLayout3_14                  matlab.ui.container.GridLayout
        WgEditField_2                   matlab.ui.control.NumericEditField
        WgEditField_10Label_2           matlab.ui.control.Label
        LgEditField_2                   matlab.ui.control.NumericEditField
        LgEditField_10Label_2           matlab.ui.control.Label
        GpfEditField_2                  matlab.ui.control.NumericEditField
        GpfEditField_10Label_2          matlab.ui.control.Label
        WfEditField_2                   matlab.ui.control.NumericEditField
        WfEditField_10Label_2           matlab.ui.control.Label
        FiEditField_2                   matlab.ui.control.NumericEditField
        FiEditField_10Label_2           matlab.ui.control.Label
        mmLabel_119                     matlab.ui.control.Label
        mmLabel_118                     matlab.ui.control.Label
        mmLabel_117                     matlab.ui.control.Label
        mmLabel_116                     matlab.ui.control.Label
        mmLabel_115                     matlab.ui.control.Label
        DataPanel_2                     matlab.ui.container.Panel
        GridLayout2_15                  matlab.ui.container.GridLayout
        Z0EditField_2                   matlab.ui.control.NumericEditField
        Z0EditField_10Label_2           matlab.ui.control.Label
        Label_47                        matlab.ui.control.Label
        tEditField_2                    matlab.ui.control.NumericEditField
        tEditField_4Label               matlab.ui.control.Label
        FmaxEditField_2                 matlab.ui.control.NumericEditField
        FmaxEditField_4Label            matlab.ui.control.Label
        FminEditField_2                 matlab.ui.control.NumericEditField
        FminEditField_4Label            matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        GHzLabel_5                      matlab.ui.control.Label
        GHzLabel_4                      matlab.ui.control.Label
        RNNInputFrWpLpOutputHErPanel    matlab.ui.container.Panel
        bd_label_2                      matlab.ui.control.Label
        neuron_label_2                  matlab.ui.control.Label
        BasedeDonneeButton_2            matlab.ui.control.Button
        ReseaudeneuronneButton_2        matlab.ui.control.Button
        UITable_2                       matlab.ui.control.Table
        CalculButton_2                  matlab.ui.control.Button
        TransfrerversCSTButton_2        matlab.ui.control.Button
        ToutEffacerButton_2             matlab.ui.control.Button
        EffacerlaligneButton_2          matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        DfautButton_2                   matlab.ui.control.Button
        SortiesPanel_10                 matlab.ui.container.Panel
        GridLayout3_10                  matlab.ui.container.GridLayout
        ErrmoyEditField_2               matlab.ui.control.NumericEditField
        Err_moyEditFieldLabel_2         matlab.ui.control.Label
        ErEditField_2                   matlab.ui.control.NumericEditField
        ErEditField_10Label_2           matlab.ui.control.Label
        HEditField_2                    matlab.ui.control.NumericEditField
        HEditField_10Label_2            matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        mmLabel_78                      matlab.ui.control.Label
        EntresPanel_10                  matlab.ui.container.Panel
        GridLayout2_10                  matlab.ui.container.GridLayout
        FrEditField_2                   matlab.ui.control.NumericEditField
        FrEditField_10Label_2           matlab.ui.control.Label
        LpEditField_2                   matlab.ui.control.NumericEditField
        LpEditField_12Label             matlab.ui.control.Label
        WpEditField_2                   matlab.ui.control.NumericEditField
        WpEditField_12Label             matlab.ui.control.Label
        GHzLabel_25                     matlab.ui.control.Label
        mmLabel_85                      matlab.ui.control.Label
        mmLabel_77                      matlab.ui.control.Label
        UIAxesSij2                      matlab.ui.control.UIAxes
        E3S3Tab                         matlab.ui.container.Tab
        TabGroup2_6                     matlab.ui.container.TabGroup
        EntrainementTab_7               matlab.ui.container.Tab
        ValeurspardfautButton_3         matlab.ui.control.Button
        OptiLabel1_3                    matlab.ui.control.Label
        CouchesoptimalesLabel_3         matlab.ui.control.Label
        ControlPlotButtonGroup_3        matlab.ui.container.ButtonGroup
        PlotGeneralPerformanceButton_3  matlab.ui.control.ToggleButton
        PlotTrainingButton_3            matlab.ui.control.ToggleButton
        PlotPerformanceButton_3         matlab.ui.control.ToggleButton
        ErreurLabel_3                   matlab.ui.control.Label
        CommandesPanel_3                matlab.ui.container.Panel
        GridLayout12_3                  matlab.ui.container.GridLayout
        Label_30                        matlab.ui.control.Label
        HButton_3                       matlab.ui.control.Button
        ChemSauvLabel_3                 matlab.ui.control.Label
        FichierdesauvegardeLabel_3      matlab.ui.control.Label
        BDdentranementLabel_3           matlab.ui.control.Label
        nomBdLabel_3                    matlab.ui.control.Label
        ErreurmoyenneEditField_3        matlab.ui.control.EditField
        ErreurmoyenneEditField_4Label   matlab.ui.control.Label
        OptionsPanel_3                  matlab.ui.container.Panel
        GridLayout11_3                  matlab.ui.container.GridLayout
        RechercheCouchenbNeuronnesoptimauxButton_3  matlab.ui.control.Button
        EntranerlerseauButton_3         matlab.ui.control.Button
        EnregistrerlerseauButton_3      matlab.ui.control.Button
        SlectionnerlabasededonneButton_3  matlab.ui.control.Button
        ParamtresPanel_3                matlab.ui.container.Panel
        GridLayout10_3                  matlab.ui.container.GridLayout
        CouchesFormatLabel_3            matlab.ui.control.Label
        CouchesneuronnesEditField_3     matlab.ui.control.EditField
        CouchesneuronnesEditFieldLabel  matlab.ui.control.Label
        EpochsEditField_3               matlab.ui.control.NumericEditField
        EpochsEditField_3Label_2        matlab.ui.control.Label
        PourcentageEntrainementEditField_3  matlab.ui.control.NumericEditField
        PourcentageEntrainementEditField_3Label  matlab.ui.control.Label
        PourcentagetestEditField_3      matlab.ui.control.NumericEditField
        PourcentagetestEditField_3Label_2  matlab.ui.control.Label
        PourcentagevalidationEditField_3  matlab.ui.control.NumericEditField
        PourcentagevalidationEditField_3Label_2  matlab.ui.control.Label
        UIAxes_3                        matlab.ui.control.UIAxes
        CalculTab_3                     matlab.ui.container.Tab
        AutresparamtresPanel_2          matlab.ui.container.Panel
        GridLayout3_15                  matlab.ui.container.GridLayout
        WgEditField_3                   matlab.ui.control.NumericEditField
        WgEditField_13Label             matlab.ui.control.Label
        LgEditField_3                   matlab.ui.control.NumericEditField
        LgEditField_13Label             matlab.ui.control.Label
        GpfEditField_3                  matlab.ui.control.NumericEditField
        GpfEditField_13Label            matlab.ui.control.Label
        WfEditField_3                   matlab.ui.control.NumericEditField
        WfEditField_13Label             matlab.ui.control.Label
        FiEditField_3                   matlab.ui.control.NumericEditField
        FiEditField_13Label             matlab.ui.control.Label
        mmLabel_124                     matlab.ui.control.Label
        mmLabel_123                     matlab.ui.control.Label
        mmLabel_122                     matlab.ui.control.Label
        mmLabel_121                     matlab.ui.control.Label
        mmLabel_120                     matlab.ui.control.Label
        DataPanel                       matlab.ui.container.Panel
        GridLayout2_14                  matlab.ui.container.GridLayout
        Label_46                        matlab.ui.control.Label
        Z0EditField_3                   matlab.ui.control.NumericEditField
        Z0EditField_13Label             matlab.ui.control.Label
        tEditField_3                    matlab.ui.control.NumericEditField
        tEditFieldLabel                 matlab.ui.control.Label
        FmaxEditField_3                 matlab.ui.control.NumericEditField
        FmaxEditFieldLabel              matlab.ui.control.Label
        FminEditField_3                 matlab.ui.control.NumericEditField
        FminEditFieldLabel              matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        GHzLabel_2                      matlab.ui.control.Label
        GHzLabel_3                      matlab.ui.control.Label
        Image_6                         matlab.ui.control.Image
        RNNInputErFrWpLpOutputHPanel    matlab.ui.container.Panel
        bd_label_3                      matlab.ui.control.Label
        BasedeDonneeButton_3            matlab.ui.control.Button
        neuron_label_3                  matlab.ui.control.Label
        ReseaudeneuronneButton_3        matlab.ui.control.Button
        DfautButton_3                   matlab.ui.control.Button
        UITable_3                       matlab.ui.control.Table
        CalculButton_3                  matlab.ui.control.Button
        TransfrerversCSTButton_3        matlab.ui.control.Button
        ToutEffacerButton_3             matlab.ui.control.Button
        EffacerlaligneButton_3          matlab.ui.control.Button
        SortiesPanel_11                 matlab.ui.container.Panel
        GridLayout3_11                  matlab.ui.container.GridLayout
        ErrmoyEditField_3               matlab.ui.control.NumericEditField
        Err_moyEditFieldLabel_3         matlab.ui.control.Label
        HEditField_3                    matlab.ui.control.NumericEditField
        HEditField_13Label              matlab.ui.control.Label
        Label_29                        matlab.ui.control.Label
        mmLabel_88                      matlab.ui.control.Label
        EntresPanel_11                  matlab.ui.container.Panel
        GridLayout2_11                  matlab.ui.container.GridLayout
        mmLabel_94                      matlab.ui.control.Label
        LpEditField_3                   matlab.ui.control.NumericEditField
        LpEditField_13Label             matlab.ui.control.Label
        WpEditField_3                   matlab.ui.control.NumericEditField
        WpEditField_13Label             matlab.ui.control.Label
        FrEditField_3                   matlab.ui.control.NumericEditField
        FrEditField_14Label             matlab.ui.control.Label
        ErEditField_3                   matlab.ui.control.NumericEditField
        ErEditField_14Label             matlab.ui.control.Label
        mmLabel_95                      matlab.ui.control.Label
        GHzLabel                        matlab.ui.control.Label
        Label_32                        matlab.ui.control.Label
        UIAxesSij3                      matlab.ui.control.UIAxes
        E4S4Tab                         matlab.ui.container.Tab
        TabGroup2_7                     matlab.ui.container.TabGroup
        EntrainementTab_8               matlab.ui.container.Tab
        CommandesPanel_5                matlab.ui.container.Panel
        GridLayout12_5                  matlab.ui.container.GridLayout
        Label_44                        matlab.ui.control.Label
        LpButton_4                      matlab.ui.control.Button
        Label_42                        matlab.ui.control.Label
        Label_41                        matlab.ui.control.Label
        WpButton_4                      matlab.ui.control.Button
        HButton_4                       matlab.ui.control.Button
        ValeurspardfautButton_4         matlab.ui.control.Button
        OptiLabel1_4                    matlab.ui.control.Label
        CouchesoptimalesLabel_4         matlab.ui.control.Label
        ControlPlotButtonGroup_4        matlab.ui.container.ButtonGroup
        PlotGeneralPerformanceButton_4  matlab.ui.control.ToggleButton
        PlotTrainingButton_4            matlab.ui.control.ToggleButton
        PlotPerformanceButton_4         matlab.ui.control.ToggleButton
        ErreurLabel_4                   matlab.ui.control.Label
        ChemSauvLabel_4                 matlab.ui.control.Label
        FichierdesauvegardeLabel_4      matlab.ui.control.Label
        BDdentranementLabel_4           matlab.ui.control.Label
        nomBdLabel_4                    matlab.ui.control.Label
        ErreurmoyenneEditField_4        matlab.ui.control.EditField
        ErreurmoyenneEditField_4Label_2  matlab.ui.control.Label
        OptionsPanel_4                  matlab.ui.container.Panel
        GridLayout11_4                  matlab.ui.container.GridLayout
        RechercheCouchenbNeuronnesoptimauxButton_4  matlab.ui.control.Button
        EntranerlerseauButton_4         matlab.ui.control.Button
        EnregistrerlerseauButton_4      matlab.ui.control.Button
        SlectionnerlabasededonneButton_4  matlab.ui.control.Button
        ParamtresPanel_4                matlab.ui.container.Panel
        GridLayout10_4                  matlab.ui.container.GridLayout
        CouchesFormatLabel_4            matlab.ui.control.Label
        CouchesneuronnesEditField_4     matlab.ui.control.EditField
        CouchesneuronnesEditField_2Label  matlab.ui.control.Label
        EpochsEditField_4               matlab.ui.control.NumericEditField
        EpochsEditField_4Label          matlab.ui.control.Label
        PourcentageEntrainementEditField_4  matlab.ui.control.NumericEditField
        PourcentageEntrainementEditField_4Label  matlab.ui.control.Label
        PourcentagetestEditField_4      matlab.ui.control.NumericEditField
        PourcentagetestEditField_4Label  matlab.ui.control.Label
        PourcentagevalidationEditField_4  matlab.ui.control.NumericEditField
        PourcentagevalidationEditField_4Label  matlab.ui.control.Label
        UIAxes_4                        matlab.ui.control.UIAxes
        CalculTab_7                     matlab.ui.container.Tab
        AutresparamtresPanel_3          matlab.ui.container.Panel
        GridLayout3_16                  matlab.ui.container.GridLayout
        WgEditField_4                   matlab.ui.control.NumericEditField
        WgEditField_14Label             matlab.ui.control.Label
        LgEditField_4                   matlab.ui.control.NumericEditField
        LgEditField_14Label             matlab.ui.control.Label
        GpfEditField_4                  matlab.ui.control.NumericEditField
        GpfEditField_14Label            matlab.ui.control.Label
        WfEditField_4                   matlab.ui.control.NumericEditField
        WfEditField_14Label             matlab.ui.control.Label
        FiEditField_4                   matlab.ui.control.NumericEditField
        FiEditField_14Label             matlab.ui.control.Label
        mmLabel_129                     matlab.ui.control.Label
        mmLabel_128                     matlab.ui.control.Label
        mmLabel_127                     matlab.ui.control.Label
        mmLabel_126                     matlab.ui.control.Label
        mmLabel_125                     matlab.ui.control.Label
        DataPanel_4                     matlab.ui.container.Panel
        GridLayout2_17                  matlab.ui.container.GridLayout
        Z0EditField_14                  matlab.ui.control.NumericEditField
        Z0EditField_14Label             matlab.ui.control.Label
        Label_49                        matlab.ui.control.Label
        tEditField_4                    matlab.ui.control.NumericEditField
        tEditField_4Label_2             matlab.ui.control.Label
        FmaxEditField_4                 matlab.ui.control.NumericEditField
        FmaxEditField_4Label_2          matlab.ui.control.Label
        FminEditField_4                 matlab.ui.control.NumericEditField
        FminEditField_4Label_2          matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        GHzLabel_9                      matlab.ui.control.Label
        GHzLabel_8                      matlab.ui.control.Label
        RNNInputErFrOutputHWpLpPanel    matlab.ui.container.Panel
        bd_label_4                      matlab.ui.control.Label
        BasedeDonneeButton_4            matlab.ui.control.Button
        neuron_label_4                  matlab.ui.control.Label
        ReseaudeneuronneButton_4        matlab.ui.control.Button
        DfautButton_4                   matlab.ui.control.Button
        UITable_4                       matlab.ui.control.Table
        CalculButton_4                  matlab.ui.control.Button
        TransfrerversCSTButton_4        matlab.ui.control.Button
        ToutEffacerButton_4             matlab.ui.control.Button
        EffacerlaligneButton_4          matlab.ui.control.Button
        Image_7                         matlab.ui.control.Image
        SortiesPanel_12                 matlab.ui.container.Panel
        GridLayout3_12                  matlab.ui.container.GridLayout
        mmLabel_105                     matlab.ui.control.Label
        mmLabel_104                     matlab.ui.control.Label
        LpEditField_4                   matlab.ui.control.NumericEditField
        LpEditField_14Label             matlab.ui.control.Label
        WpEditField_4                   matlab.ui.control.NumericEditField
        WpEditField_14Label             matlab.ui.control.Label
        ErrmoyEditField_4               matlab.ui.control.NumericEditField
        Err_moyEditFieldLabel_4         matlab.ui.control.Label
        HEditField_4                    matlab.ui.control.NumericEditField
        HEditField_14Label              matlab.ui.control.Label
        mmLabel_98                      matlab.ui.control.Label
        EntresPanel_12                  matlab.ui.container.Panel
        GridLayout2_12                  matlab.ui.container.GridLayout
        FrEditField_4                   matlab.ui.control.NumericEditField
        FrEditField_15Label             matlab.ui.control.Label
        ErEditField_4                   matlab.ui.control.NumericEditField
        ErEditField_15Label             matlab.ui.control.Label
        GHzLabel_26                     matlab.ui.control.Label
        Label_34                        matlab.ui.control.Label
        UIAxesSij4                      matlab.ui.control.UIAxes
        E5S5Tab                         matlab.ui.container.Tab
        TabGroup2_8                     matlab.ui.container.TabGroup
        EntrainementTab_9               matlab.ui.container.Tab
        ValeurspardfautButton_5         matlab.ui.control.Button
        OptiLabel1_5                    matlab.ui.control.Label
        CouchesoptimalesLabel_5         matlab.ui.control.Label
        ControlPlotButtonGroup_5        matlab.ui.container.ButtonGroup
        PlotGeneralPerformanceButton_5  matlab.ui.control.ToggleButton
        PlotTrainingButton_5            matlab.ui.control.ToggleButton
        PlotPerformanceButton_5         matlab.ui.control.ToggleButton
        ErreurLabel_5                   matlab.ui.control.Label
        CommandesPanel_6                matlab.ui.container.Panel
        GridLayout12_6                  matlab.ui.container.GridLayout
        Label_51                        matlab.ui.control.Label
        ErButton_3                      matlab.ui.control.Button
        ChemSauvLabel_5                 matlab.ui.control.Label
        FichierdesauvegardeLabel_5      matlab.ui.control.Label
        BDdentranementLabel_5           matlab.ui.control.Label
        nomBdLabel_5                    matlab.ui.control.Label
        ErreurmoyenneEditField_5        matlab.ui.control.EditField
        ErreurmoyenneEditField_5Label   matlab.ui.control.Label
        OptionsPanel_5                  matlab.ui.container.Panel
        GridLayout11_5                  matlab.ui.container.GridLayout
        RechercheCouchenbNeuronnesoptimauxButton_5  matlab.ui.control.Button
        EntranerlerseauButton_5         matlab.ui.control.Button
        EnregistrerlerseauButton_5      matlab.ui.control.Button
        SlectionnerlabasededonneButton_5  matlab.ui.control.Button
        ParamtresPanel_5                matlab.ui.container.Panel
        GridLayout10_5                  matlab.ui.container.GridLayout
        CouchesFormatLabel_5            matlab.ui.control.Label
        CouchesneuronnesEditField_5     matlab.ui.control.EditField
        CouchesneuronnesEditField_5Label  matlab.ui.control.Label
        EpochsEditField_5               matlab.ui.control.NumericEditField
        EpochsEditField_5Label          matlab.ui.control.Label
        PourcentageEntrainementEditField_5  matlab.ui.control.NumericEditField
        PourcentageEntrainementEditField_5Label  matlab.ui.control.Label
        PourcentagetestEditField_5      matlab.ui.control.NumericEditField
        PourcentagetestEditField_5Label  matlab.ui.control.Label
        PourcentagevalidationEditField_5  matlab.ui.control.NumericEditField
        PourcentagevalidationEditField_5Label  matlab.ui.control.Label
        UIAxes_5                        matlab.ui.control.UIAxes
        CalculTab_8                     matlab.ui.container.Tab
        AutresparamtresPanel_6          matlab.ui.container.Panel
        GridLayout3_22                  matlab.ui.container.GridLayout
        WgEditField_5                   matlab.ui.control.NumericEditField
        WgEditField_18Label             matlab.ui.control.Label
        LgEditField_5                   matlab.ui.control.NumericEditField
        LgEditField_18Label             matlab.ui.control.Label
        GpfEditField_5                  matlab.ui.control.NumericEditField
        GpfEditField_18Label            matlab.ui.control.Label
        WfEditField_5                   matlab.ui.control.NumericEditField
        WfEditField_18Label             matlab.ui.control.Label
        FiEditField_5                   matlab.ui.control.NumericEditField
        FiEditField_18Label             matlab.ui.control.Label
        mmLabel_156                     matlab.ui.control.Label
        mmLabel_155                     matlab.ui.control.Label
        mmLabel_154                     matlab.ui.control.Label
        mmLabel_153                     matlab.ui.control.Label
        mmLabel_152                     matlab.ui.control.Label
        DataPanel_5                     matlab.ui.container.Panel
        GridLayout2_19                  matlab.ui.container.GridLayout
        Label_68                        matlab.ui.control.Label
        Z0EditField_15                  matlab.ui.control.NumericEditField
        Z0EditField_15Label             matlab.ui.control.Label
        tEditField_5                    matlab.ui.control.NumericEditField
        tEditField_5Label_2             matlab.ui.control.Label
        FmaxEditField_5                 matlab.ui.control.NumericEditField
        FmaxEditField_5Label_2          matlab.ui.control.Label
        FminEditField_5                 matlab.ui.control.NumericEditField
        FminEditField_5Label_2          matlab.ui.control.Label
        mLabel_5                        matlab.ui.control.Label
        GHzLabel_18                     matlab.ui.control.Label
        GHzLabel_17                     matlab.ui.control.Label
        Image_8                         matlab.ui.control.Image
        RNNInputErFrWpLpOutputHPanel_2  matlab.ui.container.Panel
        bd_label_5                      matlab.ui.control.Label
        BasedeDonneeButton_18           matlab.ui.control.Button
        neuron_label_5                  matlab.ui.control.Label
        ReseaudeneuronneButton_18       matlab.ui.control.Button
        DfautButton_5                   matlab.ui.control.Button
        UITable_5                       matlab.ui.control.Table
        CalculButton_8                  matlab.ui.control.Button
        TransfrerversCSTButton_5        matlab.ui.control.Button
        ToutEffacerButton_5             matlab.ui.control.Button
        EffacerlaligneButton_5          matlab.ui.control.Button
        SortiesPanel_13                 matlab.ui.container.Panel
        GridLayout3_21                  matlab.ui.container.GridLayout
        ErrmoyEditField_5               matlab.ui.control.NumericEditField
        Err_moyEditFieldLabel_8         matlab.ui.control.Label
        ErEditField_7                   matlab.ui.control.NumericEditField
        ErEditField_7Label              matlab.ui.control.Label
        Label_675                       matlab.ui.control.Label
        mmLabel_151                     matlab.ui.control.Label
        EntresPanel_13                  matlab.ui.container.Panel
        GridLayout2_18                  matlab.ui.container.GridLayout
        mmLabel_150                     matlab.ui.control.Label
        LpEditField_5                   matlab.ui.control.NumericEditField
        LpEditField_18Label             matlab.ui.control.Label
        WpEditField_5                   matlab.ui.control.NumericEditField
        WpEditField_18Label             matlab.ui.control.Label
        FrEditField_5                   matlab.ui.control.NumericEditField
        FrEditField_15Label_2           matlab.ui.control.Label
        HEditField_7                    matlab.ui.control.NumericEditField
        HEditField_7Label               matlab.ui.control.Label
        mmLabel_149                     matlab.ui.control.Label
        GHzLabel_16                     matlab.ui.control.Label
        Label_665                       matlab.ui.control.Label
        UIAxesSij5                      matlab.ui.control.UIAxes
        E6S6Tab                         matlab.ui.container.Tab
        TabGroup2_10                    matlab.ui.container.TabGroup
        EntrainementTab_10              matlab.ui.container.Tab
        ErreurmoyenneEditField_6        matlab.ui.control.EditField
        ErreurmoyenneEditField_6Label   matlab.ui.control.Label
        ValeurspardfautButton_6         matlab.ui.control.Button
        OptiLabel1_6                    matlab.ui.control.Label
        CouchesoptimalesLabel_6         matlab.ui.control.Label
        ControlPlotButtonGroup_6        matlab.ui.container.ButtonGroup
        PlotGeneralPerformanceButton_6  matlab.ui.control.ToggleButton
        PlotTrainingButton_6            matlab.ui.control.ToggleButton
        PlotPerformanceButton_6         matlab.ui.control.ToggleButton
        ErreurLabel_6                   matlab.ui.control.Label
        CommandesPanel_7                matlab.ui.container.Panel
        GridLayout12_7                  matlab.ui.container.GridLayout
        Label_67                        matlab.ui.control.Label
        Label_66                        matlab.ui.control.Label
        Label_65                        matlab.ui.control.Label
        Label_64                        matlab.ui.control.Label
        Label_63                        matlab.ui.control.Label
        WgButton_6                      matlab.ui.control.Button
        LgButton_6                      matlab.ui.control.Button
        GpfButton_6                     matlab.ui.control.Button
        WfButton_6                      matlab.ui.control.Button
        FiButton_6                      matlab.ui.control.Button
        Label_62                        matlab.ui.control.Label
        Label_61                        matlab.ui.control.Label
        LpButton_6                      matlab.ui.control.Button
        WpButton_6                      matlab.ui.control.Button
        ChemSauvLabel_6                 matlab.ui.control.Label
        FichierdesauvegardeLabel_6      matlab.ui.control.Label
        BDdentranementLabel_6           matlab.ui.control.Label
        nomBdLabel_6                    matlab.ui.control.Label
        OptionsPanel_6                  matlab.ui.container.Panel
        GridLayout11_6                  matlab.ui.container.GridLayout
        RechercheCouchenbNeuronnesoptimauxButton_6  matlab.ui.control.Button
        EntranerlerseauButton_6         matlab.ui.control.Button
        EnregistrerlerseauButton_6      matlab.ui.control.Button
        SlectionnerlabasededonneButton_6  matlab.ui.control.Button
        ParamtresPanel_6                matlab.ui.container.Panel
        GridLayout10_6                  matlab.ui.container.GridLayout
        CouchesFormatLabel_6            matlab.ui.control.Label
        CouchesneuronnesEditField_6     matlab.ui.control.EditField
        CouchesneuronnesEditField_6Label_2  matlab.ui.control.Label
        EpochsEditField_6               matlab.ui.control.NumericEditField
        EpochsEditField_6Label          matlab.ui.control.Label
        PourcentageEntrainementEditField_6  matlab.ui.control.NumericEditField
        PourcentageEntrainementEditField_6Label  matlab.ui.control.Label
        PourcentagetestEditField_6      matlab.ui.control.NumericEditField
        PourcentagetestEditField_6Label  matlab.ui.control.Label
        PourcentagevalidationEditField_6  matlab.ui.control.NumericEditField
        PourcentagevalidationEditField_6Label  matlab.ui.control.Label
        UIAxes_6                        matlab.ui.control.UIAxes
        CalculTab_10                    matlab.ui.container.Tab
        CalculButton_11                 matlab.ui.control.Button
        RNNInputHErFrOutputWpLpFiWfGpfLgWgPanel_2  matlab.ui.container.Panel
        bd_label_6                      matlab.ui.control.Label
        neuron_label_6                  matlab.ui.control.Label
        BasedeDonneeButton_6            matlab.ui.control.Button
        ReseaudeneuronneButton_6        matlab.ui.control.Button
        DataPanel_7                     matlab.ui.container.Panel
        GridLayout2_23                  matlab.ui.container.GridLayout
        Z0EditField_6                   matlab.ui.control.NumericEditField
        Z0EditField_17Label             matlab.ui.control.Label
        Label_81                        matlab.ui.control.Label
        tEditField_6                    matlab.ui.control.NumericEditField
        tEditField_7Label               matlab.ui.control.Label
        FmaxEditField_6                 matlab.ui.control.NumericEditField
        FmaxEditField_7Label            matlab.ui.control.Label
        FminEditField_6                 matlab.ui.control.NumericEditField
        FminEditField_7Label            matlab.ui.control.Label
        mLabel_7                        matlab.ui.control.Label
        GHzLabel_24                     matlab.ui.control.Label
        GHzLabel_23                     matlab.ui.control.Label
        UITable_6                       matlab.ui.control.Table
        TransfrerversCSTButton_6        matlab.ui.control.Button
        ToutEffacerButton_6             matlab.ui.control.Button
        EffacerlaligneButton_6          matlab.ui.control.Button
        Image_10                        matlab.ui.control.Image
        DfautButton_7                   matlab.ui.control.Button
        SortiesRNN17sortiesPanel_2      matlab.ui.container.Panel
        GridLayout3_24                  matlab.ui.container.GridLayout
        ErrmoyEditField_6               matlab.ui.control.NumericEditField
        Err_moyEditFieldLabel_9         matlab.ui.control.Label
        WgEditField_6                   matlab.ui.control.NumericEditField
        WgEditField_20Label             matlab.ui.control.Label
        LgEditField_6                   matlab.ui.control.NumericEditField
        LgEditField_20Label             matlab.ui.control.Label
        GpfEditField_6                  matlab.ui.control.NumericEditField
        GpfEditField_20Label            matlab.ui.control.Label
        WfEditField_6                   matlab.ui.control.NumericEditField
        WfEditField_20Label             matlab.ui.control.Label
        FiEditField_6                   matlab.ui.control.NumericEditField
        FiEditField_20Label             matlab.ui.control.Label
        LpEditField_6                   matlab.ui.control.NumericEditField
        LpEditField_20Label             matlab.ui.control.Label
        WpEditField_6                   matlab.ui.control.NumericEditField
        WpEditField_20Label             matlab.ui.control.Label
        mmLabel_172                     matlab.ui.control.Label
        mmLabel_171                     matlab.ui.control.Label
        mmLabel_170                     matlab.ui.control.Label
        mmLabel_169                     matlab.ui.control.Label
        mmLabel_168                     matlab.ui.control.Label
        mmLabel_167                     matlab.ui.control.Label
        mmLabel_166                     matlab.ui.control.Label
        EntresPanel_15                  matlab.ui.container.Panel
        GridLayout2_22                  matlab.ui.container.GridLayout
        FrEditField_6                   matlab.ui.control.NumericEditField
        FrEditField_17Label             matlab.ui.control.Label
        ErEditField_6                   matlab.ui.control.NumericEditField
        ErEditField_17Label             matlab.ui.control.Label
        HEditField_6                    matlab.ui.control.NumericEditField
        HEditField_17Label              matlab.ui.control.Label
        GHzLabel_22                     matlab.ui.control.Label
        Label_80                        matlab.ui.control.Label
        mmLabel_165                     matlab.ui.control.Label
        UIAxesSij6                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        Err_moy1 = 0;    %--> Erreur moyenne reseau 1
        Err_moy2 = 0;    %--> Erreur moyenne reseau 2
        Err_moy3 = 0;    %--> Erreur moyenne reseau 3
        Err_moy4 = 0;    %--> Erreur moyenne reseau 4
        Err_moy5 = 0;    %--> Erreur moyenne reseau 5
        
        %% E0->S0   theorique
        Ind_row0 = 0
        row0 = [];   %--> données à envoyer à CST
        
        %% E1->S1
        row = [];   %--> données à envoyer à CST
        
        Couches = 1;
        PTrain = 70;
        PVal = 30;
        PTest = 0;
        Epochs = 5;
        Path_neuron_training = 'database';
        Path_BD_training = 'database';
        File_BD_training = 'bd.mat';
        Plot_train_training1 = [];
        Plot_val_training1 = [];
        Plot_train_trainingTrue1 = [];
        Plot_val_trainingTrue1 = [];
        Plottr_Err1 = [];
        Plotval_Err1 = [];
        n1=[];
        trainingNe1;
        trainingTr1;
        Err1;
        
        Path_neuron = 'database';   %--> Path du reseau de neuronne
        File_neuron = 'RNN1.mat';   %--> Nom du reseau de neuronne
        Path_BD = 'database';       %--> Path BD
        File_BD = 'bd.mat';       %--> File BD
        Ind_row = 0;    %--> Indice de la ligne sélectionnée dans le tableau
        
        %% E2->S2
        row2 = [];   %--> données à envoyer à CST
        
        Couches2 = 1;
        PTrain2 = 70;
        PVal2 = 30;
        PTest2 = 0;
        Epochs2 = 5;
        Path_neuron_training2 = 'database';
        Path_BD_training2 = 'database';
        File_BD_training2 = 'bd.mat';
        Plot_train_training2 = [];
        Plot_val_training2 = [];
        Plot_train_trainingTrue2 = [];
        Plot_val_trainingTrue2 = [];
        trainingNe2 = [];
        trainingTr2 = [];
        Err2 = [];
        Path_neuron2 = 'database';   %--> Path du reseau de neuronne
        File_neuron2 = 'RNN2.mat';   %--> Nom du reseau de neuronne
        Path_BD2 = 'database';       %--> Path BD
        File_BD2 = 'bd.mat';       %--> File BD
        Ind_row2 = 0;    %--> Indice de la ligne sélectionnée dans le tableau
        Plottr_Err2 = [];
        Plotval_Err2 = [];
        n2=[];
        
        %% E3->S3
        row3 = [];   %--> données à envoyer à CST
        
        Couches3 = 1;
        PTrain3 = 70;
        PVal3 = 30;
        PTest3 = 0;
        Epochs3 = 5;
        Path_neuron_training3 = 'database';
        Path_BD_training3 = 'database';
        File_BD_training3 = 'bd.mat';
        Plot_train_training3 = [];
        Plot_val_training3 = [];
        Plot_train_trainingTrue3 = [];
        Plot_val_trainingTrue3 = [];
        trainingNe3 = [];
        trainingTr3 = [];
        Err3 = [];
        Path_neuron3 = 'database';   %--> Path du reseau de neuronne
        File_neuron3 = 'RNN3.mat';   %--> Nom du reseau de neuronne
        Path_BD3 = 'database';       %--> Path BD
        File_BD3 = 'bd.mat';       %--> File BD
        Ind_row3 = 0;    %--> Indice de la ligne sélectionnée dans le tableau
        Plottr_Err3 = [];
        Plotval_Err3 = [];
        n3=[];
        
        %% E4->S4
        row4 = [];   %--> données à envoyer à CST
        
        Couches4 = 1;
        PTrain4 = 70;
        PVal4 = 30;
        PTest4 = 0;
        Epochs4 = 5;
        Path_neuron_training4 = 'database';
        Path_BD_training4 = 'database';
        File_BD_training4 = 'bd_training.mat';
        Plot_train_training4 = [];
        Plot_val_training4 = [];
        Plot_train_trainingTrue4 = [];
        Plot_val_trainingTrue4 = [];
        trainingNe4;
        trainingTr4;
        Err4;
        Path_neuron4 = 'database';   %--> Path du reseau de neuronne
        File_neuron4 = 'RNN4.mat';   %--> Nom du reseau de neuronne
        Path_BD4 = 'database';       %--> Path BD
        File_BD4 = 'bd.mat';       %--> File BD
        Ind_row4 = 0;    %--> Indice de la ligne sélectionnée dans le tableau
        Plottr_Err4 = [];
        Plotval_Err4 = [];
        n4=[];
        
        %% E5->S5
        row5 = [];   %--> données à envoyer à CST
        
        Couches5 = 1;
        PTrain5 = 70;
        PVal5 = 30;
        PTest5 = 0;
        Epochs5 = 5;
        Path_neuron_training5 = 'database';
        Path_BD_training5 = 'database';
        File_BD_training5 = 'bd_training.mat';
        Plot_train_training5 = [];
        Plot_val_training5 = [];
        Plot_train_trainingTrue5 = [];
        Plot_val_trainingTrue5 = [];
        trainingNe5;
        trainingTr5;
        Err5;
        Path_neuron5 = 'database';   %--> Path du reseau de neuronne
        File_neuron5 = 'RNN5.mat';   %--> Nom du reseau de neuronne
        Path_BD5 = 'database';       %--> Path BD
        File_BD5 = 'bd.mat';       %--> File BD
        Ind_row5 = 0;    %--> Indice de la ligne sélectionnée dans le tableau
        Plottr_Err5 = [];
        Plotval_Err5 = [];
        n5=[];
        
        %% E6->S6
        row6 = [];   %--> données à envoyer à CST
        
        Couches6 = 1;
        PTrain6 = 70;
        PVal6 = 30;
        PTest6 = 0;
        Epochs6 = 5;
        Path_neuron_training6 = 'database';
        Path_BD_training6 = 'database';
        File_BD_training6 = 'bd_training.mat';
        Plot_train_training6 = [];
        Plot_val_training6 = [];
        Plot_train_trainingTrue6 = [];
        Plot_val_trainingTrue6 = [];
        trainingNe6;
        trainingTr6;
        Err6;
        Path_neuron6 = 'database';   %--> Path du reseau de neuronne
        File_neuron6 = 'RNN6.mat';   %--> Nom du reseau de neuronne
        Path_BD6 = 'database';       %--> Path BD
        File_BD6 = 'bd.mat';       %--> File BD
        Ind_row6 = 0;    %--> Indice de la ligne sélectionnée dans le tableau
        Plottr_Err6 = [];
        Plotval_Err6 = [];
        n6=[];
        
        Path_CST = 'CST-MATLAB-API-master';   %--> Path de l'API CST
        Directory_CST = 'CST-MATLAB-API-master';
        
        Dfault_path = 'database';
        Dfault_FileBd = 'bd.mat';
        Dfault_FileNeuron = ["RNN1_10_100.mat";"RNN2_10_100.mat";"RNN3_09_1000.mat";"RNN4_19_1000.mat";"RNN5_09_100.mat";"RNN6_10_60.mat"];
        Path_neurons = ["database";"database";"database";"database";"database";"database"];
        Path_bds = ["database";"database";"database";"database";"database";"database"];
        File_neurons = ["RNN1_10_100.mat";"RNN2_10_1000.mat";"RNN3_09_1000.mat";"RNN4_19_1000.mat";"RNN5_09_100.mat";"RNN6_10_60.mat"];
        File_bds = ["bd.mat";"bd.mat";"bd.mat";"bd.mat";"bd.mat";"bd.mat"];
        Z0 = 50;
        
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        function PlotSij(app, filenameTXT, numVolet)
            [Frequency, Sparametter] = CstLoadSparametterTXT(filenameTXT);
            %-- plot result -- Sij
            if numVolet==0
                plot(app.UIAxesSij0,Frequency,Sparametter,'-');
            elseif numVolet==1
                plot(app.UIAxesSij1,Frequency,Sparametter,'-');
            elseif numVolet==2
                plot(app.UIAxesSij2,Frequency,Sparametter,'-');
            elseif numVolet==3
                plot(app.UIAxesSij3,Frequency,Sparametter,'-');
            elseif numVolet==4
                plot(app.UIAxesSij4,Frequency,Sparametter,'-');
            elseif numVolet==5
                plot(app.UIAxesSij5,Frequency,Sparametter,'-');
            elseif numVolet==6
                plot(app.UIAxesSij6,Frequency,Sparametter,'-');
            end
        end
        
        
        function results = FormatCouche(app,sizeNb)
            a = '[';
            b = '';
            for i = 1:sizeNb+1
                b = [b,'%d,'];
            end
            c = ']';
            results = [a,b,c];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DfautButton_1, DfautButton_2, 
        % DfautButton_3, DfautButton_4, DfautButton_5, DfautButton_6, 
        % DfautButton_7
        function DfautButton_Pushed(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if selectedTab == app.E0S0Tab
                
                app.HEditField_0.Value = 1.6;
                app.ErEditField_0.Value = 4.3;
                app.FrEditField_0.Value = 2.45;
                app.WpEditField_0.Value = 0;
                app.LpEditField_0.Value = 0;
                app.FiEditField_0.Value = 0;
                app.WfEditField_0.Value = 0;
                app.GpfEditField_0.Value = 0;
                app.LgEditField_0.Value = 0;
                app.WgEditField_0.Value = 0;
                
            elseif selectedTab == app.E1S1Tab
                
                app.HEditField_1.Value = 1.6;
                app.ErEditField_1.Value = 4.3;
                app.FrEditField_1.Value = 2.45;
                app.WpEditField_1.Value = 0;
                app.LpEditField_1.Value = 0;
                app.FiEditField_1.Value = 0;
                app.WfEditField_1.Value = 0;
                app.GpfEditField_1.Value = 0;
                app.LgEditField_1.Value = 0;
                app.WgEditField_1.Value = 0;
                app.ErrmoyEditField_1.Value = 0;
                
            elseif selectedTab == app.E2S2Tab
                
                app.HEditField_2.Value = 0;
                app.ErEditField_2.Value = 0;
                app.FrEditField_2.Value = 2.45;
                app.WpEditField_2.Value = 37.61;
                app.LpEditField_2.Value = 29.15;
                app.FiEditField_2.Value = 0;
                app.WfEditField_2.Value = 0;
                app.GpfEditField_2.Value = 0;
                app.LgEditField_2.Value = 0;
                app.WgEditField_2.Value = 0;
                app.ErrmoyEditField_2.Value = 0;
                
            elseif selectedTab == app.E3S3Tab
                
                app.HEditField_3.Value = 0;
                app.ErEditField_3.Value = 4.3;
                app.FrEditField_3.Value = 2.45;
                app.WpEditField_3.Value = 37.61;
                app.LpEditField_3.Value = 29.15;
                app.FiEditField_3.Value = 0;
                app.WfEditField_3.Value = 0;
                app.GpfEditField_3.Value = 0;
                app.LgEditField_3.Value = 0;
                app.WgEditField_3.Value = 0;
                app.ErrmoyEditField_3.Value = 0;
                
            elseif selectedTab == app.E4S4Tab
                
                app.HEditField_4.Value = 0;
                app.ErEditField_4.Value = 4.3;
                app.FrEditField_4.Value = 2.45;
                app.WpEditField_4.Value = 0;
                app.LpEditField_4.Value = 0;
                app.FiEditField_4.Value = 0;
                app.WfEditField_4.Value = 0;
                app.GpfEditField_4.Value = 0;
                app.LgEditField_4.Value = 0;
                app.WgEditField_4.Value = 0;
                app.ErrmoyEditField_4.Value = 0;
                
            elseif selectedTab == app.E5S5Tab
                
                app.ErEditField_7.Value = 0;
                app.HEditField_7.Value = 1.6;
                app.FrEditField_5.Value = 2.45;
                app.WpEditField_5.Value = 37.61;
                app.LpEditField_5.Value = 29.15;
                app.FiEditField_5.Value = 0;
                app.WfEditField_5.Value = 0;
                app.GpfEditField_5.Value = 0;
                app.LgEditField_5.Value = 0;
                app.WgEditField_5.Value = 0;
                app.ErrmoyEditField_5.Value = 0;
                
            elseif selectedTab == app.E6S6Tab
                
                app.HEditField_6.Value = 1.6;
                app.ErEditField_6.Value = 4.3;
                app.FrEditField_6.Value = 2.45;
                app.WpEditField_6.Value = 0;
                app.LpEditField_6.Value = 0;
                app.FiEditField_6.Value = 0;
                app.WfEditField_6.Value = 0;
                app.GpfEditField_6.Value = 0;
                app.LgEditField_6.Value = 0;
                app.WgEditField_6.Value = 0;
                app.ErrmoyEditField_6.Value = 0;
                
            end
        end

        % Callback function
        function HEditFieldValueChanging(app, event)
            
        end

        % Callback function
        function ErEditFieldValueChanging(app, event)
            
        end

        % Callback function
        function FrEditFieldValueChanging(app, event)
            
        end

        % Cell selection callback: UITable_0, UITable_1, UITable_2, 
        % UITable_3, UITable_4, UITable_5, UITable_6
        function UITable_CellSelection(app, event)
            j = event.Indices(1);
            selectedTab = app.TabGroup.SelectedTab;
            
            if selectedTab == app.E0S0Tab
                i=1;    app.Ind_row0=j;
                n = app.UITable_0.Data{j,i};  i=i+1;
                H = app.UITable_0.Data{j,i};  i=i+1;
                Er = app.UITable_0.Data{j,i}; i=i+1;
                Fr = app.UITable_0.Data{j,i}; i=i+1;
                Z0 = app.UITable_0.Data{j,i}; i=i+1;
                Wp = app.UITable_0.Data{j,i}; i=i+1;
                Lp = app.UITable_0.Data{j,i}; i=i+1;
                Fi = app.UITable_0.Data{j,i}; i=i+1;
                Wf = app.UITable_0.Data{j,i}; i=i+1;
                Gpf = app.UITable_0.Data{j,i}; i=i+1;
                Lg = app.UITable_0.Data{j,i}; i=i+1;
                Wg = app.UITable_0.Data{j,i};
                app.row0 = [n, H, Er, Fr, Z0, Wp, Lp, Fi, Wf, Gpf, Lg, Wg];
                app.EffacerlaligneButton_0.Enable = true;
                app.TransfrerversCSTButton_0.Enable = true;
                
            elseif selectedTab == app.E1S1Tab
                i=1;    app.Ind_row=j;
                n = app.UITable_1.Data{j,i};  i=i+1;
                H = app.UITable_1.Data{j,i};  i=i+1;
                Er = app.UITable_1.Data{j,i}; i=i+1;
                Fr = app.UITable_1.Data{j,i}; i=i+1;
                Z0 = app.UITable_1.Data{j,i}; i=i+1;
                Wp = app.UITable_1.Data{j,i}; i=i+1;
                Lp = app.UITable_1.Data{j,i}; i=i+1;
                Fi = app.UITable_1.Data{j,i}; i=i+1;
                Wf = app.UITable_1.Data{j,i}; i=i+1;
                Gpf = app.UITable_1.Data{j,i}; i=i+1;
                Lg = app.UITable_1.Data{j,i}; i=i+1;
                Wg = app.UITable_1.Data{j,i};
                app.row = [n, H, Er, Fr, Z0, Wp, Lp, Fi, Wf, Gpf, Lg, Wg];
                app.EffacerlaligneButton_1.Enable = true;
                app.TransfrerversCSTButton_1.Enable = true;
            elseif selectedTab == app.E2S2Tab
                i=1;    app.Ind_row2=j;
                n = app.UITable_2.Data{j,i};  i=i+1;
                H = app.UITable_2.Data{j,i};  i=i+1;
                Er = app.UITable_2.Data{j,i}; i=i+1;
                Fr = app.UITable_2.Data{j,i}; i=i+1;
                Z0 = app.UITable_2.Data{j,i}; i=i+1;
                Wp = app.UITable_2.Data{j,i}; i=i+1;
                Lp = app.UITable_2.Data{j,i}; i=i+1;
                Fi = app.UITable_2.Data{j,i}; i=i+1;
                Wf = app.UITable_2.Data{j,i}; i=i+1;
                Gpf = app.UITable_2.Data{j,i}; i=i+1;
                Lg = app.UITable_2.Data{j,i}; i=i+1;
                Wg = app.UITable_2.Data{j,i};
                app.row2 = [n, H, Er, Fr, Z0, Wp, Lp, Fi, Wf, Gpf, Lg, Wg];
                app.EffacerlaligneButton_2.Enable = true;
                app.TransfrerversCSTButton_2.Enable = true;
            elseif selectedTab == app.E3S3Tab
                i=1;    app.Ind_row3=j;
                n = app.UITable_3.Data{j,i};  i=i+1;
                H = app.UITable_3.Data{j,i};  i=i+1;
                Er = app.UITable_3.Data{j,i}; i=i+1;
                Fr = app.UITable_3.Data{j,i}; i=i+1;
                Z0 = app.UITable_3.Data{j,i}; i=i+1;
                Wp = app.UITable_3.Data{j,i}; i=i+1;
                Lp = app.UITable_3.Data{j,i}; i=i+1;
                Fi = app.UITable_3.Data{j,i}; i=i+1;
                Wf = app.UITable_3.Data{j,i}; i=i+1;
                Gpf = app.UITable_3.Data{j,i}; i=i+1;
                Lg = app.UITable_3.Data{j,i}; i=i+1;
                Wg = app.UITable_3.Data{j,i};
                app.row3 = [n, H, Er, Fr, Z0, Wp, Lp, Fi, Wf, Gpf, Lg, Wg];
                app.EffacerlaligneButton_3.Enable = true;
                app.TransfrerversCSTButton_3.Enable = true;
            elseif selectedTab == app.E4S4Tab
                i=1;    app.Ind_row4=j;
                n = app.UITable_4.Data{j,i};  i=i+1;
                H = app.UITable_4.Data{j,i};  i=i+1;
                Er = app.UITable_4.Data{j,i}; i=i+1;
                Fr = app.UITable_4.Data{j,i}; i=i+1;
                Z0 = app.UITable_4.Data{j,i}; i=i+1;
                Wp = app.UITable_4.Data{j,i}; i=i+1;
                Lp = app.UITable_4.Data{j,i}; i=i+1;
                Fi = app.UITable_4.Data{j,i}; i=i+1;
                Wf = app.UITable_4.Data{j,i}; i=i+1;
                Gpf = app.UITable_4.Data{j,i}; i=i+1;
                Lg = app.UITable_4.Data{j,i}; i=i+1;
                Wg = app.UITable_4.Data{j,i};
                app.row4 = [n, H, Er, Fr, Z0, Wp, Lp, Fi, Wf, Gpf, Lg, Wg];
                app.EffacerlaligneButton_4.Enable = true;
                app.TransfrerversCSTButton_4.Enable = true;  
            elseif selectedTab == app.E5S5Tab
                i=1;    app.Ind_row5=j;
                n = app.UITable_5.Data{j,i};  i=i+1;
                H = app.UITable_5.Data{j,i};  i=i+1;
                Er = app.UITable_5.Data{j,i}; i=i+1;
                Fr = app.UITable_5.Data{j,i}; i=i+1;
                Z0 = app.UITable_5.Data{j,i}; i=i+1;
                Wp = app.UITable_5.Data{j,i}; i=i+1;
                Lp = app.UITable_5.Data{j,i}; i=i+1;
                Fi = app.UITable_5.Data{j,i}; i=i+1;
                Wf = app.UITable_5.Data{j,i}; i=i+1;
                Gpf = app.UITable_5.Data{j,i}; i=i+1;
                Lg = app.UITable_5.Data{j,i}; i=i+1;
                Wg = app.UITable_5.Data{j,i};
                app.row5 = [n, H, Er, Fr, Z0, Wp, Lp, Fi, Wf, Gpf, Lg, Wg];
                app.EffacerlaligneButton_5.Enable = true;
                app.TransfrerversCSTButton_5.Enable = true;
            elseif selectedTab == app.E6S6Tab
                i=1;    app.Ind_row6=j;
                n = app.UITable_6.Data{j,i};  i=i+1;
                H = app.UITable_6.Data{j,i};  i=i+1;
                Er = app.UITable_6.Data{j,i}; i=i+1;
                Fr = app.UITable_6.Data{j,i}; i=i+1;
                Z0 = app.UITable_6.Data{j,i}; i=i+1;
                Wp = app.UITable_6.Data{j,i}; i=i+1;
                Lp = app.UITable_6.Data{j,i}; i=i+1;
                Fi = app.UITable_6.Data{j,i}; i=i+1;
                Wf = app.UITable_6.Data{j,i}; i=i+1;
                Gpf = app.UITable_6.Data{j,i}; i=i+1;
                Lg = app.UITable_6.Data{j,i}; i=i+1;
                Wg = app.UITable_6.Data{j,i};
                app.row6 = [n, H, Er, Fr, Z0, Wp, Lp, Fi, Wf, Gpf, Lg, Wg];
                app.EffacerlaligneButton_6.Enable = true;
                app.TransfrerversCSTButton_6.Enable = true;
            end
        end

        % Button pushed function: EffacerlaligneButton_0, 
        % EffacerlaligneButton_1, EffacerlaligneButton_2, 
        % EffacerlaligneButton_3, EffacerlaligneButton_4, 
        % EffacerlaligneButton_5, EffacerlaligneButton_6
        function EffacerlaligneButton_Pushed(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.E0S0Tab
                if size(app.UITable_0.Data,1)>1 && app.Ind_row0>0
                    app.UITable_0.Data(app.Ind_row0,:) = [];
                    if size(app.UITable_0.Data,1)>=1
                        k=1;
                        for i=size(app.UITable_0.Data,1):-1:1
                            app.UITable_0.Data(k,1) = {i};
                            k=k+1;
                        end
                    end
                else
                    app.UITable_0.Data = [];
                end
            elseif selectedTab == app.E1S1Tab
                if size(app.UITable_1.Data,1)>1 && app.Ind_row>0
                    app.UITable_1.Data(app.Ind_row,:) = [];
                    if size(app.UITable_1.Data,1)>=1
                        k=1;
                        for i=size(app.UITable_1.Data,1):-1:1
                            app.UITable_1.Data(k,1) = {i};
                            k=k+1;
                        end
                    end
                else
                    app.UITable_1.Data = [];
                end
            elseif selectedTab == app.E2S2Tab
                if size(app.UITable_2.Data,1)>1 && app.Ind_row2>0
                    app.UITable_2.Data(app.Ind_row2,:) = [];
                    if size(app.UITable_2.Data,1)>=1
                        k=1;
                        for i=size(app.UITable_2.Data,1):-1:1
                            app.UITable_2.Data(k,1) = {i};
                            k=k+1;
                        end
                    end
                else
                    app.UITable_2.Data = [];
                end
            elseif selectedTab == app.E3S3Tab
                if size(app.UITable_3.Data,1)>1 && app.Ind_row3>0
                    app.UITable_3.Data(app.Ind_row3,:) = [];
                    if size(app.UITable_3.Data,1)>=1
                        k=1;
                        for i=size(app.UITable_3.Data,1):-1:1
                            app.UITable_3.Data(k,1) = {i};
                            k=k+1;
                        end
                    end
                else
                    app.UITable_3.Data = [];
                end
            elseif selectedTab == app.E4S4Tab
                if size(app.UITable_4.Data,1)>1 && app.Ind_row4>0
                    app.UITable_4.Data(app.Ind_row4,:) = [];
                    if size(app.UITable_4.Data,1)>=1
                        k=1;
                        for i=size(app.UITable_4.Data,1):-1:1
                            app.UITable_4.Data(k,1) = {i};
                            k=k+1;
                        end
                    end
                else
                    app.UITable_4.Data = [];
                end
            elseif selectedTab == app.E5S5Tab
                if size(app.UITable_5.Data,1)>1 && app.Ind_row5>0
                    app.UITable_5.Data(app.Ind_row5,:) = [];
                    if size(app.UITable_5.Data,1)>=1
                        k=1;
                        for i=size(app.UITable_5.Data,1):-1:1
                            app.UITable_5.Data(k,1) = {i};
                            k=k+1;
                        end
                    end
                else
                    app.UITable_5.Data = [];
                end
            elseif selectedTab == app.E6S6Tab
                if size(app.UITable_6.Data,1)>1 && app.Ind_row6>0
                    app.UITable_6.Data(app.Ind_row6,:) = [];
                    if size(app.UITable_6.Data,1)>=1
                        k=1;
                        for i=size(app.UITable_6.Data,1):-1:1
                            app.UITable_6.Data(k,1) = {i};
                            k=k+1;
                        end
                    end
                else
                    app.UITable_6.Data = [];
                end
            end
        end

        % Button pushed function: ToutEffacerButton_0, 
        % ToutEffacerButton_1, ToutEffacerButton_2, ToutEffacerButton_3, 
        % ToutEffacerButton_4, ToutEffacerButton_5, ToutEffacerButton_6
        function ToutEffacerButton_Pushed(app, event)
            selectedTab = app.TabGroup.SelectedTab;8
            if selectedTab == app.E0S0Tab
                app.UITable_0.Data = [];
            elseif selectedTab == app.E1S1Tab
                app.UITable_1.Data = [];
            elseif selectedTab == app.E2S2Tab
                app.UITable_2.Data = [];
            elseif selectedTab == app.E3S3Tab
                app.UITable_3.Data = [];
            elseif selectedTab == app.E4S4Tab
                app.UITable_4.Data = [];
            elseif selectedTab == app.E5S5Tab
                app.UITable_5.Data = [];
            elseif selectedTab == app.E6S6Tab
                app.UITable_6.Data = [];
            end
        end

        % Button pushed function: TransfrerversCSTButton_1
        function TransfrerversCSTButton_1Pushed(app, event)
            t = app.tEditField_1.Value; % en micrometre
            Fmin = app.FminEditField_1.Value; Fmax = app.FmaxEditField_1.Value;
            filenameTXT = 'resultats.txt';
            
            [nL,Nc] = size(app.row);
            if(nL==1 & ~strcmp(app.Path_CST,''))
                
                Hs = app.row(2);        Er = app.row(3);
                Wp = app.row(6);        Lp = app.row(7);
                Fi = app.row(8);        Wf = app.row(9);
                Gpf= app.row(10);       Lg = app.row(11);
                Wg = app.row(12);
                %fprintf("%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n", Hs, Er,Wp, Lp, Fi, Wf, Gpf, Lg, Wg, Fmin, Fmax, t, app.Path_CST, filenameTXT);
                toCST(Hs, Er,Wp, Lp, Fi, Wf, Gpf, Lg, Wg, Fmin, Fmax, t, app.Path_CST, filenameTXT);
                PlotSij(app, filenameTXT, 1);
                
            end
        end

        % Value changed function: CouchesneuronnesEditField_1, 
        % CouchesneuronnesEditField_2, CouchesneuronnesEditField_3, 
        % CouchesneuronnesEditField_4, CouchesneuronnesEditField_5, 
        % CouchesneuronnesEditField_6
        function CouchesneuronnesEditField_ValueChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            value1 = app.CouchesneuronnesEditField_1.Value;
            value2 = app.CouchesneuronnesEditField_2.Value;
            value3 = app.CouchesneuronnesEditField_3.Value;
            value4 = app.CouchesneuronnesEditField_4.Value;
            value5 = app.CouchesneuronnesEditField_5.Value;
            value6 = app.CouchesneuronnesEditField_6.Value;
            
            n1 = size(strfind(value1,','),2);
            n2 = size(strfind(value2,','),2);
            n3 = size(strfind(value3,','),2);
            n4 = size(strfind(value4,','),2);
            n5 = size(strfind(value5,','),2);
            n6 = size(strfind(value6,','),2);
            
            if selectedTab == app.E1S1Tab
                if n1 == 0
                    app.Couches = sscanf(value1,'[%d]');
                    app.CouchesFormatLabel_1.Text = ['[',num2str(app.Couches),']'];
                else
                    app.Couches = sscanf(value1,app.FormatCouche(n1));
                    app.CouchesFormatLabel_1.Text = mat2str(app.Couches);
                end
                app.CouchesFormatLabel_1.FontAngle = 'italic';
                
            elseif selectedTab == app.E2S2Tab
                
                if n2 == 0
                    app.Couches2 = sscanf(value2,'[%d]');
                    app.CouchesFormatLabel_2.Text = ['[',num2str(app.Couches2),']'];
                else
                    app.Couches2 = sscanf(value2,app.FormatCouche(n2));
                    app.CouchesFormatLabel_2.Text = mat2str(app.Couches2);
                end
                app.CouchesFormatLabel_2.FontAngle = 'italic';
                
            elseif selectedTab == app.E3S3Tab
                
                if n3 == 0
                    app.Couches3 = sscanf(value3,'[%d]');
                    app.CouchesFormatLabel_3.Text = ['[',num2str(app.Couches3),']'];
                else
                    app.Couches3 = sscanf(value3,app.FormatCouche(n3));
                    app.CouchesFormatLabel_3.Text = mat2str(app.Couches3);
                end
                app.CouchesFormatLabel_3.FontAngle = 'italic';
                
            elseif selectedTab == app.E4S4Tab
                
                if n4 == 0
                    app.Couches4 = sscanf(value4,'[%d]');
                    app.CouchesFormatLabel_4.Text = ['[',num2str(app.Couches4),']'];
                else
                    app.Couches4 = sscanf(value4,app.FormatCouche(n4));
                    app.CouchesFormatLabel_4.Text = mat2str(app.Couches4);
                end
                app.CouchesFormatLabel_4.FontAngle = 'italic';
                
            elseif selectedTab == app.E5S5Tab
                
                if n5 == 0
                    app.Couches5 = sscanf(value5,'[%d]');
                    app.CouchesFormatLabel_5.Text = ['[',num2str(app.Couches5),']'];
                else
                    app.Couches5 = sscanf(value5,app.FormatCouche(n5));
                    app.CouchesFormatLabel_5.Text = mat2str(app.Couches5);
                end
                app.CouchesFormatLabel_5.FontAngle = 'italic';
                
            elseif selectedTab == app.E6S6Tab
                
                if n6 == 0
                    app.Couches6 = sscanf(value6,'[%d]');
                    app.CouchesFormatLabel_6.Text = ['[',num2str(app.Couches6),']'];
                else
                    app.Couches6 = sscanf(value6,app.FormatCouche(n6));
                    app.CouchesFormatLabel_6.Text = mat2str(app.Couches6);
                end
                app.CouchesFormatLabel_6.FontAngle = 'italic';
            end
            
        end

        % Button pushed function: ValeurspardfautButton_1, 
        % ValeurspardfautButton_2, ValeurspardfautButton_3, 
        % ValeurspardfautButton_4, ValeurspardfautButton_5, 
        % ValeurspardfautButton_6
        function ValeurspardfautButton_Pushed(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if selectedTab == app.E1S1Tab
                app.CouchesneuronnesEditField_1.Value = '[13]';
                app.PourcentageEntrainementEditField_1.Value = 70;
                app.PourcentagevalidationEditField_1.Value = 25;
                app.PourcentagetestEditField_1.Value = 5;
                app.EpochsEditField_1.Value = 100;
                app.CouchesFormatLabel_1.Text = 'Format : [nbNeuronnes,nbNeuronnes,....,nbNeuronnes]';
                app.CouchesFormatLabel_1.FontAngle = 'normal';
                app.Path_neurons(1) = app.Dfault_path;
                app.Path_bds(1) = app.Dfault_path;
                app.File_bds(1) = app.Dfault_FileBd;
            elseif selectedTab == app.E2S2Tab
                app.CouchesneuronnesEditField_2.Value = '[13]';
                app.PourcentageEntrainementEditField_2.Value = 70;
                app.PourcentagevalidationEditField_2.Value = 25;
                app.PourcentagetestEditField_2.Value = 5;
                app.EpochsEditField_2.Value = 100;
                app.CouchesFormatLabel_2.Text = 'Format : [nbNeuronnes,nbNeuronnes,....,nbNeuronnes]';
                app.CouchesFormatLabel_2.FontAngle = 'normal';
                app.Path_neurons(2) = app.Dfault_path;
                app.Path_bds(2) = app.Dfault_path;
                app.File_bds(2) = app.Dfault_FileBd;
            elseif selectedTab == app.E3S3Tab
                app.CouchesneuronnesEditField_3.Value = '[13]';
                app.PourcentageEntrainementEditField_3.Value = 70;
                app.PourcentagevalidationEditField_3.Value = 25;
                app.PourcentagetestEditField_3.Value = 5;
                app.EpochsEditField_3.Value = 100;
                app.CouchesFormatLabel_3.Text = 'Format : [nbNeuronnes,nbNeuronnes,....,nbNeuronnes]';
                app.CouchesFormatLabel_3.FontAngle = 'normal';
                app.Path_neurons(3) = app.Dfault_path;
                app.Path_bds(3) = app.Dfault_path;
                app.File_bds(3) = app.Dfault_FileBd;
            elseif selectedTab == app.E4S4Tab
                app.CouchesneuronnesEditField_4.Value = '[13]';
                app.PourcentageEntrainementEditField_4.Value = 70;
                app.PourcentagevalidationEditField_4.Value = 25;
                app.PourcentagetestEditField_4.Value = 5;
                app.EpochsEditField_4.Value = 100;
                app.CouchesFormatLabel_4.Text = 'Format : [nbNeuronnes,nbNeuronnes,....,nbNeuronnes]';
                app.CouchesFormatLabel_4.FontAngle = 'normal';
                app.Path_neurons(4) = app.Dfault_path;
                app.Path_bds(4) = app.Dfault_path;
                app.File_bds(4) = app.Dfault_FileBd;
            elseif selectedTab == app.E5S5Tab
                app.CouchesneuronnesEditField_5.Value = '[13]';
                app.PourcentageEntrainementEditField_5.Value = 70;
                app.PourcentagevalidationEditField_5.Value = 25;
                app.PourcentagetestEditField_5.Value = 5;
                app.EpochsEditField_5.Value = 100;
                app.CouchesFormatLabel_5.Text = 'Format : [nbNeuronnes,nbNeuronnes,....,nbNeuronnes]';
                app.CouchesFormatLabel_5.FontAngle = 'normal';
                app.Path_neurons(5) = app.Dfault_path;
                app.Path_bds(5) = app.Dfault_path;
                app.File_bds(5) = app.Dfault_FileBd;
            elseif selectedTab == app.E6S6Tab
                app.CouchesneuronnesEditField_6.Value = '[13]';
                app.PourcentageEntrainementEditField_6.Value = 70;
                app.PourcentagevalidationEditField_6.Value = 25;
                app.PourcentagetestEditField_6.Value = 5;
                app.EpochsEditField_6.Value = 100;
                app.CouchesFormatLabel_6.Text = 'Format : [nbNeuronnes,nbNeuronnes,....,nbNeuronnes]';
                app.CouchesFormatLabel_6.FontAngle = 'normal';
                app.Path_neurons(6) = app.Dfault_path;
                app.Path_bds(6) = app.Dfault_path;
                app.File_bds(6) = app.Dfault_FileBd;
            end
            
            
        end

        % Button pushed function: SlectionnerlabasededonneButton_1, 
        % SlectionnerlabasededonneButton_2, 
        % SlectionnerlabasededonneButton_3, 
        % SlectionnerlabasededonneButton_4, 
        % SlectionnerlabasededonneButton_5, 
        % SlectionnerlabasededonneButton_6
        function SlectionnerlabasededonneButton_Pushed(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            [f_BD, p_BD] = uigetfile('*.mat');
            %drawnow;
            figure(app.UIFigure);
            
            if selectedTab == app.E1S1Tab
                if f_BD ~= 0
                    app.nomBdLabel_1.FontColor = '#1a7314';
                    app.File_bds(1) = f_BD;                                %--> Determinatiton de la bd par défaut
                    app.Path_bds(1) = p_BD;
                    app.nomBdLabel_1.Text = f_BD;                 %--> Changement du label nom du neuronne
                else
                    app.nomBdLabel_1.Text = app.Dfault_FileBd;
                    app.nomBdLabel_1.FontColor = 'black';
                end
            elseif selectedTab == app.E2S2Tab
                if f_BD ~= 0
                    app.nomBdLabel_2.FontColor = '#1a7314';
                    app.File_bds(2) = f_BD;                                %--> Determinatiton de la bd par défaut
                    app.Path_bds(2) = p_BD;
                    app.nomBdLabel_2.Text = f_BD;                           %--> Changement du label nom du neuronne
                else
                    app.nomBdLabel_2.Text = app.Dfault_FileBd;
                    app.nomBdLabel_2.FontColor = 'black';
                end
            elseif selectedTab == app.E3S3Tab
                if f_BD ~= 0
                    app.nomBdLabel_3.FontColor = '#1a7314';
                    app.File_bds(3) = f_BD;                                %--> Determinatiton de la bd par défaut
                    app.Path_bds(3) = p_BD;
                    app.nomBdLabel_3.Text = f_BD;                           %--> Changement du label nom du neuronne
                else
                    app.nomBdLabel_3.Text = app.Dfault_FileBd;
                    app.nomBdLabel_3.FontColor = 'black';
                end
            elseif selectedTab == app.E4S4Tab
                if f_BD ~= 0
                    app.nomBdLabel_4.FontColor = '#1a7314';
                    app.File_bds(4) = f_BD;                                %--> Determinatiton de la bd par défaut
                    app.Path_bds(4) = p_BD;
                    app.nomBdLabel_4.Text = f_BD;                           %--> Changement du label nom du neuronne
                else
                    app.nomBdLabel_4.Text = app.Dfault_FileBd;
                    app.nomBdLabel_4.FontColor = 'black';
                end
            elseif selectedTab == app.E5S5Tab
                if f_BD ~= 0
                    app.nomBdLabel_5.FontColor = '#1a7314';
                    app.File_bds(5) = f_BD;                                %--> Determinatiton de la bd par défaut
                    app.Path_bds(5) = p_BD;
                    app.nomBdLabel_5.Text = f_BD;                           %--> Changement du label nom du neuronne
                else
                    app.nomBdLabel_5.Text = app.Dfault_FileBd;
                    app.nomBdLabel_5.FontColor = 'black';
                end
            elseif selectedTab == app.E6S6Tab
                if f_BD ~= 0
                    app.nomBdLabel_6.FontColor = '#1a7314';
                    app.File_bds(6) = f_BD;                                %--> Determinatiton de la bd par défaut
                    app.Path_bds(6) = p_BD;
                    app.nomBdLabel_6.Text = f_BD;                           %--> Changement du label nom du neuronne
                else
                    app.nomBdLabel_6.Text = app.Dfault_FileBd;
                    app.nomBdLabel_6.FontColor = 'black';
                end
            end
            
            
        end

        % Button pushed function: EntranerlerseauButton_1, 
        % EntranerlerseauButton_2, EntranerlerseauButton_3, 
        % EntranerlerseauButton_4, EntranerlerseauButton_5, 
        % EntranerlerseauButton_6
        function EntranerlerseauButton_Pushed(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if selectedTab == app.E1S1Tab
                train = app.PourcentageEntrainementEditField_1.Value;
                epochs = app.EpochsEditField_1.Value;
                validation = app.PourcentagevalidationEditField_1.Value;
                test = app.PourcentagetestEditField_1.Value;
                bd = load(fullfile(app.Path_bds(1),app.File_bds(1)), '-mat');
                [net, tr, y2_Train, y2_TrainTrue, y2_Val, y2_ValTrue, rmsetrain, rmseval] = training1(bd, app.Couches', epochs, train, validation, test, true);
                app.Err1 = [rmsetrain'; rmseval'];
                app.trainingNe1 = net;
                app.trainingTr1 = tr;
                app.Plot_train_trainingTrue1 = y2_TrainTrue;
                app.Plot_train_training1 = y2_Train;
                app.Plot_val_trainingTrue1 = y2_ValTrue;
                app.Plot_val_training1 = y2_Val;
                app.EnregistrerlerseauButton_1.Enable = true;
                app.EntranerlerseauButton_1.Enable = true;
                app.ErreurmoyenneEditField_1.Value = num2str(mean([app.Err1(1,:),app.Err1(2,:)]));
            elseif selectedTab == app.E2S2Tab
                train = app.PourcentageEntrainementEditField_2.Value;
                epochs = app.EpochsEditField_2.Value;
                validation = app.PourcentagevalidationEditField_2.Value;
                test = app.PourcentagetestEditField_2.Value;
                bd = load(fullfile(app.Path_bds(2),app.File_bds(2)), '-mat');
                [net, tr, y2_Train, y2_TrainTrue, y2_Val, y2_ValTrue, rmsetrain, rmseval] = training2(bd, app.Couches2', epochs, train, validation, test, true);
                app.Err2 = [rmsetrain'; rmseval'];
                app.trainingNe2 = net;
                app.trainingTr2 = tr;
                app.Plot_train_trainingTrue2 = y2_TrainTrue;
                app.Plot_train_training2 = y2_Train;
                app.Plot_val_trainingTrue2 = y2_ValTrue;
                app.Plot_val_training2 = y2_Val;
                app.EnregistrerlerseauButton_2.Enable = true;
                app.EntranerlerseauButton_2.Enable = true;
                app.ErreurmoyenneEditField_2.Value = num2str(mean([app.Err2(1,:),app.Err2(2,:)]));
            elseif selectedTab == app.E3S3Tab
                train = app.PourcentageEntrainementEditField_3.Value;
                epochs = app.EpochsEditField_3.Value;
                validation = app.PourcentagevalidationEditField_3.Value;
                test = app.PourcentagetestEditField_3.Value;
                bd = load(fullfile(app.Path_bds(3),app.File_bds(3)), '-mat');
                [net, tr, y2_Train, y2_TrainTrue, y2_Val, y2_ValTrue, rmsetrain, rmseval] = training3(bd, app.Couches3', epochs, train, validation, test, true);
                app.Err3 = [rmsetrain'; rmseval'];
                app.trainingNe3 = net;
                app.trainingTr3 = tr;
                app.Plot_train_trainingTrue3 = y2_TrainTrue;
                app.Plot_train_training3 = y2_Train;
                app.Plot_val_trainingTrue3 = y2_ValTrue;
                app.Plot_val_training3 = y2_Val;
                app.EnregistrerlerseauButton_3.Enable = true;
                app.EntranerlerseauButton_3.Enable = true;
                app.ErreurmoyenneEditField_3.Value = num2str(mean([app.Err3(1,:),app.Err3(2,:)]));
            elseif selectedTab == app.E4S4Tab
                train = app.PourcentageEntrainementEditField_4.Value;
                epochs = app.EpochsEditField_4.Value;
                validation = app.PourcentagevalidationEditField_4.Value;
                test = app.PourcentagetestEditField_4.Value;
                bd = load(fullfile(app.Path_bds(4),app.File_bds(4)), '-mat');
                [net, tr, y2_Train, y2_TrainTrue, y2_Val, y2_ValTrue, rmsetrain, rmseval] = training4(bd, app.Couches4', epochs, train, validation, test, true);
                app.Err4 = [rmsetrain'; rmseval'];
                app.trainingNe4 = net;
                app.trainingTr4 = tr;
                app.Plot_train_trainingTrue4 = y2_TrainTrue;
                app.Plot_train_training4 = y2_Train;
                app.Plot_val_trainingTrue4 = y2_ValTrue;
                app.Plot_val_training4 = y2_Val;
                app.EnregistrerlerseauButton_4.Enable = true;
                app.EntranerlerseauButton_4.Enable = true;
                app.ErreurmoyenneEditField_4.Value = num2str(mean([app.Err4(1,:),app.Err4(2,:)]));
            elseif selectedTab == app.E5S5Tab
                train = app.PourcentageEntrainementEditField_5.Value;
                epochs = app.EpochsEditField_5.Value;
                validation = app.PourcentagevalidationEditField_5.Value;
                test = app.PourcentagetestEditField_5.Value;
                bd = load(fullfile(app.Path_bds(5),app.File_bds(5)), '-mat');
                [net, tr, y2_Train, y2_TrainTrue, y2_Val, y2_ValTrue, rmsetrain, rmseval] = training5(bd, app.Couches5', epochs, train, validation, test, true);
                app.Err5 = [rmsetrain'; rmseval'];
                app.trainingNe5 = net;
                app.trainingTr5 = tr;
                app.Plot_train_trainingTrue5 = y2_TrainTrue;
                app.Plot_train_training5 = y2_Train;
                app.Plot_val_trainingTrue5 = y2_ValTrue;
                app.Plot_val_training5 = y2_Val;
                app.EnregistrerlerseauButton_5.Enable = true;
                app.EntranerlerseauButton_5.Enable = true;
                app.ErreurmoyenneEditField_5.Value = num2str(mean([app.Err5(1,:),app.Err5(2,:)]));
            elseif selectedTab == app.E6S6Tab
                train = app.PourcentageEntrainementEditField_6.Value;
                epochs = app.EpochsEditField_6.Value;
                validation = app.PourcentagevalidationEditField_6.Value;
                test = app.PourcentagetestEditField_6.Value;
                bd = load(fullfile(app.Path_bds(6),app.File_bds(6)), '-mat');
                [net, tr, y2_Train, y2_TrainTrue, y2_Val, y2_ValTrue, rmsetrain, rmseval] = training6(bd, app.Couches6', epochs, train, validation, test, true);
                app.Err6 = [rmsetrain'; rmseval'];
                app.trainingNe6 = net;
                app.trainingTr6 = tr;
                app.Plot_train_trainingTrue6 = y2_TrainTrue;
                app.Plot_train_training6 = y2_Train;
                app.Plot_val_trainingTrue6 = y2_ValTrue;
                app.Plot_val_training6 = y2_Val;
                app.EnregistrerlerseauButton_6.Enable = true;
                app.EntranerlerseauButton_6.Enable = true;
                app.ErreurmoyenneEditField_6.Value = num2str(mean([app.Err6(1,:),app.Err6(2,:)]));
            end
            
        end

        % Button pushed function: EnregistrerlerseauButton_1, 
        % EnregistrerlerseauButton_2, EnregistrerlerseauButton_3, 
        % EnregistrerlerseauButton_4, EnregistrerlerseauButton_5, 
        % EnregistrerlerseauButton_6
        function EnregistrerlerseauButton_Pushed(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            [f_Ne,p_Ne] = uiputfile(['net.','mat']);
            %drawnow;
            figure(app.UIFigure);
            if selectedTab == app.E1S1Tab
                net = app.trainingNe1;
                tr = app.trainingTr1;
                Err = mean([app.Err1(1,:),app.Err1(2,:)]);
                if f_Ne ~= 0
                    save([p_Ne,f_Ne],'net', 'tr', 'Err');
                    app.ChemSauvLabel_1.Text = f_Ne;
                else
                    app.ChemSauvLabel_1.Text = '';
                end
            elseif selectedTab == app.E2S2Tab
                net = app.trainingNe2;
                tr = app.trainingTr2;
                Err = mean([app.Err2(1,:),app.Err2(2,:)]);
                if f_Ne ~= 0
                    save([p_Ne,f_Ne],'net', 'tr', 'Err');
                    app.ChemSauvLabel_2.Text = f_Ne;
                else
                    app.ChemSauvLabel_2.Text = '';
                end
            elseif selectedTab == app.E3S3Tab
                net = app.trainingNe3;
                tr = app.trainingTr3;
                Err = mean([app.Err3(1,:),app.Err3(2,:)]);
                if f_Ne ~= 0
                    save([p_Ne,f_Ne],'net', 'tr', 'Err');
                    app.ChemSauvLabel_3.Text = f_Ne;
                else
                    app.ChemSauvLabel_3.Text = '';
                end
            elseif selectedTab == app.E4S4Tab
                net = app.trainingNe4;
                tr = app.trainingTr4;
                Err = mean([app.Err4(1,:),app.Err4(2,:)]);
                if f_Ne ~= 0
                    save([p_Ne,f_Ne],'net', 'tr', 'Err');
                    app.ChemSauvLabel_4.Text = f_Ne;
                else
                    app.ChemSauvLabel_4.Text = '';
                end
            elseif selectedTab == app.E5S5Tab
                net = app.trainingNe5;
                tr = app.trainingTr5;
                Err = mean([app.Err5(1,:),app.Err5(2,:)]);
                if f_Ne ~= 0
                    save([p_Ne,f_Ne],'net', 'tr', 'Err');
                    app.ChemSauvLabel_5.Text = f_Ne;
                else
                    app.ChemSauvLabel_5.Text = '';
                end
            elseif selectedTab == app.E6S6Tab
                net = app.trainingNe6;
                tr = app.trainingTr6;
                Err = mean([app.Err6(1,:),app.Err6(2,:)]);
                if f_Ne ~= 0
                    save([p_Ne,f_Ne],'net', 'tr', 'Err');
                    app.ChemSauvLabel_6.Text = f_Ne;
                else
                    app.ChemSauvLabel_6.Text = '';
                end
            end
            
            
            %--> save a mat file
            
        end

        % Button pushed function: WpButton_1, WpButton_4, WpButton_6
        function WpButton_Pushed(app, event)
            i=1;
            selectedButton1 = app.ControlPlotButtonGroup.SelectedObject;
            selectedButton4 = app.ControlPlotButtonGroup_4.SelectedObject;
            selectedButton6 = app.ControlPlotButtonGroup_6.SelectedObject;
            selectedTab = app.TabGroup.SelectedTab;
            
            
            if selectedTab == app.E1S1Tab
                if strcmp(selectedButton1.Text,'Plot Performance')
                    cla(app.UIAxes_1);
                    
                    y1 = app.Plottr_Err1;
                    y2 = app.Plotval_Err1;
                    x = 1:size(app.n1,1);
                    
                    xr = num2str(app.n1);
                    for j=1:size(xr,1)
                        l(j,:) = strrep(xr(j,:),'  ',',');
                    end
                    xr=l;
                    
                    plot(app.UIAxes_1,x,y1(i,:));   hold(app.UIAxes_1,'on');
                    plot(app.UIAxes_1,x,y2(i,:));   hold(app.UIAxes_1,'off');
                    app.UIAxes_1.XTick = x;
                    app.UIAxes_1.XTickLabel = xr;
                    app.UIAxes_1.Title.String = 'Performances détaillées Wp';
                    app.UIAxes_1.XTickLabelRotation = 25;
                    app.UIAxes_1.XLabel.String = 'Couches de neuronnes';
                    app.UIAxes_1.YLabel.String = 'Erreur quadratique';
                    [v, ind] = min(y1(i,:));
                    
                    app.Couches = app.n1(ind,:)';
                    c = num2str(app.Couches');
                    c = strrep(c,'  ',',');
                    app.OptiLabel1.Text = ['[',xr(ind,:),']'];
                    app.CouchesneuronnesEditField_1.Value = ['[',c,']'];
                    app.CouchesFormatLabel_1.Text = ['[',c,']'];
                    
                    app.CommandesPanel.Visible = true;
                    app.UIAxes_1.Visible = true;
                else
                    cla(app.UIAxes_1);
                    train = app.Plot_train_training1(i,:);
                    val = app.Plot_val_training1(i,:);
                    trainTrue = app.Plot_train_trainingTrue1(i,:);
                    valTrue = app.Plot_val_trainingTrue1(i,:);
                    
                    app.Label_11.Text = num2str(mean(app.Err1(:,i)));
                    app.Label_12.Text = num2str(mean(app.Err1(:,i+1)));
                    
                    app.ErreurLabel_1.Visible = true;
                    app.CommandesPanel.Visible = true;
                    app.UIAxes_1.Visible = true;
                    
                    app.UIAxes_1.Title.String = 'Entrainement Wp';
                    
                    plot(app.UIAxes_1,trainTrue,train);   hold(app.UIAxes_1,'on');
                    plot(app.UIAxes_1,valTrue,val);       hold(app.UIAxes_1,'off');
                    app.UIAxes_1.XTickMode = 'auto';
                    app.UIAxes_1.XTickLabelMode = 'auto';
                    app.UIAxes_1.XLabel.String = 'Valeurs calculées';
                    app.UIAxes_1.YLabel.String = 'Valeurs dans la base de donnée';
                    app.UIAxes_1.XTickLabelRotation = 0;
                end
            elseif selectedTab == app.E4S4Tab
                i=2;
                if strcmp(selectedButton4.Text,'Plot Performance')
                    cla(app.UIAxes_4);
                    
                    y1 = app.Plottr_Err4;
                    y2 = app.Plotval_Err4;
                    x = 1:size(app.n4,1);
                    
                    xr = num2str(app.n4);
                    for j=1:size(xr,1)
                        l(j,:) = strrep(xr(j,:),'  ',',');
                    end
                    xr=l;
                    
                    plot(app.UIAxes_4,x,y1(i,:));   hold(app.UIAxes_4,'on');
                    plot(app.UIAxes_4,x,y2(i,:));   hold(app.UIAxes_4,'off');
                    app.UIAxes_4.XTick = x;
                    app.UIAxes_4.XTickLabel = xr;
                    app.UIAxes_4.Title.String = 'Performances détaillées Wp';
                    app.UIAxes_4.XTickLabelRotation = 25;
                    app.UIAxes_4.XLabel.String = 'Couches de neuronnes';
                    app.UIAxes_4.YLabel.String = 'Erreur quadratique';
                    [v, ind] = min(y1(i,:));
                    
                    app.Couches4 = app.n4(ind,:)';
                    c = num2str(app.Couches4');
                    c = strrep(c,'  ',',');
                    app.OptiLabel1_4.Text = ['[',xr(ind,:),']'];
                    app.CouchesneuronnesEditField_4.Value = ['[',c,']'];
                    app.CouchesFormatLabel_4.Text = ['[',c,']'];
                    
                    %app.ErreurLabel_1.Visible = true;
                    app.CommandesPanel_5.Visible = true;
                    app.UIAxes_4.Visible = true;
                else
                    cla(app.UIAxes_4);
                    train = app.Plot_train_training4(i,:);
                    val = app.Plot_val_training4(i,:);
                    trainTrue = app.Plot_train_trainingTrue4(i,:);
                    valTrue = app.Plot_val_trainingTrue4(i,:);
                    
                    app.ErreurLabel_4.Visible = true;
                    app.CommandesPanel_5.Visible = true;
                    app.UIAxes_4.Visible = true;
                    app.UIAxes_4.Title.String = 'Entrainement Wp';
                    
                    plot(app.UIAxes_4,trainTrue,train);   hold(app.UIAxes_4,'on');
                    plot(app.UIAxes_4,valTrue,val);       hold(app.UIAxes_4,'off');
                    app.UIAxes_4.XTickMode = 'auto';
                    app.UIAxes_4.XTickLabelMode = 'auto';
                    app.UIAxes_4.XLabel.String = 'Valeurs calculées';
                    app.UIAxes_4.YLabel.String = 'Valeurs dans la base de donnée';
                    app.UIAxes_4.XTickLabelRotation = 0;
                end
            elseif selectedTab == app.E6S6Tab
                i=1;
                if strcmp(selectedButton6.Text,'Plot Performance')
                    cla(app.UIAxes_6);
                    
                    y1 = app.Plottr_Err6;
                    y2 = app.Plotval_Err6;
                    x = 1:size(app.n6,1);
                    
                    xr = num2str(app.n6);
                    for j=1:size(xr,1)
                        l(j,:) = strrep(xr(j,:),'  ',',');
                    end
                    xr=l;
                    
                    plot(app.UIAxes_6,x,y1(i,:));   hold(app.UIAxes_6,'on');
                    plot(app.UIAxes_6,x,y2(i,:));   hold(app.UIAxes_6,'off');
                    app.UIAxes_6.XTick = x;
                    app.UIAxes_6.XTickLabel = xr;
                    app.UIAxes_6.Title.String = 'Performances détaillées Wp';
                    app.UIAxes_6.XTickLabelRotation = 25;
                    app.UIAxes_6.XLabel.String = 'Couches de neuronnes';
                    app.UIAxes_6.YLabel.String = 'Erreur quadratique';
                    [v, ind] = min(y1(i,:));
                    
                    app.Couches6 = app.n6(ind,:)';
                    c = num2str(app.Couches6');
                    c = strrep(c,'  ',',');
                    app.OptiLabel1_6.Text = ['[',xr(ind,:),']'];
                    app.CouchesneuronnesEditField_6.Value = ['[',c,']'];
                    app.CouchesFormatLabel_6.Text = ['[',c,']'];
                    
                    app.CommandesPanel_7.Visible = true;
                    app.UIAxes_6.Visible = true;
                else
                    cla(app.UIAxes_6);
                    train = app.Plot_train_training6(i,:);
                    val = app.Plot_val_training6(i,:);
                    trainTrue = app.Plot_train_trainingTrue6(i,:);
                    valTrue = app.Plot_val_trainingTrue6(i,:);
                    
                    app.Label_61.Text = num2str(mean(app.Err6(:,i)));
                    app.Label_62.Text = num2str(mean(app.Err6(:,i+1)));
                    app.Label_63.Text = num2str(mean(app.Err6(:,i+1)));
                    app.Label_64.Text = num2str(mean(app.Err6(:,i+1)));
                    app.Label_65.Text = num2str(mean(app.Err6(:,i+1)));
                    app.Label_66.Text = num2str(mean(app.Err6(:,i+1)));
                    app.Label_67.Text = num2str(mean(app.Err6(:,i+1)));
                    
                    app.ErreurLabel_6.Visible = true;
                    app.CommandesPanel_7.Visible = true;
                    app.UIAxes_6.Visible = true;
                    
                    app.UIAxes_6.Title.String = 'Entrainement Wp';
                    
                    plot(app.UIAxes_6,trainTrue,train);   hold(app.UIAxes_6,'on');
                    plot(app.UIAxes_6,valTrue,val);       hold(app.UIAxes_6,'off');
                    app.UIAxes_6.XTickMode = 'auto';
                    app.UIAxes_6.XTickLabelMode = 'auto';
                    app.UIAxes_6.XLabel.String = 'Valeurs calculées';
                    app.UIAxes_6.YLabel.String = 'Valeurs dans la base de donnée'
                    app.UIAxes_6.XTickLabelRotation = 0;
                end
            end
            
        end

        % Button pushed function: LpButton_1, LpButton_4, LpButton_6
        function LpButton_Pushed(app, event)
            i=2;
            selectedButton1 = app.ControlPlotButtonGroup.SelectedObject;
            selectedButton4 = app.ControlPlotButtonGroup_4.SelectedObject;
            selectedButton6 = app.ControlPlotButtonGroup_6.SelectedObject;
            selectedTab = app.TabGroup.SelectedTab;
            
            
            if selectedTab == app.E1S1Tab
                if strcmp(selectedButton1.Text,'Plot Performance')
                    cla(app.UIAxes_1);
                    
                    y1 = app.Plottr_Err1;
                    y2 = app.Plotval_Err1;
                    x = 1:size(app.n1,1);
                    
                    xr = num2str(app.n1);
                    for j=1:size(xr,1)
                        l(j,:) = strrep(xr(j,:),'  ',',');
                    end
                    xr=l;
                    
                    plot(app.UIAxes_1,x,y1(i,:));   hold(app.UIAxes_1,'on');
                    plot(app.UIAxes_1,x,y2(i,:));   hold(app.UIAxes_1,'off');
                    app.UIAxes_1.XTick = x;
                    app.UIAxes_1.XTickLabel = xr;
                    app.UIAxes_1.Title.String = 'Performances détaillées Lp';
                    app.UIAxes_1.XTickLabelRotation = 25;
                    app.UIAxes_1.XLabel.String = 'Couches de neuronnes';
                    app.UIAxes_1.YLabel.String = 'Erreur quadratique';
                    [v, ind] = min(y1(i,:));
                    
                    app.Couches = app.n1(ind,:)';
                    c = num2str(app.Couches');
                    c = strrep(c,'  ',',');
                    app.OptiLabel1.Text = ['[',xr(ind,:),']'];
                    app.CouchesneuronnesEditField_1.Value = ['[',c,']'];
                    app.CouchesFormatLabel_1.Text = ['[',c,']'];
                    
                    app.CommandesPanel.Visible = true;
                    app.UIAxes_1.Visible = true;
                else
                    cla(app.UIAxes_1);
                    train = app.Plot_train_training1(i,:);
                    val = app.Plot_val_training1(i,:);
                    trainTrue = app.Plot_train_trainingTrue1(i,:);
                    valTrue = app.Plot_val_trainingTrue1(i,:);
                    
                    app.ErreurLabel_1.Visible = true;
                    app.CommandesPanel.Visible = true;
                    app.UIAxes_1.Visible = true;
                    app.UIAxes_1.Title.String = 'Entrainement Lp';
                    
                    plot(app.UIAxes_1,trainTrue,train);   hold(app.UIAxes_1,'on');
                    plot(app.UIAxes_1,valTrue,val);       hold(app.UIAxes_1,'off');
                    app.UIAxes_1.XTickMode = 'auto';
                    app.UIAxes_1.XTickLabelMode = 'auto';
                    app.UIAxes_1.XLabel.String = 'Valeurs calculées';
                    app.UIAxes_1.YLabel.String = 'Valeurs dans la base de donnée';
                    app.UIAxes_1.XTickLabelRotation = 0;
                end
            elseif selectedTab == app.E4S4Tab
                i=3;
                if strcmp(selectedButton4.Text,'Plot Performance')
                    cla(app.UIAxes_4);
                    
                    y1 = app.Plottr_Err4;
                    y2 = app.Plotval_Err4;
                    x = 1:size(app.n4,1);
                    
                    xr = num2str(app.n4);
                    for j=1:size(xr,1)
                        l(j,:) = strrep(xr(j,:),'  ',',');
                    end
                    xr=l;
                    
                    plot(app.UIAxes_4,x,y1(i,:));   hold(app.UIAxes_4,'on');
                    plot(app.UIAxes_4,x,y2(i,:));   hold(app.UIAxes_4,'off');
                    app.UIAxes_4.XTick = x;
                    app.UIAxes_4.XTickLabel = xr;
                    app.UIAxes_4.Title.String = 'Performances détaillées Lp';
                    app.UIAxes_4.XTickLabelRotation = 25;
                    app.UIAxes_4.XLabel.String = 'Couches de neuronnes';
                    app.UIAxes_4.YLabel.String = 'Erreur quadratique';
                    [v, ind] = min(y1(i,:));
                    
                    app.Couches4 = app.n4(ind,:)';
                    c = num2str(app.Couches4');
                    c = strrep(c,'  ',',');
                    app.OptiLabel1_4.Text = ['[',xr(ind,:),']'];
                    app.CouchesneuronnesEditField_4.Value = ['[',c,']'];
                    app.CouchesFormatLabel_4.Text = ['[',c,']'];
                    
                    app.CommandesPanel_5.Visible = true;
                    app.UIAxes_4.Visible = true;
                else
                    cla(app.UIAxes_4);
                    train = app.Plot_train_training4(i,:);
                    val = app.Plot_val_training4(i,:);
                    trainTrue = app.Plot_train_trainingTrue4(i,:);
                    valTrue = app.Plot_val_trainingTrue4(i,:);
                    
                    app.ErreurLabel_4.Visible = true;
                    app.CommandesPanel_5.Visible = true;
                    app.UIAxes_4.Visible = true;
                    app.UIAxes_4.Title.String = 'Entrainement Lp';
                    
                    plot(app.UIAxes_4,trainTrue,train);   hold(app.UIAxes_4,'on');
                    plot(app.UIAxes_4,valTrue,val);       hold(app.UIAxes_4,'off');
                    app.UIAxes_4.XTickMode = 'auto';
                    app.UIAxes_4.XTickLabelMode = 'auto';
                    app.UIAxes_4.XLabel.String = 'Valeurs calculées';
                    app.UIAxes_4.YLabel.String = 'Valeurs dans la base de donnée';
                    app.UIAxes_4.XTickLabelRotation = 0;
                end
            elseif selectedTab == app.E6S6Tab
                i=2;
                if strcmp(selectedButton6.Text,'Plot Performance')
                    cla(app.UIAxes_6);
                    
                    y1 = app.Plottr_Err6;
                    y2 = app.Plotval_Err6;
                    x = 1:size(app.n6,1);
                    
                    xr = num2str(app.n6);
                    for j=1:size(xr,1)
                        l(j,:) = strrep(xr(j,:),'  ',',');
                    end
                    xr=l;
                    
                    plot(app.UIAxes_6,x,y1(i,:));   hold(app.UIAxes_6,'on');
                    plot(app.UIAxes_6,x,y2(i,:));   hold(app.UIAxes_6,'off');
                    app.UIAxes_6.XTick = x;
                    app.UIAxes_6.XTickLabel = xr;
                    app.UIAxes_6.Title.String = 'Performances détaillées Lp';
                    app.UIAxes_6.XTickLabelRotation = 25;
                    app.UIAxes_6.XLabel.String = 'Couches de neuronnes';
                    app.UIAxes_6.YLabel.String = 'Erreur quadratique';
                    [v, ind] = min(y1(i,:));
                    
                    app.Couches6 = app.n6(ind,:)';
                    c = num2str(app.Couches6');
                    c = strrep(c,'  ',',');
                    app.OptiLabel1_6.Text = ['[',xr(ind,:),']'];
                    app.CouchesneuronnesEditField_6.Value = ['[',c,']'];
                    app.CouchesFormatLabel_6.Text = ['[',c,']'];
                    
                    app.CommandesPanel_7.Visible = true;
                    app.UIAxes_6.Visible = true;
                else
                    cla(app.UIAxes_6);
                    train = app.Plot_train_training6(i,:);
                    val = app.Plot_val_training6(i,:);
                    trainTrue = app.Plot_train_trainingTrue6(i,:);
                    valTrue = app.Plot_val_trainingTrue6(i,:);
                    
                    app.ErreurLabel_6.Visible = true;
                    app.CommandesPanel_7.Visible = true;
                    app.UIAxes_6.Visible = true;
                    app.UIAxes_6.Title.String = 'Entrainement Lp';
                    
                    plot(app.UIAxes_6,trainTrue,train);   hold(app.UIAxes_6,'on');
                    plot(app.UIAxes_6,valTrue,val);       hold(app.UIAxes_6,'off');
                    app.UIAxes_6.XTickMode = 'auto';
                    app.UIAxes_6.XTickLabelMode = 'auto';
                    app.UIAxes_6.XLabel.String = 'Valeurs calculées';
                    app.UIAxes_6.YLabel.String = 'Valeurs dans la base de donnée';
                    app.UIAxes_6.XTickLabelRotation = 0;
                end
            end
            
        end

        % Button pushed function: FiButton_6
        function FiButton_1Pushed(app, event)
            i=3;
            selectedButton = app.ControlPlotButtonGroup_6.SelectedObject;
            if strcmp(selectedButton.Text,'Plot Performance')
                cla(app.UIAxes_6);
                
                y1 = app.Plottr_Err6;
                y2 = app.Plotval_Err6;
                x = 1:size(app.n6,1);
                
                xr = num2str(app.n6);
                for j=1:size(xr,1)
                    l(j,:) = strrep(xr(j,:),'  ',',');
                end
                xr=l;
                
                plot(app.UIAxes_6,x,y1(i,:));   hold(app.UIAxes_6,'on');
                plot(app.UIAxes_6,x,y2(i,:));   hold(app.UIAxes_6,'off');
                app.UIAxes_6.XTick = x;
                app.UIAxes_6.XTickLabel = xr;
                app.UIAxes_6.Title.String = 'Performances détaillées Fi';
                app.UIAxes_6.XTickLabelRotation = 25;
                app.UIAxes_6.XLabel.String = 'Couches de neuronnes';
                app.UIAxes_6.YLabel.String = 'Erreur quadratique';
                [v, ind] = min(y1(i,:));
                
                app.Couches6 = app.n6(ind,:)';
                %disp(app.Couches);
                c = num2str(app.Couches6');
                c = strrep(c,'  ',',');
                app.OptiLabel1_6.Text = ['[',xr(ind,:),']'];
                app.CouchesneuronnesEditField_6.Value = ['[',c,']'];
                app.CouchesFormatLabel_6.Text = ['[',c,']'];
                
                %app.ErreurLabel_1.Visible = true;
                app.CommandesPanel_7.Visible = true;
                app.UIAxes_6.Visible = true;
            else
                cla(app.UIAxes_6);
                train = app.Plot_train_training6(i,:);
                val = app.Plot_val_training6(i,:);
                trainTrue = app.Plot_train_trainingTrue6(i,:);
                valTrue = app.Plot_val_trainingTrue6(i,:);
                
                app.ErreurLabel_6.Visible = true;
                app.CommandesPanel_7.Visible = true;
                app.UIAxes_6.Visible = true;
                app.UIAxes_6.Title.String = 'Entrainement Fi';
                
                plot(app.UIAxes_6,trainTrue,train);   hold(app.UIAxes_6,'on');
                plot(app.UIAxes_6,valTrue,val);       hold(app.UIAxes_6,'off');
                app.UIAxes_6.XTickMode = 'auto';
                app.UIAxes_6.XTickLabelMode = 'auto';
                app.UIAxes_6.XLabel.String = 'Valeurs calculées';
                app.UIAxes_6.YLabel.String = 'Valeurs dans la base de donnée';
                app.UIAxes_6.XTickLabelRotation = 0;
            end
        end

        % Button pushed function: WfButton_6
        function WfButton_1Pushed(app, event)
            i=4;
            selectedButton = app.ControlPlotButtonGroup_6.SelectedObject;
            if strcmp(selectedButton.Text,'Plot Performance')
                cla(app.UIAxes_6);
                
                y1 = app.Plottr_Err6;
                y2 = app.Plotval_Err6;
                x = 1:size(app.n6,1);
                
                xr = num2str(app.n6);
                for j=1:size(xr,1)
                    l(j,:) = strrep(xr(j,:),'  ',',');
                end
                xr=l;
                
                plot(app.UIAxes_6,x,y1(i,:));   hold(app.UIAxes_6,'on');
                plot(app.UIAxes_6,x,y2(i,:));   hold(app.UIAxes_6,'off');
                app.UIAxes_6.XTick = x;
                app.UIAxes_6.XTickLabel = xr;
                app.UIAxes_6.Title.String = 'Performances détaillées Wf';
                app.UIAxes_6.XTickLabelRotation = 25;
                app.UIAxes_6.XLabel.String = 'Couches de neuronnes';
                app.UIAxes_6.YLabel.String = 'Erreur quadratique';
                [v, ind] = min(y1(i,:));
                
                app.Couches6 = app.n6(ind,:)';
                %disp(app.Couches);
                c = num2str(app.Couches6');
                c = strrep(c,'  ',',');
                app.OptiLabel1_6.Text = ['[',xr(ind,:),']'];
                app.CouchesneuronnesEditField_6.Value = ['[',c,']'];
                app.CouchesFormatLabel_6.Text = ['[',c,']'];
                
                %app.ErreurLabel_1.Visible = true;
                app.CommandesPanel_7.Visible = true;
                app.UIAxes_6.Visible = true;
            else
                cla(app.UIAxes_6);
                train = app.Plot_train_training6(i,:);
                val = app.Plot_val_training6(i,:);
                trainTrue = app.Plot_train_trainingTrue6(i,:);
                valTrue = app.Plot_val_trainingTrue6(i,:);
                
                app.ErreurLabel_6.Visible = true;
                app.CommandesPanel_7.Visible = true;
                app.UIAxes_6.Visible = true;
                app.UIAxes_6.Title.String = 'Entrainement Wf';
                
                plot(app.UIAxes_6,trainTrue,train);   hold(app.UIAxes_6,'on');
                plot(app.UIAxes_6,valTrue,val);       hold(app.UIAxes_6,'off');
                app.UIAxes_6.XTickMode = 'auto';
                app.UIAxes_6.XTickLabelMode = 'auto';
                app.UIAxes_6.XLabel.String = 'Valeurs calculées';
                app.UIAxes_6.YLabel.String = 'Valeurs dans la base de donnée';
                app.UIAxes_6.XTickLabelRotation = 0;
            end
        end

        % Button pushed function: GpfButton_6
        function GpfButton_1Pushed(app, event)
            i=5;
            selectedButton = app.ControlPlotButtonGroup_6.SelectedObject;
            if strcmp(selectedButton.Text,'Plot Performance')
                cla(app.UIAxes_6);
                
                y1 = app.Plottr_Err6;
                y2 = app.Plotval_Err6;
                x = 1:size(app.n6,1);
                
                xr = num2str(app.n6);
                xr = num2str(app.n6);
                for j=1:size(xr,1)
                    l(j,:) = strrep(xr(j,:),'  ',',');
                end
                xr=l;
                
                plot(app.UIAxes_6,x,y1(i,:));   hold(app.UIAxes_6,'on');
                plot(app.UIAxes_6,x,y2(i,:));   hold(app.UIAxes_6,'off');
                app.UIAxes_6.XTick = x;
                app.UIAxes_6.XTickLabel = xr;
                app.UIAxes_6.Title.String = 'Performances détaillées Gpf';
                app.UIAxes_6.XTickLabelRotation = 25;
                app.UIAxes_6.XLabel.String = 'Couches de neuronnes';
                app.UIAxes_6.YLabel.String = 'Erreur quadratique';
                [v, ind] = min(y1(i,:));
                
                app.Couches6 = app.n6(ind,:)';
                %disp(app.Couches);
                c = num2str(app.Couches6');
                c = strrep(c,'  ',',');
                app.OptiLabel1_6.Text = ['[',xr(ind,:),']'];
                app.CouchesneuronnesEditField_6.Value = ['[',c,']'];
                app.CouchesFormatLabel_6.Text = ['[',c,']'];
                
                %app.ErreurLabel_1.Visible = true;
                app.CommandesPanel_7.Visible = true;
                app.UIAxes_6.Visible = true;
            else
                train = app.Plot_train_training6(i,:);
                val = app.Plot_val_training6(i,:);
                trainTrue = app.Plot_train_trainingTrue6(i,:);
                valTrue = app.Plot_val_trainingTrue6(i,:);
                
                app.ErreurLabel_6.Visible = true;
                app.CommandesPanel.Visible = true;
                app.UIAxes_6.Visible = true;
                app.UIAxes_6.Title.String = 'Entrainement Gpf';
                
                plot(app.UIAxes_6,trainTrue,train);   hold(app.UIAxes_6,'on');
                plot(app.UIAxes_6,valTrue,val);       hold(app.UIAxes_6,'off');
                app.UIAxes_6.XTickMode = 'auto';
                app.UIAxes_6.XTickLabelMode = 'auto';
                app.UIAxes_6.XLabel.String = 'Valeurs calculées';
                app.UIAxes_6.YLabel.String = 'Valeurs dans la base de donnée';
                app.UIAxes_6.XTickLabelRotation = 0;
            end
        end

        % Button pushed function: LgButton_6
        function LgButton_1Pushed(app, event)
            i=6;
            selectedButton = app.ControlPlotButtonGroup_6.SelectedObject;
            if strcmp(selectedButton.Text,'Plot Performance')
                cla(app.UIAxes_6);
                
                y1 = app.Plottr_Err6;
                y2 = app.Plotval_Err6;
                x = 1:size(app.n6,1);
                
                xr = num2str(app.n6);
                for j=1:size(xr,1)
                    l(j,:) = strrep(xr(j,:),'  ',',');
                end
                xr=l;
                
                plot(app.UIAxes_6,x,y1(i,:));   hold(app.UIAxes_6,'on');
                plot(app.UIAxes_6,x,y2(i,:));   hold(app.UIAxes_6,'off');
                app.UIAxes_6.XTick = x;
                app.UIAxes_6.XTickLabel = xr;
                app.UIAxes_6.Title.String = 'Performances détaillées Lg';
                app.UIAxes_6.XTickLabelRotation = 25;
                app.UIAxes_6.XLabel.String = 'Couches de neuronnes';
                app.UIAxes_6.YLabel.String = 'Erreur quadratique';
                [v, ind] = min(y1(i,:));
                
                app.Couches6 = app.n6(ind,:)';
                %disp(app.Couches);
                c = num2str(app.Couches6');
                c = strrep(c,'  ',',');
                app.OptiLabel1_6.Text = ['[',xr(ind,:),']'];
                app.CouchesneuronnesEditField_6.Value = ['[',c,']'];
                app.CouchesFormatLabel_6.Text = ['[',c,']'];
                
                %app.ErreurLabel_1.Visible = true;
                app.CommandesPanel_7.Visible = true;
                app.UIAxes_6.Visible = true;
            else
                cla(app.UIAxes_6);
                train = app.Plot_train_training6(i,:);
                val = app.Plot_val_training6(i,:);
                trainTrue = app.Plot_train_trainingTrue6(i,:);
                valTrue = app.Plot_val_trainingTrue6(i,:);
                
                app.ErreurLabel_6.Visible = true;
                app.CommandesPanel_7.Visible = true;
                app.UIAxes_6.Visible = true;
                app.UIAxes_6.Title.String = 'Entrainement Lg';
                
                plot(app.UIAxes_6,trainTrue,train);   hold(app.UIAxes_6,'on');
                plot(app.UIAxes_6,valTrue,val);       hold(app.UIAxes_6,'off');
                app.UIAxes_6.XTickMode = 'auto';
                app.UIAxes_6.XTickLabelMode = 'auto';
                app.UIAxes_6.XLabel.String = 'Valeurs calculées';
                app.UIAxes_6.YLabel.String = 'Valeurs dans la base de donnée';
                app.UIAxes_6.XTickLabelRotation = 0;
            end
        end

        % Button pushed function: WgButton_6
        function WgButton_1Pushed(app, event)
            i=7;
            selectedButton = app.ControlPlotButtonGroup_6.SelectedObject;
            if strcmp(selectedButton.Text,'Plot Performance')
                cla(app.UIAxes_6);
                
                y1 = app.Plottr_Err6;
                y2 = app.Plotval_Err6;
                x = 1:size(app.n6,1);
                
                xr = num2str(app.n6);
                for j=1:size(xr,1)
                    l(j,:) = strrep(xr(j,:),'  ',',');
                end
                xr=l;
                
                plot(app.UIAxes_6,x,y1(i,:));   hold(app.UIAxes_6,'on');
                plot(app.UIAxes_6,x,y2(i,:));   hold(app.UIAxes_6,'off');
                app.UIAxes_6.XTick = x;
                app.UIAxes_6.XTickLabel = xr;
                app.UIAxes_6.Title.String = 'Performances détaillées Wg';
                app.UIAxes_6.XTickLabelRotation = 25;
                app.UIAxes_6.XLabel.String = 'Couches de neuronnes';
                app.UIAxes_6.YLabel.String = 'Erreur quadratique';
                [v, ind] = min(y1(i,:));
                
                app.Couches6 = app.n6(ind,:)';
                %disp(app.Couches);
                c = num2str(app.Couches6');
                c = strrep(c,'  ',',');
                app.OptiLabel1_6.Text = ['[',xr(ind,:),']'];
                app.CouchesneuronnesEditField_6.Value = ['[',c,']'];
                app.CouchesFormatLabel_6.Text = ['[',c,']'];
                
                %app.ErreurLabel_6.Visible = true;
                app.CommandesPanel_7.Visible = true;
                app.UIAxes_6.Visible = true;
            else
                cla(app.UIAxes_6);
                train = app.Plot_train_training6(i,:);
                val = app.Plot_val_training6(i,:);
                trainTrue = app.Plot_train_trainingTrue6(i,:);
                valTrue = app.Plot_val_trainingTrue6(i,:);
                
                app.ErreurLabel_6.Visible = true;
                app.CommandesPanel_7.Visible = true;
                app.UIAxes_6.Visible = true;
                app.UIAxes_6.Title.String = 'Entrainement Wg';
                
                plot(app.UIAxes_6,trainTrue,train);   hold(app.UIAxes_6,'on');
                plot(app.UIAxes_6,valTrue,val);       hold(app.UIAxes_6,'off');
                app.UIAxes_6.XTickMode = 'auto';
                app.UIAxes_6.XTickLabelMode = 'auto';
                app.UIAxes_6.XLabel.String = 'Valeurs calculées';
                app.UIAxes_6.YLabel.String = 'Valeurs dans la base de donnée';
                app.UIAxes_6.XTickLabelRotation = 0;
            end
        end

        % Value changed function: WpEditField_2
        function WpEditField_2ValueChanged(app, event)
            value = app.WpEditField_2.Value;
            app.Wp2 = value;
        end

        % Value changed function: LpEditField_2
        function LpEditField_2ValueChanged(app, event)
            value = app.LpEditField_2.Value;
            app.Lp2 = value;
        end

        % Value changed function: FrEditField_2
        function FrEditField_2ValueChanged(app, event)
            value = app.FrEditField_2.Value;
            app.Fr2 = value;
        end

        % Button pushed function: TransfrerversCSTButton_2
        function TransfrerversCSTButton_2Pushed(app, event)
            t = app.tEditField_2.Value; % en micrometre
            Fmin = app.FminEditField_2.Value; Fmax = app.FmaxEditField_2.Value;
            filenameTXT = 'resultats.txt';
            
            [nL,Nc] = size(app.row2);
            if(nL==1 & ~strcmp(app.Path_CST,''))
                %addpath('Functions');
                Hs = app.row2(2);        Er = app.row2(3);
                Wp = app.row2(6);        Lp = app.row2(7);
                Fi = app.row2(8);        Wf = app.row2(9);
                Gpf= app.row2(10);       Lg = app.row2(11);
                Wg = app.row2(12);
                %fprintf("%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n", Hs, Er,Wp, Lp, Fi, Wf, Gpf, Lg, Wg, Fmin, Fmax, t, app.Path_CST, filenameTXT);
                toCST(Hs, Er,Wp, Lp, Fi, Wf, Gpf, Lg, Wg, Fmin, Fmax, t, app.Path_CST, filenameTXT);
                PlotSij(app, filenameTXT, 2);
            end
        end

        % Button pushed function: 
        % RechercheCouchenbNeuronnesoptimauxButton_1, 
        % RechercheCouchenbNeuronnesoptimauxButton_2, 
        % RechercheCouchenbNeuronnesoptimauxButton_3, 
        % RechercheCouchenbNeuronnesoptimauxButton_4, 
        % RechercheCouchenbNeuronnesoptimauxButton_5, 
        % RechercheCouchenbNeuronnesoptimauxButton_6
        function RechercheCouchenbNeuronnesoptimauxButton_Pushed(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if selectedTab == app.E1S1Tab
                train = app.PourcentageEntrainementEditField_1.Value;
                epochs = app.EpochsEditField_1.Value;
                validation = app.PourcentagevalidationEditField_1.Value;
                test = app.PourcentagetestEditField_1.Value;
                bd = load(fullfile(app.Path_bds(1),app.File_bds(1)), '-mat');                 %--> Chargement de la BD
                [~, ~, ~, ~, ~, ~, rmsetrain, rmseval, n] = training1(bd, app.Couches', epochs, train, validation, test, false);     %--> Entrainement du réseau de neuronne
                app.n1 = n;
                app.Plottr_Err1 = rmsetrain;
                app.Plotval_Err1 = rmseval;
                app.EnregistrerlerseauButton_1.Enable = true;
                app.EntranerlerseauButton_1.Enable = true;
                app.ErreurmoyenneEditField_1.Value = '';
            elseif selectedTab == app.E2S2Tab
                train = app.PourcentageEntrainementEditField_2.Value;
                epochs = app.EpochsEditField_2.Value;
                validation = app.PourcentagevalidationEditField_2.Value;
                test = app.PourcentagetestEditField_2.Value;
                bd = load(fullfile(app.Path_bds(2),app.File_bds(2)), '-mat');                 %--> Chargement de la BD
                [net, tr, y2_Train, y2_TrainTrue, y2_Val, y2_ValTrue, rmsetrain, rmseval, n] = training2(bd, app.Couches2', epochs, train, validation, test, false);     %--> Entrainement du réseau de neuronne
                app.n2 = n;
                app.Plottr_Err2 = rmsetrain;
                app.Plotval_Err2 = rmseval;
                app.EnregistrerlerseauButton_2.Enable = true;
                app.EntranerlerseauButton_2.Enable = true;
                app.ErreurmoyenneEditField_2.Value = '';
            elseif selectedTab == app.E3S3Tab
                train = app.PourcentageEntrainementEditField_3.Value;
                epochs = app.EpochsEditField_3.Value;
                validation = app.PourcentagevalidationEditField_3.Value;
                test = app.PourcentagetestEditField_3.Value;
                bd = load(fullfile(app.Path_bds(3),app.File_bds(3)), '-mat');                 %--> Chargement de la BD
                [net, tr, y2_Train, y2_TrainTrue, y2_Val, y2_ValTrue, rmsetrain, rmseval, n] = training3(bd, app.Couches3', epochs, train, validation, test, false);     %--> Entrainement du réseau de neuronne
                app.n3 = n;
                app.Plottr_Err3 = rmsetrain;
                app.Plotval_Err3 = rmseval;
                app.EnregistrerlerseauButton_3.Enable = true;
                app.EntranerlerseauButton_3.Enable = true;
                app.ErreurmoyenneEditField_3.Value = '';
            elseif selectedTab == app.E4S4Tab
                train = app.PourcentageEntrainementEditField_4.Value;
                epochs = app.EpochsEditField_4.Value;
                validation = app.PourcentagevalidationEditField_4.Value;
                test = app.PourcentagetestEditField_4.Value;
                bd = load(fullfile(app.Path_bds(4),app.File_bds(4)), '-mat');                 %--> Chargement de la BD
                [net, tr, y2_Train, y2_TrainTrue, y2_Val, y2_ValTrue, rmsetrain, rmseval, n] = training4(bd, app.Couches4', epochs, train, validation, test, false);     %--> Entrainement du réseau de neuronne
                app.n4 = n;
                app.Plottr_Err4 = rmsetrain;
                app.Plotval_Err4 = rmseval;
                app.EnregistrerlerseauButton_4.Enable = true;
                app.EntranerlerseauButton_4.Enable = true;
                app.ErreurmoyenneEditField_4.Value = '';
            elseif selectedTab == app.E5S5Tab
                train = app.PourcentageEntrainementEditField_5.Value;
                epochs = app.EpochsEditField_5.Value;
                validation = app.PourcentagevalidationEditField_5.Value;
                test = app.PourcentagetestEditField_5.Value;
                bd = load(fullfile(app.Path_bds(5),app.File_bds(5)), '-mat');                 %--> Chargement de la BD
                [net, tr, y2_Train, y2_TrainTrue, y2_Val, y2_ValTrue, rmsetrain, rmseval, n] = training5(bd, app.Couches5', epochs, train, validation, test, false);     %--> Entrainement du réseau de neuronne
                app.n5 = n;
                app.Plottr_Err5 = rmsetrain;
                app.Plotval_Err5 = rmseval;
                app.EnregistrerlerseauButton_5.Enable = true;
                app.EntranerlerseauButton_5.Enable = true;
                app.ErreurmoyenneEditField_5.Value = '';
            elseif selectedTab == app.E6S6Tab
                train = app.PourcentageEntrainementEditField_6.Value;
                epochs = app.EpochsEditField_6.Value;
                validation = app.PourcentagevalidationEditField_6.Value;
                test = app.PourcentagetestEditField_6.Value;
                bd = load(fullfile(app.Path_bds(6),app.File_bds(6)), '-mat');                 %--> Chargement de la BD
                [net, tr, y2_Train, y2_TrainTrue, y2_Val, y2_ValTrue, rmsetrain, rmseval, n] = training6(bd, app.Couches6', epochs, train, validation, test, false);     %--> Entrainement du réseau de neuronne
                app.n6 = n;
                app.Plottr_Err6 = rmsetrain;
                app.Plotval_Err6 = rmseval;
                app.EnregistrerlerseauButton_6.Enable = true;
                app.EntranerlerseauButton_6.Enable = true;
                app.ErreurmoyenneEditField_6.Value = '';
            end
        end

        % Selection changed function: ControlPlotButtonGroup
        function ControlPlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.ControlPlotButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Plot Performance')
                cla(app.UIAxes_1);
                
                app.UIAxes_1.Visible = true;
                app.ErreurLabel_1.Visible = false;
                app.CommandesPanel.Visible = true;
                app.UIAxes_1.Visible = true;
                
                app.ErreurLabel_1.Visible = false;
                app.Label_11.Text = '';
                app.Label_12.Text = '';
                WpButton_Pushed(app, event);
                
            elseif strcmp(selectedButton.Text,'Plot Training')
                WpButton_Pushed(app, event);
            else
                cla(app.UIAxes_1);
                app.ErreurLabel_1.Visible = false;
                app.Label_11.Text = '';
                app.Label_12.Text = '';
                
                y1 = mean(app.Plottr_Err1);
                y2 = mean(app.Plotval_Err1);
                x = 1:size(app.n1,1);
                xr = num2str(app.n1);
                for j=1:size(xr,1)
                    l(j,:) = strrep(xr(j,:),'  ',',');
                end
                xr=l;
                
                plot(app.UIAxes_1,x,y1);   hold(app.UIAxes_1,'on');
                plot(app.UIAxes_1,x,y2);   hold(app.UIAxes_1,'off');
                app.UIAxes_1.XTick = x;
                app.UIAxes_1.XTickLabel = xr;
                app.UIAxes_1.Title.String = 'Performances generales';
                app.UIAxes_1.XTickLabelRotation = 25;
                app.UIAxes_1.XLabel.String = 'Couches de neuronnes';
                app.UIAxes_1.YLabel.String = 'Erreur moyenne';
                [v, i] = min(y1);
                
                app.ErreurLabel_1.Visible = false;
                app.Couches = app.n1(i,:)';
                c = num2str(app.Couches');
                c = strrep(c,'  ',',');
                app.OptiLabel1.Text = xr(i,:);
                app.CouchesneuronnesEditField_1.Value = ['[',c,']'];
                app.CouchesFormatLabel_1.Text = ['[',c,']'];
                app.CommandesPanel.Visible = true;
                app.UIAxes_1.Visible = true;
                
                app.ErreurLabel_1.Visible = false;
                app.CommandesPanel.Visible = false;
                app.UIAxes_1.Visible = true;
            end
        end

        % Button pushed function: HButton_2, HButton_3, HButton_4
        function HButton_Pushed(app, event)
            i=1;
            selectedButton2 = app.ControlPlotButtonGroup_2.SelectedObject;
            selectedButton3 = app.ControlPlotButtonGroup_3.SelectedObject;
            selectedButton4 = app.ControlPlotButtonGroup_4.SelectedObject;
            selectedTab = app.TabGroup.SelectedTab;
            
            
            if selectedTab == app.E2S2Tab
                if strcmp(selectedButton2.Text,'Plot Performance')
                    cla(app.UIAxes_2);
                    
                    y1 = app.Plottr_Err2;
                    y2 = app.Plotval_Err2;
                    x = 1:size(app.n2,1);
                    
                    xr = num2str(app.n2);
                    for j=1:size(xr,1)
                        l(j,:) = strrep(xr(j,:),'  ',',');
                    end
                    xr=l;
                    
                    plot(app.UIAxes_2,x,y1(i,:));   hold(app.UIAxes_2,'on');
                    plot(app.UIAxes_2,x,y2(i,:));   hold(app.UIAxes_2,'off');
                    app.UIAxes_2.XTick = x;
                    app.UIAxes_2.XTickLabel = xr;
                    app.UIAxes_2.Title.String = 'Performances détaillées H';
                    app.UIAxes_2.XTickLabelRotation = 25;
                    app.UIAxes_2.XLabel.String = 'Couches de neuronnes';
                    app.UIAxes_2.YLabel.String = 'Erreur quadratique';
                    [v, ind] = min(y1(i,:));
                    
                    app.Couches2 = app.n2(ind,:)';
                    c = num2str(app.Couches2');
                    c = strrep(c,'  ',',');
                    app.OptiLabel1_2.Text = ['[',xr(ind,:),']'];
                    app.CouchesneuronnesEditField_2.Value = ['[',c,']'];
                    app.CouchesFormatLabel_2.Text = ['[',c,']'];
                    app.CommandesPanel_2.Visible = true;
                    app.UIAxes_2.Visible = true;
                else
                    cla(app.UIAxes_2);
                    train = app.Plot_train_training2(i,:);
                    val = app.Plot_val_training2(i,:);
                    trainTrue = app.Plot_train_trainingTrue2(i,:);
                    valTrue = app.Plot_val_trainingTrue2(i,:);
                    app.Label_20.Text = num2str(mean(app.Err2(:,i)));
                    app.Label_21.Text = num2str(mean(app.Err2(:,i+1)));
                    app.ErreurLabel_2.Visible = true;
                    app.CommandesPanel_2.Visible = true;
                    app.UIAxes_2.Visible = true;
                    
                    app.UIAxes_2.Title.String = 'Entrainement H';
                    
                    plot(app.UIAxes_2,trainTrue,train);   hold(app.UIAxes_2,'on');
                    plot(app.UIAxes_2,valTrue,val);       hold(app.UIAxes_2,'off');
                    app.UIAxes_2.XTickMode = 'auto';
                    app.UIAxes_2.XTickLabelMode = 'auto';
                    app.UIAxes_2.XLabel.String = 'Valeurs calculées';
                    app.UIAxes_2.YLabel.String = 'Valeurs dans la base de donnée';
                    app.UIAxes_2.XTickLabelRotation = 0;
                end
            elseif selectedTab == app.E3S3Tab
                if strcmp(selectedButton3.Text,'Plot Performance')
                    cla(app.UIAxes_3);
                    
                    y1 = app.Plottr_Err3;
                    y2 = app.Plotval_Err3;
                    x = 1:size(app.n3,1);
                    
                    xr = num2str(app.n3);
                    for j=1:size(xr,1)
                        l(j,:) = strrep(xr(j,:),'  ',',');
                    end
                    xr=l;
                    
                    plot(app.UIAxes_3,x,y1(i,:));   hold(app.UIAxes_3,'on');
                    plot(app.UIAxes_3,x,y2(i,:));   hold(app.UIAxes_3,'off');
                    app.UIAxes_3.XTick = x;
                    app.UIAxes_3.XTickLabel = xr;
                    app.UIAxes_3.Title.String = 'Performances détaillées H';
                    app.UIAxes_3.XTickLabelRotation = 25;
                    app.UIAxes_3.XLabel.String = 'Couches de neuronnes';
                    app.UIAxes_3.YLabel.String = 'Erreur quadratique';
                    [v, ind] = min(y1(i,:));
                    
                    app.Couches3 = app.n3(ind,:)';
                    c = num2str(app.Couches3');
                    c = strrep(c,'  ',',');
                    app.OptiLabel1_3.Text = ['[',xr(ind,:),']'];
                    app.CouchesneuronnesEditField_3.Value = ['[',c,']'];
                    app.CouchesFormatLabel_3.Text = ['[',c,']'];
                    app.CommandesPanel_3.Visible = true;
                    app.UIAxes_3.Visible = true;
                else
                    cla(app.UIAxes_3);
                    train = app.Plot_train_training3(i,:);
                    val = app.Plot_val_training3(i,:);
                    trainTrue = app.Plot_train_trainingTrue3(i,:);
                    valTrue = app.Plot_val_trainingTrue3(i,:);
                    
                    app.UIAxes_3.Title.String = 'Entrainement H';
                    
                    plot(app.UIAxes_3,trainTrue,train);   hold(app.UIAxes_3,'on');
                    plot(app.UIAxes_3,valTrue,val);       hold(app.UIAxes_3,'off');
                    app.UIAxes_3.XTickMode = 'auto';
                    app.UIAxes_3.XTickLabelMode = 'auto';
                    app.UIAxes_3.XLabel.String = 'Valeurs calculées';
                    app.UIAxes_3.YLabel.String = 'Valeurs dans la base de donnée';
                    app.UIAxes_3.XTickLabelRotation = 0;
                end
            elseif selectedTab == app.E4S4Tab
                if strcmp(selectedButton4.Text,'Plot Performance')
                    cla(app.UIAxes_4);
                    
                    y1 = app.Plottr_Err4;
                    y2 = app.Plotval_Err4;
                    x = 1:size(app.n4,1);
                    
                    xr = num2str(app.n4);
                    for j=1:size(xr,1)
                        l(j,:) = strrep(xr(j,:),'  ',',');
                    end
                    xr=l;
                    
                    plot(app.UIAxes_4,x,y1(i,:));   hold(app.UIAxes_4,'on');
                    plot(app.UIAxes_4,x,y2(i,:));   hold(app.UIAxes_4,'off');
                    app.UIAxes_4.XTick = x;
                    app.UIAxes_4.XTickLabel = xr;
                    app.UIAxes_4.Title.String = 'Performances détaillées H';
                    app.UIAxes_4.XTickLabelRotation = 25;
                    app.UIAxes_4.XLabel.String = 'Couches de neuronnes';
                    app.UIAxes_4.YLabel.String = 'Erreur quadratique';
                    [v, ind] = min(y1(i,:));
                    
                    app.Couches4 = app.n4(ind,:)';
                    c = num2str(app.Couches4');
                    c = strrep(c,'  ',',');
                    app.OptiLabel1_4.Text = ['[',xr(ind,:),']'];
                    app.CouchesneuronnesEditField_4.Value = ['[',c,']'];
                    app.CouchesFormatLabel_4.Text = ['[',c,']'];
                    app.CommandesPanel_5.Visible = true;
                    app.UIAxes_4.Visible = true;
                else
                    cla(app.UIAxes_4);
                    train = app.Plot_train_training4(i,:);
                    val = app.Plot_val_training4(i,:);
                    trainTrue = app.Plot_train_trainingTrue4(i,:);
                    valTrue = app.Plot_val_trainingTrue4(i,:);
                    
                    app.Label_41.Text = num2str(mean(app.Err4(:,i)));
                    app.Label_42.Text = num2str(mean(app.Err4(:,i+1)));
                    app.Label_44.Text = num2str(mean(app.Err4(:,i+1)));
                    app.ErreurLabel_4.Visible = true;
                    app.CommandesPanel_5.Visible = true;
                    app.UIAxes_4.Visible = true;
                    
                    app.UIAxes_4.Title.String = 'Entrainement H';
                    
                    plot(app.UIAxes_4,trainTrue,train);   hold(app.UIAxes_4,'on');
                    plot(app.UIAxes_4,valTrue,val);       hold(app.UIAxes_4,'off');
                    app.UIAxes_4.XTickMode = 'auto';
                    app.UIAxes_4.XTickLabelMode = 'auto';
                    app.UIAxes_4.XLabel.String = 'Valeurs calculées';
                    app.UIAxes_4.YLabel.String = 'Valeurs dans la base de donnée';
                    app.UIAxes_4.XTickLabelRotation = 0;
                end
            end
        end

        % Value changed function: ErEditField_3
        function ErEditField_3ValueChanged(app, event)
            value = app.ErEditField_3.Value;
            app.Er3 = value;
        end

        % Value changed function: FrEditField_3
        function FrEditField_3ValueChanged(app, event)
            value = app.FrEditField_3.Value;
            app.Fr3 = value;
            
        end

        % Value changed function: WpEditField_3
        function WpEditField_3ValueChanged(app, event)
            value = app.WpEditField_3.Value;
            app.Wp = value;
        end

        % Value changed function: Z0EditField_3
        function Z0EditField_3ValueChanged(app, event)
            value = app.Z0EditField_3.Value;
            
        end

        % Value changed function: HEditField_3
        function HEditField_3ValueChanged(app, event)
            value = app.HEditField_3.Value;
            
        end

        % Button pushed function: TransfrerversCSTButton_3
        function TransfrerversCSTButton_3Pushed(app, event)
            t = app.tEditField_3.Value; % en micrometre
            Fmin = app.FminEditField_3.Value; Fmax = app.FmaxEditField_3.Value;
            filenameTXT = 'resultats.txt';
            
            [nL,Nc] = size(app.row3);
            if(nL==1 & ~strcmp(app.Path_CST,''))
                %addpath('Functions');
                Hs = app.row3(2);        Er = app.row3(3);
                Wp = app.row3(6);        Lp = app.row3(7);
                Fi = app.row3(8);        Wf = app.row3(9);
                Gpf= app.row3(10);       Lg = app.row3(11);
                Wg = app.row3(12);
                %fprintf("%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n", Hs, Er,Wp, Lp, Fi, Wf, Gpf, Lg, Wg, Fmin, Fmax, t, app.Path_CST, filenameTXT);
                toCST(Hs, Er,Wp, Lp, Fi, Wf, Gpf, Lg, Wg, Fmin, Fmax, t, app.Path_CST, filenameTXT);
                PlotSij(app, filenameTXT, 3);
            end
        end

        % Value changed function: ErEditField_4
        function ErEditField_4ValueChanged(app, event)
            value = app.ErEditField_4.Value;
            app.Er4 = value;
        end

        % Value changed function: FrEditField_4
        function FrEditField_4ValueChanged(app, event)
            value = app.FrEditField_4.Value;
            app.Fr4 = value;
        end

        % Value changed function: HEditField_4
        function HEditField_4ValueChanged(app, event)
            value = app.HEditField_4.Value;
            
        end

        % Value changed function: FiEditField_4
        function FiEditField_4ValueChanged(app, event)
            value = app.FiEditField_4.Value;
            
        end

        % Button pushed function: TransfrerversCSTButton_4
        function TransfrerversCSTButton_4Pushed(app, event)
            t = app.tEditField_4.Value; % en micrometre
            Fmin = app.FminEditField_4.Value; Fmax = app.FmaxEditField_4.Value;
            filenameTXT = 'resultats.txt';
            
            [nL,Nc] = size(app.row4);
            if(nL==1 & ~strcmp(app.Path_CST,''))
                %addpath('Functions');
                Hs = app.row4(2);        Er = app.row4(3);
                Wp = app.row4(6);        Lp = app.row4(7);
                Fi = app.row4(8);        Wf = app.row4(9);
                Gpf= app.row4(10);       Lg = app.row4(11);
                Wg = app.row4(12);
                %fprintf("%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n", Hs, Er,Wp, Lp, Fi, Wf, Gpf, Lg, Wg, Fmin, Fmax, t, app.Path_CST, filenameTXT);
                toCST(Hs, Er,Wp, Lp, Fi, Wf, Gpf, Lg, Wg, Fmin, Fmax, t, app.Path_CST, filenameTXT);
                PlotSij(app, filenameTXT, 4);
            end
        end

        % Button pushed function: ErButton_2, ErButton_3
        function ErButton_Pushed(app, event)
            i=2;
            selectedButton2 = app.ControlPlotButtonGroup_2.SelectedObject;
            selectedButton5 = app.ControlPlotButtonGroup_5.SelectedObject;
            selectedTab = app.TabGroup.SelectedTab;
            
            if selectedTab == app.E2S2Tab
                if strcmp(selectedButton2.Text,'Plot Performance')
                    cla(app.UIAxes_2);
                    
                    y1 = app.Plottr_Err2;
                    y2 = app.Plotval_Err2;
                    x = 1:size(app.n2,1);
                    
                    xr = num2str(app.n2);
                    for j=1:size(xr,1)
                        l(j,:) = strrep(xr(j,:),'  ',',');
                    end
                    xr=l;
                    
                    plot(app.UIAxes_2,x,y1(i,:));   hold(app.UIAxes_2,'on');
                    plot(app.UIAxes_2,x,y2(i,:));   hold(app.UIAxes_2,'off');
                    app.UIAxes_2.XTick = x;
                    app.UIAxes_2.XTickLabel = xr;
                    app.UIAxes_2.Title.String = 'Performances détaillées Er';
                    app.UIAxes_2.XTickLabelRotation = 25;
                    app.UIAxes_2.XLabel.String = 'Couches de neuronnes';
                    app.UIAxes_2.YLabel.String = 'Erreur quadratique';
                    [v, ind] = min(y1(i,:));
                    
                    app.Couches2 = app.n2(ind,:)';
                    c = num2str(app.Couches2');
                    c = strrep(c,'  ',',');
                    app.OptiLabel1_2.Text = ['[',xr(ind,:),']'];
                    app.CouchesneuronnesEditField_2.Value = ['[',c,']'];
                    app.CouchesFormatLabel_2.Text = ['[',c,']'];
                    
                    app.CommandesPanel_2.Visible = true;
                    app.UIAxes_2.Visible = true;
                else
                    cla(app.UIAxes_2);
                    train = app.Plot_train_training2(i,:);
                    val = app.Plot_val_training2(i,:);
                    trainTrue = app.Plot_train_trainingTrue2(i,:);
                    valTrue = app.Plot_val_trainingTrue2(i,:);
                    app.ErreurLabel_2.Visible = true;
                    app.CommandesPanel_2.Visible = true;
                    app.UIAxes_2.Visible = true;
                    app.UIAxes_2.Title.String = 'Entrainement Er';
                    
                    plot(app.UIAxes_2,trainTrue,train);   hold(app.UIAxes_2,'on');
                    plot(app.UIAxes_2,valTrue,val);       hold(app.UIAxes_2,'off');
                    app.UIAxes_2.XTickMode = 'auto';
                    app.UIAxes_2.XTickLabelMode = 'auto';
                    app.UIAxes_2.XLabel.String = 'Valeurs calculées';
                    app.UIAxes_2.YLabel.String = 'Valeurs dans la base de donnée';
                    app.UIAxes_2.XTickLabelRotation = 0;
                end
            elseif selectedTab == app.E5S5Tab
                i=1;
                if strcmp(selectedButton5.Text,'Plot Performance')
                    cla(app.UIAxes_5);
                    
                    y1 = app.Plottr_Err5;
                    y2 = app.Plotval_Err5;
                    x = 1:size(app.n5,1);
                    
                    xr = num2str(app.n5);
                    for j=1:size(xr,1)
                        l(j,:) = strrep(xr(j,:),'  ',',');
                    end
                    xr=l;
                    
                    plot(app.UIAxes_5,x,y1(i,:));   hold(app.UIAxes_5,'on');
                    plot(app.UIAxes_5,x,y2(i,:));   hold(app.UIAxes_5,'off');
                    app.UIAxes_5.XTick = x;
                    app.UIAxes_5.XTickLabel = xr;
                    app.UIAxes_5.Title.String = 'Performances détaillées Er';
                    app.UIAxes_5.XTickLabelRotation = 25;
                    app.UIAxes_5.XLabel.String = 'Couches de neuronnes';
                    app.UIAxes_5.YLabel.String = 'Erreur quadratique';
                    [v, ind] = min(y1(i,:));
                    
                    app.Couches5 = app.n5(ind,:)';
                    c = num2str(app.Couches5');
                    c = strrep(c,'  ',',');
                    app.OptiLabel1_5.Text = ['[',xr(ind,:),']'];
                    app.CouchesneuronnesEditField_5.Value = ['[',c,']'];
                    app.CouchesFormatLabel_5.Text = ['[',c,']'];
                    app.CommandesPanel_5.Visible = true;
                    app.UIAxes_5.Visible = true;
                else
                    cla(app.UIAxes_5);
                    train = app.Plot_train_training5(i,:);
                    val = app.Plot_val_training5(i,:);
                    trainTrue = app.Plot_train_trainingTrue5(i,:);
                    valTrue = app.Plot_val_trainingTrue5(i,:);
                    app.Label_51.Text = num2str(mean(app.Err5(:,i)));
                    app.ErreurLabel_5.Visible = true;
                    app.CommandesPanel_5.Visible = true;
                    app.UIAxes_5.Visible = true;
                    
                    app.UIAxes_5.Title.String = 'Entrainement Er';
                    
                    plot(app.UIAxes_5,trainTrue,train);   hold(app.UIAxes_5,'on');
                    plot(app.UIAxes_5,valTrue,val);       hold(app.UIAxes_5,'off');
                    app.UIAxes_5.XTickMode = 'auto';
                    app.UIAxes_5.XTickLabelMode = 'auto';
                    app.UIAxes_5.XLabel.String = 'Valeurs calculées';
                    app.UIAxes_5.YLabel.String = 'Valeurs dans la base de donnée';
                    app.UIAxes_5.XTickLabelRotation = 0;
                end
            end
        end

        % Callback function
        function HEditField_16ValueChanged(app, event)
            value = app.HEditField_0.Value;
            app.H5 = value;
        end

        % Callback function
        function FrEditField_15ValueChanged(app, event)
            value = app.FrEditField_5.Value;
            app.Fr5 = value;
        end

        % Button pushed function: CalculButton_11, CalculButton_2, 
        % CalculButton_3, CalculButton_4, CalculButton_7, CalculButton_8, 
        % CalculButton_9
        function CalculButton_Pushed(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if selectedTab == app.E0S0Tab
                H = app.HEditField_0.Value;            Er = app.ErEditField_0.Value;            Fr = app.FrEditField_0.Value;
                [Wp,Lp,Fi,Wf,Gpf,Lg, Wg] = antenna1(H,Er,Fr,app.Z0);
                app.WpEditField_0.Value = Wp;
                app.LpEditField_0.Value = Lp;
                app.FiEditField_0.Value = Fi;
                app.WfEditField_0.Value = Wf;
                app.GpfEditField_0.Value = Gpf;
                app.LgEditField_0.Value = Lg;
                app.WgEditField_0.Value = Wg;
                data1 = {size(app.UITable_0.Data,1)+1,H, Er, Fr, app.Z0, Wp, Lp, Fi, Wf, Gpf, Lg, Wg, 0};
                app.UITable_0.Data = [data1;app.UITable_0.Data];
                app.ToutEffacerButton_0.Enable = true;
            elseif selectedTab == app.E1S1Tab
                bd = load(fullfile(app.Path_bds(1),app.File_bds(1)), '-mat');               %--> Chargement de la bd
                bd = bd.bd; disp(app.File_neurons(1))
                net = load(fullfile(app.Path_neurons(1) ,app.File_neurons(1)), '-mat');      %--> Chargement du réseau de enuronne
                netfin = net.net;
                H = app.HEditField_1.Value;            Er = app.ErEditField_1.Value;            Fr = app.FrEditField_1.Value;
                bd=bd';
                xfin=bd(1:3,:);
                testfin=[H,Er,Fr]';
                for i =1:3
                    testfin(i) = (testfin(i)-min(xfin(i,:)))/(max(xfin(i,:))-min(xfin(i,:)));
                end
                testify=netfin(testfin);
                testify=exp(testify)-1;
                Wp = testify(1);            Lp = testify(2);
                Fi = calcFi(Fr, app.Z0, Wp, Lp);            Wf = calcWf(H, Er, app.Z0);            Gpf = calcGpf(H, Er, Fr, Wp);            Lg = calcLg(H, Lp);            Wg = calcWg(H, Wp);
                app.WpEditField_1.Value = Wp;
                app.LpEditField_1.Value = Lp;
                app.FiEditField_1.Value = Fi;
                app.WfEditField_1.Value = Wf;
                app.GpfEditField_1.Value = Gpf;
                app.LgEditField_1.Value = Lg;
                app.WgEditField_1.Value = Wg;
                app.ErrmoyEditField_1.Value = net.Err;
                data1 = {size(app.UITable_1.Data,1)+1,H, Er, Fr, app.Z0, Wp, Lp, Fi, Wf, Gpf, Lg, Wg, net.Err};
                app.UITable_1.Data = [data1;app.UITable_1.Data];
                app.ToutEffacerButton_1.Enable = true;
            elseif selectedTab == app.E2S2Tab
                
                bd = load(fullfile(app.Path_bds(2),app.File_bds(2)), '-mat');               %--> Chargement de la bd
                bd = bd.bd;
                net = load(fullfile(app.Path_neurons(2) ,app.File_neurons(2)), '-mat');      %--> Chargement du réseau de enuronne
                netfin = net.net;
                Wp = app.WpEditField_2.Value;            Lp = app.LpEditField_2.Value;            Fr = app.FrEditField_2.Value;
                bd=bd';
                xfin=bd([3 5:6],:);
                testfin=[Fr,Wp,Lp]';
                for i =1:3
                    testfin(i) = (testfin(i)-min(xfin(i,:)))/(max(xfin(i,:))-min(xfin(i,:)));
                end
                testify=netfin(testfin);
                testify=exp(testify)-1;
                H = testify(1);            Er = testify(2);
                Fi = calcFi(Fr, app.Z0, Wp, Lp);            Wf = calcWf(H, Er, app.Z0);            Gpf = calcGpf(H, Er, Fr, Wp);            Lg = calcLg(H, Lp);            Wg = calcWg(H, Wp);
                app.HEditField_2.Value = H;
                app.ErEditField_2.Value = Er;
                app.FiEditField_2.Value = Fi;
                app.WfEditField_2.Value = Wf;
                app.GpfEditField_2.Value = Gpf;
                app.LgEditField_2.Value = Lg;
                app.WgEditField_2.Value = Wg;
                app.ErrmoyEditField_2.Value = net.Err;
                
                data1 = {size(app.UITable_2.Data,1)+1,H, Er, Fr, app.Z0, Wp, Lp, Fi, Wf, Gpf, Lg, Wg, net.Err};
                app.UITable_2.Data = [data1;app.UITable_2.Data];
                app.ToutEffacerButton_2.Enable = true;
                
            elseif selectedTab == app.E3S3Tab
                
                bd = load(fullfile(app.Path_bds(3),app.File_bds(3)), '-mat');               %--> Chargement de la bd
                bd = bd.bd;
                net = load(fullfile(app.Path_neurons(3) ,app.File_neurons(3)), '-mat');      %--> Chargement du réseau de enuronne
                netfin = net.net;
                Er = app.ErEditField_3.Value;            Lp = app.LpEditField_3.Value;            Fr = app.FrEditField_3.Value;         Wp = app.WpEditField_3.Value;
                bd=bd';
                xfin=bd([2:3 5:6],:);
                testfin=[Er,Fr,Wp,Lp]';
                for i =1:4
                    testfin(i) = (testfin(i)-min(xfin(i,:)))/(max(xfin(i,:))-min(xfin(i,:)));
                end
                testify=netfin(testfin);
                testify=exp(testify)-1;
                H = testify(1);
                Fi = calcFi(Fr, app.Z0, Wp, Lp);            Wf = calcWf(H, Er, app.Z0);            Gpf = calcGpf(H, Er, Fr, Wp);            Lg = calcLg(H, Lp);            Wg = calcWg(H, Wp);
                app.HEditField_3.Value = H;
                app.FiEditField_3.Value = Fi; clc; disp(Wf);
                app.WfEditField_3.Value = Wf;
                app.GpfEditField_3.Value = Gpf;
                app.LgEditField_3.Value = Lg;
                app.WgEditField_3.Value = Wg;
                app.ErrmoyEditField_3.Value = net.Err;
                
                data1 = {size(app.UITable_3.Data,1)+1,H, Er, Fr, app.Z0, Wp, Lp, Fi, Wf, Gpf, Lg, Wg, net.Err};
                app.UITable_3.Data = [data1;app.UITable_3.Data];
                app.ToutEffacerButton_3.Enable = true;
                
            elseif selectedTab == app.E4S4Tab
                
                bd = load(fullfile(app.Path_bds(4),app.File_bds(4)), '-mat');               %--> Chargement de la bd
                display(app.Path_bds(4));
                display(app.File_bds(4));
                bd = bd.bd;
                net = load(fullfile(app.Path_neurons(4) ,app.File_neurons(4)), '-mat');      %--> Chargement du réseau de enuronne
                netfin = net.net;
                Er = app.ErEditField_4.Value;            Fr = app.FrEditField_4.Value;
                bd=bd';
                xfin=bd(2:3,:);
                testfin=[Er,Fr]';
                for i =1:2
                    testfin(i) = (testfin(i)-min(xfin(i,:)))/(max(xfin(i,:))-min(xfin(i,:)));
                end
                testify=netfin(testfin);
                testify=exp(testify)-1;
                H = testify(1);         Wp = testify(2);            Lp = testify(3);
                Fi = calcFi(Fr, app.Z0, Wp, Lp);            Wf = calcWf(H, Er, app.Z0);            Gpf = calcGpf(H, Er, Fr, Wp);            Lg = calcLg(H, Lp);            Wg = calcWg(H, Wp);
                app.HEditField_4.Value = H;
                app.WpEditField_4.Value = Wp;
                app.LpEditField_4.Value = Lp;
                app.FiEditField_4.Value = Fi;
                app.WfEditField_4.Value = Wf;
                app.GpfEditField_4.Value = Gpf;
                app.LgEditField_4.Value = Lg;
                app.WgEditField_4.Value = Wg;
                app.ErrmoyEditField_4.Value = net.Err;
                
                data1 = {size(app.UITable_4.Data,1)+1,H, Er, Fr, app.Z0, Wp, Lp, Fi, Wf, Gpf, Lg, Wg, net.Err};
                app.UITable_4.Data = [data1;app.UITable_4.Data];
                app.ToutEffacerButton_4.Enable = true;
                
            elseif selectedTab == app.E5S5Tab
                bd = load(fullfile(app.Path_bds(5),app.File_bds(5)), '-mat');               %--> Chargement de la bd
                display(app.Path_bds(5));
                display(app.File_bds(5));
                bd = bd.bd;
                net = load(fullfile(app.Path_neurons(5) ,app.File_neurons(5)), '-mat');      %--> Chargement du réseau de enuronne
                netfin = net.net;
                Er = app.HEditField_7.Value;            Fr = app.FrEditField_5.Value;              Wp = app.WpEditField_5.Value;            Lp = app.LpEditField_5.Value;
                bd=bd';
                xfin=bd([1 3 5:6],:);
                testfin=[Er,Fr,Wp,Lp]';
                for i =1:4
                    testfin(i) = (testfin(i)-min(xfin(i,:)))/(max(xfin(i,:))-min(xfin(i,:)));
                end
                testify=netfin(testfin);
                testify=exp(testify)-1;
                H = testify(1);
                Fi = calcFi(Fr, app.Z0, Wp, Lp);            Wf = calcWf(H, Er, app.Z0);            Gpf = calcGpf(H, Er, Fr, Wp);            Lg = calcLg(H, Lp);            Wg = calcWg(H, Wp);
                app.ErEditField_7.Value = H;
                app.FiEditField_5.Value = Fi;
                app.WfEditField_5.Value = Wf;
                app.GpfEditField_5.Value = Gpf;
                app.LgEditField_5.Value = Lg;
                app.WgEditField_5.Value = Wg;
                app.ErrmoyEditField_5.Value = net.Err;
                
                data1 = {size(app.UITable_5.Data,1)+1,H, Er, Fr, app.Z0, Wp, Lp, Fi, Wf, Gpf, Lg, Wg, net.Err};
                app.UITable_5.Data = [data1;app.UITable_5.Data];
                app.ToutEffacerButton_5.Enable = true;
                
            elseif selectedTab == app.E6S6Tab
                bd = load(fullfile(app.Path_bds(6),app.File_bds(6)), '-mat');               %--> Chargement de la bd
                display(app.Path_bds(6));
                display(app.File_bds(6));
                bd = bd.bd;
                net = load(fullfile(app.Path_neurons(6) ,app.File_neurons(6)), '-mat');      %--> Chargement du réseau de enuronne
                netfin = net.net;
                Er = app.ErEditField_6.Value;            Fr = app.FrEditField_6.Value;        H = app.HEditField_6.Value;
                bd=bd';
                xfin=bd(1:3,:);
                testfin=[H,Er,Fr]';
                for i =1:3
                    testfin(i) = (testfin(i)-min(xfin(i,:)))/(max(xfin(i,:))-min(xfin(i,:)));
                end
                testify=netfin(testfin);
                testify=exp(testify)-1;
                Wp = testify(1);    Lp = testify(2);    Fi = testify(3);
                Wf = testify(4);    Gpf = testify(5);    Lg = testify(6);            Wg = testify(7);
                app.WpEditField_6.Value = Wp;
                app.LpEditField_6.Value = Lp;
                app.FiEditField_6.Value = Fi;
                app.WfEditField_6.Value = Wf;
                app.GpfEditField_6.Value = Gpf;
                app.LgEditField_6.Value = Lg;
                app.WgEditField_6.Value = Wg;
                app.ErrmoyEditField_6.Value = net.Err;
                
                data1 = {size(app.UITable_6.Data,1)+1,H, Er, Fr, app.Z0, Wp, Lp, Fi, Wf, Gpf, Lg, Wg, net.Err};
                app.UITable_6.Data = [data1;app.UITable_6.Data];
                app.ToutEffacerButton_6.Enable = true;
            end


        end

        % Button pushed function: ReseaudeneuronneButton_17, 
        % ReseaudeneuronneButton_18, ReseaudeneuronneButton_2, 
        % ReseaudeneuronneButton_3, ReseaudeneuronneButton_4, 
        % ReseaudeneuronneButton_6
        function ReseaudeneuronneButton_Pushed(app, event)
            [f_Ne, p_Ne] = uigetfile('*.mat');                %--> Open a mat file
            %drawnow;
            figure(app.UIFigure);
            selectedTab = app.TabGroup.SelectedTab;
            
            if f_Ne ~= 0 & selectedTab == app.E1S1Tab
                app.neuron_label_1.FontColor = '#1a7314';
                app.File_neurons(1) = f_Ne;                                %--> déterminatiton du neuronne par défaut
                app.Path_neurons(1) = p_Ne;
                app.neuron_label_1.Text = app.File_neurons(1);           %--> Changement du label nom du neuronne
            elseif f_Ne ~= 0 & selectedTab == app.E2S2Tab
                app.neuron_label_2.FontColor = '#1a7314';
                app.File_neurons(2) = f_Ne;                                %--> déterminatiton du neuronne par défaut
                app.Path_neurons(2) = p_Ne;
                app.neuron_label_2.Text = app.File_neurons(2);           %--> Changement du label nom du neuronne
            elseif f_Ne ~= 0 & selectedTab == app.E3S3Tab
                app.neuron_label_3.FontColor = '#1a7314';
                app.File_neurons(3) = f_Ne;                                %--> déterminatiton du neuronne par défaut
                app.Path_neurons(3) = p_Ne;
                app.neuron_label_3.Text = app.File_neurons(3);           %--> Changement du label nom du neuronne
            elseif f_Ne ~= 0 & selectedTab == app.E4S4Tab
                app.neuron_label_4.FontColor = '#1a7314';
                app.File_neurons(4) = f_Ne;                                %--> déterminatiton du neuronne par défaut
                app.Path_neurons(4) = p_Ne;
                app.neuron_label_4.Text = app.File_neurons(4);           %--> Changement du label nom du neuronne
            elseif f_Ne ~= 0 & selectedTab == app.E5S5Tab
                app.neuron_label_5.FontColor = '#1a7314';
                app.File_neurons(5) = f_Ne;                                %--> déterminatiton du neuronne par défaut
                app.Path_neurons(5) = p_Ne;
                app.neuron_label_5.Text = app.File_neurons(5);           %--> Changement du label nom du neuronne
                elseif f_Ne ~= 0 & selectedTab == app.E6S6Tab
                app.neuron_label_6.FontColor = '#1a7314';
                app.File_neurons(6) = f_Ne;                                %--> déterminatiton du neuronne par défaut
                app.Path_neurons(6) = p_Ne;
                app.neuron_label_6.Text = app.File_neurons(6);           %--> Changement du label nom du neuronne
            else
                
                try app.neuron_label_1.FontColor = 'black'; end
                try app.neuron_label_2.FontColor = 'black'; end
                try app.neuron_label_3.FontColor = 'black'; end
                try app.neuron_label_4.FontColor = 'black'; end
                try app.neuron_label_5.FontColor = 'black'; end
                try app.neuron_label_6.FontColor = 'black'; end
                
            end
        end

        % Button pushed function: BasedeDonneeButton_17, 
        % BasedeDonneeButton_18, BasedeDonneeButton_2, 
        % BasedeDonneeButton_3, BasedeDonneeButton_4, BasedeDonneeButton_6
        function BasedeDonneeButton_Pushed(app, event)
            [f_BD, p_BD] = uigetfile('*.mat');                %--> Open a mat file
            %drawnow;
            figure(app.UIFigure);
            selectedTab = app.TabGroup.SelectedTab;
            
            if f_BD ~= 0 & selectedTab == app.E1S1Tab
                app.bd_label_1.FontColor = '#1a7314';
                app.File_bds(1) = f_BD;                                %--> déterminatiton du neuronne par défaut
                app.Path_bds(1) = p_BD;
                app.bd_label_1.Text = app.File_bds(1);           %--> Changement du label nom du neuronne
            elseif f_BD ~= 0 & selectedTab == app.E2S2Tab
                app.bd_label_2.FontColor = '#1a7314';
                app.File_bds(2) = f_BD;                                %--> déterminatiton du neuronne par défaut
                app.Path_bds(2) = p_BD;
                app.bd_label_2.Text = app.File_bds(2);           %--> Changement du label nom du neuronne
            elseif f_BD ~= 0 & selectedTab == app.E3S3Tab
                app.bd_label_3.FontColor = '#1a7314';
                app.File_bds(3) = f_BD;                                %--> déterminatiton du neuronne par défaut
                app.Path_bds(3) = p_BD;
                app.bd_label_3.Text = app.File_bds(3);           %--> Changement du label nom du neuronne
            elseif f_BD ~= 0 & selectedTab == app.E4S4Tab
                app.bd_label_4.FontColor = '#1a7314';
                app.File_bds(4) = f_BD;                                %--> déterminatiton du neuronne par défaut
                app.Path_bds(4) = p_BD;
                app.bd_label_4.Text = app.File_bds(4);           %--> Changement du label nom du neuronne
            elseif f_BD ~= 0 & selectedTab == app.E5S5Tab
                app.bd_label_5.FontColor = '#1a7314';
                app.File_bds(5) = f_BD;                                %--> déterminatiton du neuronne par défaut
                app.Path_bds(5) = p_BD;
                app.bd_label_5.Text = app.File_bds(5);           %--> Changement du label nom du neuronne
            elseif f_BD ~= 0 & selectedTab == app.E6S6Tab
                app.bd_label_6.FontColor = '#1a7314';
                app.File_bds(6) = f_BD;                                %--> déterminatiton du neuronne par défaut
                app.Path_bds(6) = p_BD;
                app.bd_label_6.Text = app.File_bds(6);           %--> Changement du label nom du neuronne
            else
                try app.bd_label_1.FontColor = 'black'; end
                try app.bd_label_2.FontColor = 'black'; end
                try app.bd_label_3.FontColor = 'black'; end
                try app.bd_label_4.FontColor = 'black'; end
                try app.bd_label_5.FontColor = 'black'; end
                try app.bd_label_6.FontColor = 'black'; end
            end
        end

        % Selection changed function: ControlPlotButtonGroup_4
        function ControlPlotButtonGroup_4SelectionChanged(app, event)
            selectedButton = app.ControlPlotButtonGroup_4.SelectedObject;
            if strcmp(selectedButton.Text,'Plot Performance')
                cla(app.UIAxes_4);
                
                app.UIAxes_4.Visible = true;
                app.ErreurLabel_4.Visible = false;
                app.CommandesPanel_2.Visible = true;
                app.UIAxes_4.Visible = true;
                
                app.ErreurLabel_4.Visible = false;
                app.Label_41.Text = '';
                app.Label_42.Text = '';
                app.Label_44.Text = '';
                HButton_Pushed(app, event);
                
            elseif strcmp(selectedButton.Text,'Plot Training')
                HButton_Pushed(app, event);
            else
                cla(app.UIAxes_4);
                app.ErreurLabel_4.Visible = false;
                app.Label_41.Text = '';
                app.Label_42.Text = '';
                app.Label_44.Text = '';
                
                y1 = mean(app.Plottr_Err4);
                y2 = mean(app.Plotval_Err4);
                x = 1:size(app.n4,1);
                xr = num2str(app.n4);
                for j=1:size(xr,1)
                    l(j,:) = strrep(xr(j,:),'  ',',');
                end
                xr=l;
                
                plot(app.UIAxes_4,x,y1);   hold(app.UIAxes_4,'on');
                plot(app.UIAxes_4,x,y2);   hold(app.UIAxes_4,'off');
                app.UIAxes_4.XTick = x;
                app.UIAxes_4.XTickLabel = xr;
                app.UIAxes_4.Title.String = 'Performances generales';
                app.UIAxes_4.XTickLabelRotation = 25;
                app.UIAxes_4.XLabel.String = 'Couches de neuronnes';
                app.UIAxes_4.YLabel.String = 'Erreur moyenne';
                [v, i] = min(y1);
                
                app.ErreurLabel_4.Visible = false;
                
                app.Couches4 = app.n4(i,:)';
                c = num2str(app.Couches4');
                c = strrep(c,'  ',',');
                app.OptiLabel1_4.Text = xr(i,:);
                app.CouchesneuronnesEditField_4.Value = ['[',c,']'];
                app.CouchesFormatLabel_4.Text = ['[',c,']'];
                app.CommandesPanel_5.Visible = true;
                app.UIAxes_4.Visible = true;
                app.CommandesPanel_5.Visible = false;
                app.UIAxes_4.Visible = true;
            end
        end

        % Selection changed function: ControlPlotButtonGroup_2
        function ControlPlotButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ControlPlotButtonGroup_2.SelectedObject;
            
            if strcmp(selectedButton.Text,'Plot Performance')
                cla(app.UIAxes_2);
                
                app.UIAxes_2.Visible = true;
                app.ErreurLabel_2.Visible = false;
                app.CommandesPanel_2.Visible = true;
                app.UIAxes_2.Visible = true;
                
                app.ErreurLabel_2.Visible = false;
                app.Label_20.Text = '';
                app.Label_21.Text = '';
                HButton_Pushed(app, event);
                
                
            elseif strcmp(selectedButton.Text,'Plot Training')
                HButton_Pushed(app, event);
            else
                app.ErreurLabel_2.Visible = false;
                cla(app.UIAxes_2);
                app.Label_21.Text = '';
                
                y1 = mean(app.Plottr_Err2);
                y2 = mean(app.Plotval_Err2);
                x = 1:size(app.n2,1);
                xr = num2str(app.n2);
                for j=1:size(xr,1)
                    l(j,:) = strrep(xr(j,:),'  ',',');
                end
                xr=l;
                
                plot(app.UIAxes_2,x,y1);   hold(app.UIAxes_2,'on');
                plot(app.UIAxes_2,x,y2);   hold(app.UIAxes_2,'off');
                app.UIAxes_2.XTick = x;
                app.UIAxes_2.XTickLabel = xr;
                app.UIAxes_2.Title.String = 'Performances generales';
                app.UIAxes_2.XTickLabelRotation = 25;
                app.UIAxes_2.XLabel.String = 'Couches de neuronnes';
                app.UIAxes_2.YLabel.String = 'Erreur moyenne';
                [v, i] = min(y1);
                
                app.ErreurLabel_2.Visible = false;
                
                app.Couches2 = app.n2(i,:)';
                c = num2str(app.Couches2');
                c = strrep(c,'  ',',');
                app.OptiLabel1_2.Text = xr(i,:);
                app.CouchesneuronnesEditField_2.Value = ['[',c,']'];
                app.CouchesFormatLabel_2.Text = ['[',c,']'];
                app.CommandesPanel_2.Visible = true;
                app.UIAxes_2.Visible = true;
                app.ErreurLabel_2.Visible = false;
                app.CommandesPanel_2.Visible = false;
                app.UIAxes_2.Visible = true;
            end
            
        end

        % Selection changed function: ControlPlotButtonGroup_3
        function ControlPlotButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ControlPlotButtonGroup_3.SelectedObject;
            if strcmp(selectedButton.Text,'Plot Performance')
                cla(app.UIAxes_3);
                
                app.UIAxes_3.Visible = true;
                app.ErreurLabel_3.Visible = false;
                app.CommandesPanel_3.Visible = true;
                app.UIAxes_3.Visible = true;
                
                app.ErreurLabel_3.Visible = false;
                app.Label_30.Text = '';
                HButton_Pushed(app, event);
                
            elseif strcmp(selectedButton.Text,'Plot Training')
                k=1;
                app.Label_30.Text = num2str(mean(app.Err3));
                
                cla(app.UIAxes_3);
                app.ErreurLabel_3.Visible = true;
                app.CommandesPanel_3.Visible = true;
                app.UIAxes_3.Visible = true;
                HButton_Pushed(app, event);
            end
        end

        % Selection changed function: ControlPlotButtonGroup_5
        function ControlPlotButtonGroup_5SelectionChanged(app, event)
            selectedButton = app.ControlPlotButtonGroup_5.SelectedObject;
            if strcmp(selectedButton.Text,'Plot Performance')
                cla(app.UIAxes_5);
                
                app.UIAxes_5.Visible = true;
                app.ErreurLabel_5.Visible = false;
                app.CommandesPanel_6.Visible = true;
                app.UIAxes_5.Visible = true;
                
                app.ErreurLabel_5.Visible = false;
                app.Label_51.Text = '';
                ErButton_Pushed(app, event);
                
            elseif strcmp(selectedButton.Text,'Plot Training')
                ErButton_Pushed(app, event);
            else
                app.ErreurLabel_5.Visible = false;
                cla(app.UIAxes_5);
                app.Label_51.Text = '';
                app.Label_52.Text = '';
                app.Label_54.Text = '';
                
                y1 = mean(app.Plottr_Err5);
                y2 = mean(app.Plotval_Err5);
                x = 1:size(app.n5,1);
                xr = num2str(app.n5);
                for j=1:size(xr,1)
                    l(j,:) = strrep(xr(j,:),'  ',',');
                end
                xr=l;
                
                plot(app.UIAxes_5,x,y1);   hold(app.UIAxes_5,'on');
                plot(app.UIAxes_5,x,y2);   hold(app.UIAxes_5,'off');
                app.UIAxes_5.XTick = x;
                app.UIAxes_5.XTickLabel = xr;
                app.UIAxes_5.Title.String = 'Performances generales';
                app.UIAxes_5.XTickLabelRotation = 25;
                app.UIAxes_5.XLabel.String = 'Couches de neuronnes';
                app.UIAxes_5.YLabel.String = 'Erreur moyenne';
                [v, i] = min(y1);
                
                app.ErreurLabel_5.Visible = false;
                
                app.Couches5 = app.n5(i,:)';
                c = num2str(app.Couches5');
                c = strrep(c,'  ',',');
                app.OptiLabel1_5.Text = xr(i,:);
                app.CouchesneuronnesEditField_5.Value = ['[',c,']'];
                app.CouchesFormatLabel_5.Text = ['[',c,']'];
                app.CommandesPanel_6.Visible = true;
                app.UIAxes_5.Visible = true;
                app.CommandesPanel_5.Visible = false;
                app.UIAxes_5.Visible = true;
                
            end
        end

        % Value changed function: LpEditField_3
        function LpEditField_3ValueChanged(app, event)
            value = app.LpEditField_3.Value;
            app.Lp3 = value;
        end

        % Button pushed function: TransfrerversCSTButton_0
        function TransfrerversCSTButton_0Pushed(app, event)
            t = app.tEditField_0.Value; % en micrometre
            Fmin = app.FminEditField_0.Value; Fmax = app.FmaxEditField_0.Value;
            filenameTXT = 'resultats.txt';
            [nL,Nc] = size(app.row0);
            if(nL==1 & ~strcmp(app.Path_CST,''))
                Hs = app.row0(2);        Er = app.row0(3);
                Wp = app.row0(6);        Lp = app.row0(7);
                Fi = app.row0(8);        Wf = app.row0(9);
                Gpf= app.row0(10);       Lg = app.row0(11);
                Wg = app.row0(12);
                toCST(Hs, Er,Wp, Lp, Fi, Wf, Gpf, Lg, Wg, Fmin, Fmax, t, app.Path_CST, filenameTXT);
                %fprintf("%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n", Hs, Er,Wp, Lp, Fi, Wf, Gpf, Lg, Wg, Fmin, Fmax, t, app.Path_CST, filenameTXT);
                PlotSij(app, filenameTXT, 0);
            end
        end

        % Button pushed function: TransfrerversCSTButton_5
        function TransfrerversCSTButton_5Pushed(app, event)
            t = app.tEditField_5.Value; % en micrometre
            Fmin = app.FminEditField_5.Value; Fmax = app.FmaxEditField_5.Value;
            filenameTXT = 'resultats.txt';
            
            [nL,Nc] = size(app.row5);
            if(nL==1 & ~strcmp(app.Path_CST,''))
                
                Hs = app.row5(2);        Er = app.row5(3);
                Wp = app.row5(6);        Lp = app.row5(7);
                Fi = app.row5(8);        Wf = app.row5(9);
                Gpf= app.row5(10);       Lg = app.row5(11);
                Wg = app.row5(12);
                %fprintf("%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n", Hs, Er,Wp, Lp, Fi, Wf, Gpf, Lg, Wg, Fmin, Fmax, t, app.Path_CST, filenameTXT);
                toCST(Hs, Er,Wp, Lp, Fi, Wf, Gpf, Lg, Wg, Fmin, Fmax, t, app.Path_CST, filenameTXT);
                PlotSij(app, filenameTXT, 5);
                
            end
        end

        % Selection changed function: ControlPlotButtonGroup_6
        function ControlPlotButtonGroup_6SelectionChanged(app, event)
            selectedButton = app.ControlPlotButtonGroup_6.SelectedObject;
            if strcmp(selectedButton.Text,'Plot Performance')
                cla(app.UIAxes_6);
                
                app.UIAxes_6.Visible = true;
                app.ErreurLabel_6.Visible = false;
                app.CommandesPanel_7.Visible = true;
                app.UIAxes_6.Visible = true;
                
                app.ErreurLabel_6.Visible = false;
                app.Label_61.Text = '';
                app.Label_62.Text = '';
                app.Label_63.Text = '';
                app.Label_64.Text = '';
                app.Label_65.Text = '';
                app.Label_66.Text = '';
                app.Label_67.Text = '';
                WpButton_Pushed(app, event);
                
            elseif strcmp(selectedButton.Text,'Plot Training')
                WpButton_Pushed(app, event);
            else
                cla(app.UIAxes_5);
                app.Label_61.Text = '';
                app.Label_62.Text = '';
                app.Label_62.Text = '';
                app.Label_63.Text = '';
                app.Label_64.Text = '';
                app.Label_65.Text = '';
                app.Label_66.Text = '';
                app.Label_67.Text = '';
                app.ErreurLabel_6.Visible = false;
                y1 = mean(app.Plottr_Err6);
                y2 = mean(app.Plotval_Err6);
                x = 1:size(app.n6,1);
                xr = num2str(app.n6);
                for j=1:size(xr,1)
                    l(j,:) = strrep(xr(j,:),'  ',',');
                end
                xr=l;
                
                plot(app.UIAxes_6,x,y1);   hold(app.UIAxes_6,'on');
                plot(app.UIAxes_6,x,y2);   hold(app.UIAxes_6,'off');
                app.UIAxes_6.XTick = x;
                app.UIAxes_6.XTickLabel = xr;
                app.UIAxes_6.Title.String = 'Performances generales';
                app.UIAxes_6.XTickLabelRotation = 25;
                app.UIAxes_6.XLabel.String = 'Couches de neuronnes';
                app.UIAxes_6.YLabel.String = 'Erreur moyenne';
                [v, i] = min(y1);
                
                app.ErreurLabel_6.Visible = false;
                
                app.Couches6 = app.n6(i,:)';
                c = num2str(app.Couches6');
                c = strrep(c,'  ',',');
                app.OptiLabel1_6.Text = xr(i,:);
                app.CouchesneuronnesEditField_6.Value = ['[',c,']'];
                app.CouchesFormatLabel_6.Text = ['[',c,']'];
                app.CommandesPanel_6.Visible = true;
                app.UIAxes_6.Visible = true;
                app.CommandesPanel_7.Visible = false;
                app.UIAxes_6.Visible = true;
            end
        end

        % Button pushed function: TransfrerversCSTButton_6
        function TransfrerversCSTButton_6Pushed(app, event)
            t = app.tEditField_6.Value; % en micrometre
            Fmin = app.FminEditField_6.Value; Fmax = app.FmaxEditField_6.Value;
            filenameTXT = 'resultats.txt';
            
            [nL,Nc] = size(app.row6);
            if(nL==1 & ~strcmp(app.Path_CST,''))
                
                Hs = app.row6(2);        Er = app.row6(3);
                Wp = app.row6(6);        Lp = app.row6(7);
                Fi = app.row6(8);        Wf = app.row6(9);
                Gpf= app.row6(10);       Lg = app.row6(11);
                Wg = app.row6(12);
                %fprintf("%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n%s\n", Hs, Er,Wp, Lp, Fi, Wf, Gpf, Lg, Wg, Fmin, Fmax, t, app.Path_CST, filenameTXT);
                toCST(Hs, Er,Wp, Lp, Fi, Wf, Gpf, Lg, Wg, Fmin, Fmax, t, app.Path_CST, filenameTXT);
                PlotSij(app, filenameTXT, 6);
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AccueilTab = uitab(app.TabGroup);
            app.AccueilTab.Title = 'Accueil';
            app.InformationsPanel_2 = uipanel(app.AccueilTab);
            app.InformationsPanel_2.Title = 'Informations';
            app.GridLayout13_2 = uigridlayout(app.InformationsPanel_2);
            app.E1S1Label = uilabel(app.GridLayout13_2);
            app.E1S1Label.Text = 'E1 ---> S1';
            app.E2S2Label = uilabel(app.GridLayout13_2);
            app.E2S2Label.Text = 'E2 ---> S2';
            app.E3S3Label = uilabel(app.GridLayout13_2);
            app.E3S3Label.Text = 'E3 ---> S3';
            app.HrFrLabel = uilabel(app.GridLayout13_2);
            app.HrFrLabel.Text = 'H, εr, Fr               --->    ';
            app.FrWpLpLabel = uilabel(app.GridLayout13_2);
            app.FrWpLpLabel.Text = 'Fr, Wp, Lp          --->   ';
            app.ErFrWpLpLabel = uilabel(app.GridLayout13_2);
            app.ErFrWpLpLabel.Text = 'Er, Fr, Wp, Lp     --->    ';
            app.OutputLabel = uilabel(app.GridLayout13_2);
            app.OutputLabel.Text = 'Output';
            app.InputLabel = uilabel(app.GridLayout13_2);
            app.InputLabel.Text = 'Input';
            app.WpLpLabel = uilabel(app.GridLayout13_2);
            app.WpLpLabel.Text = 'Wp, Lp';
            app.HErLabel = uilabel(app.GridLayout13_2);
            app.HErLabel.Text = 'H , Er';
            app.HLabel = uilabel(app.GridLayout13_2);
            app.HLabel.Text = 'H';
            app.E4S4Label = uilabel(app.GridLayout13_2);
            app.E4S4Label.Text = 'E4 ---> S4';
            app.ErFrLabel = uilabel(app.GridLayout13_2);
            app.ErFrLabel.Text = 'Er, Fr                  --->    ';
            app.HWpLpLabel = uilabel(app.GridLayout13_2);
            app.HWpLpLabel.Text = 'H, Wp, Lp';
            app.HFrWpLpLabel = uilabel(app.GridLayout13_2);
            app.HFrWpLpLabel.Text = 'H, Fr, Wp, Lp     --->';
            app.ErLabel = uilabel(app.GridLayout13_2);
            app.ErLabel.Text = 'Er';
            app.E5S5Label = uilabel(app.GridLayout13_2);
            app.E5S5Label.Text = 'E5 ---> S5';
            app.CalculThoriqueLabel = uilabel(app.GridLayout13_2);
            app.CalculThoriqueLabel.Text = 'Calcul Théorique';
            app.HrFrLabel_2 = uilabel(app.GridLayout13_2);
            app.HrFrLabel_2.Text = 'H, εr, Fr              --->    ';
            app.E6S6Label = uilabel(app.GridLayout13_2);
            app.E6S6Label.Text = 'E6 ---> S6';
            app.Label_677 = uilabel(app.GridLayout13_2);
            app.Label_677.Text = '--------------------------------------------';
            app.Label_676 = uilabel(app.GridLayout13_2);
            app.Label_676.Text = '--------------------------------------------';
            app.WpLpFiWfGpfLgWgLabel = uilabel(app.GridLayout13_2);
            app.WpLpFiWfGpfLgWgLabel.Text = 'Wp, Lp, Fi, Wf, Gpf, Lg, Wg';
            app.E0S0Tab = uitab(app.TabGroup);
            app.E0S0Tab.Title = 'Calculs Théorique';
            app.TabGroup2_9 = uitabgroup(app.E0S0Tab);
            app.CalculTab_9 = uitab(app.TabGroup2_9);
            app.CalculTab_9.Title = 'Calcul';
            app.UIAxesSij0 = uiaxes(app.CalculTab_9);
            title(app.UIAxesSij0, 'patch antenna at 2.45GHz')
            xlabel(app.UIAxesSij0, 'Frequency (GHz)')
            ylabel(app.UIAxesSij0, 'S11 (dB)')
            app.EntresPanel_14 = uipanel(app.CalculTab_9);
            app.EntresPanel_14.Title = 'Entrées';
            app.GridLayout2_20 = uigridlayout(app.EntresPanel_14);
            app.mmLabel_157 = uilabel(app.GridLayout2_20);
            app.mmLabel_157.Text = 'mm';
            app.Label_76 = uilabel(app.GridLayout2_20);
            app.Label_76.Text = '';
            app.GHzLabel_19 = uilabel(app.GridLayout2_20);
            app.GHzLabel_19.Text = 'GHz';
            app.HEditField_16Label = uilabel(app.GridLayout2_20);
            app.HEditField_16Label.Text = 'H';
            app.HEditField_0 = uieditfield(app.GridLayout2_20, 'numeric');
            app.HEditField_0.ValueDisplayFormat = '%11.3f';
            app.HEditField_0.Value = 1.6;
            app.ErEditField_16Label = uilabel(app.GridLayout2_20);
            app.ErEditField_16Label.Text = 'Er';
            app.ErEditField_0 = uieditfield(app.GridLayout2_20, 'numeric');
            app.ErEditField_0.ValueDisplayFormat = '%11.3f';
            app.ErEditField_0.Value = 4.3;
            app.FrEditField_16Label = uilabel(app.GridLayout2_20);
            app.FrEditField_16Label.Text = 'Fr';
            app.FrEditField_0 = uieditfield(app.GridLayout2_20, 'numeric');
            app.FrEditField_0.ValueDisplayFormat = '%11.3f';
            app.FrEditField_0.Value = 2.45;
            app.RsultatsPanel = uipanel(app.CalculTab_9);
            app.RsultatsPanel.Title = 'Résultats';
            app.GridLayout3_23 = uigridlayout(app.RsultatsPanel);
            app.mmLabel_158 = uilabel(app.GridLayout3_23);
            app.mmLabel_158.Text = 'mm';
            app.mmLabel_159 = uilabel(app.GridLayout3_23);
            app.mmLabel_159.Text = 'mm';
            app.mmLabel_160 = uilabel(app.GridLayout3_23);
            app.mmLabel_160.Text = 'mm';
            app.mmLabel_161 = uilabel(app.GridLayout3_23);
            app.mmLabel_161.Text = 'mm';
            app.mmLabel_162 = uilabel(app.GridLayout3_23);
            app.mmLabel_162.Text = 'mm';
            app.mmLabel_163 = uilabel(app.GridLayout3_23);
            app.mmLabel_163.Text = 'mm';
            app.mmLabel_164 = uilabel(app.GridLayout3_23);
            app.mmLabel_164.Text = 'mm';
            app.WpEditField_19Label = uilabel(app.GridLayout3_23);
            app.WpEditField_19Label.Text = 'Wp';
            app.WpEditField_0 = uieditfield(app.GridLayout3_23, 'numeric');
            app.WpEditField_0.ValueDisplayFormat = '%11.3f';
            app.WpEditField_0.Editable = 'off';
            app.LpEditField_19Label = uilabel(app.GridLayout3_23);
            app.LpEditField_19Label.Text = 'Lp';
            app.LpEditField_0 = uieditfield(app.GridLayout3_23, 'numeric');
            app.LpEditField_0.ValueDisplayFormat = '%11.3f';
            app.LpEditField_0.Editable = 'off';
            app.FiEditField_19Label = uilabel(app.GridLayout3_23);
            app.FiEditField_19Label.Text = 'Fi';
            app.FiEditField_0 = uieditfield(app.GridLayout3_23, 'numeric');
            app.FiEditField_0.ValueDisplayFormat = '%11.3f';
            app.FiEditField_0.Editable = 'off';
            app.WfEditField_19Label = uilabel(app.GridLayout3_23);
            app.WfEditField_19Label.Text = 'Wf';
            app.WfEditField_0 = uieditfield(app.GridLayout3_23, 'numeric');
            app.WfEditField_0.ValueDisplayFormat = '%11.3f';
            app.WfEditField_0.Editable = 'off';
            app.GpfEditField_19Label = uilabel(app.GridLayout3_23);
            app.GpfEditField_19Label.Text = 'Gpf';
            app.GpfEditField_0 = uieditfield(app.GridLayout3_23, 'numeric');
            app.GpfEditField_0.ValueDisplayFormat = '%11.3f';
            app.GpfEditField_0.Editable = 'off';
            app.LgEditField_19Label = uilabel(app.GridLayout3_23);
            app.LgEditField_19Label.Text = 'Lg';
            app.LgEditField_0 = uieditfield(app.GridLayout3_23, 'numeric');
            app.LgEditField_0.ValueDisplayFormat = '%11.3f';
            app.LgEditField_0.Editable = 'off';
            app.WgEditField_19Label = uilabel(app.GridLayout3_23);
            app.WgEditField_19Label.Text = 'Wg';
            app.WgEditField_0 = uieditfield(app.GridLayout3_23, 'numeric');
            app.WgEditField_0.ValueDisplayFormat = '%11.3f';
            app.WgEditField_0.Editable = 'off';
            app.DfautButton_6 = uibutton(app.CalculTab_9, 'push');
            app.DfautButton_6.ButtonPushedFcn = createCallbackFcn(app, @DfautButton_Pushed, true);
            app.DfautButton_6.Text = 'Défaut';
            app.Image_9 = uiimage(app.CalculTab_9);
            app.Image_9.ImageSource = 'antena_indic.jpg';
            app.EffacerlaligneButton_0 = uibutton(app.CalculTab_9, 'push');
            app.EffacerlaligneButton_0.ButtonPushedFcn = createCallbackFcn(app, @EffacerlaligneButton_Pushed, true);
            app.EffacerlaligneButton_0.Enable = 'off';
            app.EffacerlaligneButton_0.Text = 'Effacer la ligne';
            app.ToutEffacerButton_0 = uibutton(app.CalculTab_9, 'push');
            app.ToutEffacerButton_0.ButtonPushedFcn = createCallbackFcn(app, @ToutEffacerButton_Pushed, true);
            app.ToutEffacerButton_0.Enable = 'off';
            app.ToutEffacerButton_0.Text = 'Tout Effacer';
            app.TransfrerversCSTButton_0 = uibutton(app.CalculTab_9, 'push');
            app.TransfrerversCSTButton_0.ButtonPushedFcn = createCallbackFcn(app, @TransfrerversCSTButton_0Pushed, true);
            app.TransfrerversCSTButton_0.Enable = 'off';
            app.TransfrerversCSTButton_0.Text = 'Transférer vers CST';
            app.CalculButton_9 = uibutton(app.CalculTab_9, 'push');
            app.CalculButton_9.ButtonPushedFcn = createCallbackFcn(app, @CalculButton_Pushed, true);
            app.CalculButton_9.Text = 'Calcul';
            app.UITable_0 = uitable(app.CalculTab_9);
            app.UITable_0.ColumnName = {'N°'; 'H'; 'Er'; 'Fr'; 'Z0'; 'Wp'; 'Lp'; 'Fi'; 'Wf'; 'Gpf'; 'Lg'; 'Wg'; 'Err. moy.'};
            app.UITable_0.RowName = {''};
            app.UITable_0.CellSelectionCallback = createCallbackFcn(app, @UITable_CellSelection, true);
            app.DataPanel_6 = uipanel(app.CalculTab_9);
            app.DataPanel_6.Title = 'Data';
            app.GridLayout2_21 = uigridlayout(app.DataPanel_6);
            app.GHzLabel_20 = uilabel(app.GridLayout2_21);
            app.GHzLabel_20.Text = 'GHz';
            app.GHzLabel_21 = uilabel(app.GridLayout2_21);
            app.GHzLabel_21.Text = 'GHz';
            app.mLabel_6 = uilabel(app.GridLayout2_21);
            app.mLabel_6.Text = 'μm';
            app.FminEditField_6Label = uilabel(app.GridLayout2_21);
            app.FminEditField_6Label.Text = 'Fmin';
            app.FminEditField_0 = uieditfield(app.GridLayout2_21, 'numeric');
            app.FminEditField_0.ValueDisplayFormat = '%11.3f';
            app.FminEditField_0.Value = 1;
            app.FmaxEditField_6Label = uilabel(app.GridLayout2_21);
            app.FmaxEditField_6Label.Text = 'Fmax';
            app.FmaxEditField_0 = uieditfield(app.GridLayout2_21, 'numeric');
            app.FmaxEditField_0.ValueDisplayFormat = '%11.3f';
            app.FmaxEditField_0.Value = 3;
            app.tEditField_6Label = uilabel(app.GridLayout2_21);
            app.tEditField_6Label.Text = 't';
            app.tEditField_0 = uieditfield(app.GridLayout2_21, 'numeric');
            app.tEditField_0.ValueDisplayFormat = '%11.3f';
            app.tEditField_0.Value = 35;
            app.Label_77 = uilabel(app.GridLayout2_21);
            app.Label_77.Text = 'Ω';
            app.Z0EditField_16Label = uilabel(app.GridLayout2_21);
            app.Z0EditField_16Label.Text = 'Z0';
            app.Z0EditField_0 = uieditfield(app.GridLayout2_21, 'numeric');
            app.Z0EditField_0.ValueDisplayFormat = '%11.3f';
            app.Z0EditField_0.Editable = 'off';
            app.Z0EditField_0.Value = 50;
            app.E1S1Tab = uitab(app.TabGroup);
            app.E1S1Tab.Title = 'E1->S1';
            app.TabGroup2 = uitabgroup(app.E1S1Tab);
            app.EntrainementTab_5 = uitab(app.TabGroup2);
            app.EntrainementTab_5.Title = 'Entrainement';
            app.UIAxes_1 = uiaxes(app.EntrainementTab_5);
            title(app.UIAxes_1, 'Title')
            xlabel(app.UIAxes_1, 'Valeur de la base de donnée')
            ylabel(app.UIAxes_1, 'Valeur calculée')
            app.UIAxes_1.LineStyleOrder = {'-'};
            app.ParamtresPanel = uipanel(app.EntrainementTab_5);
            app.ParamtresPanel.Title = 'Paramètres';
            app.GridLayout10 = uigridlayout(app.ParamtresPanel);
            app.PourcentagevalidationEditField_3Label = uilabel(app.GridLayout10);
            app.PourcentagevalidationEditField_3Label.Text = 'Pourcentage validation';
            app.PourcentagevalidationEditField_1 = uieditfield(app.GridLayout10, 'numeric');
            app.PourcentagevalidationEditField_1.Value = 25;
            app.PourcentagetestEditField_3Label = uilabel(app.GridLayout10);
            app.PourcentagetestEditField_3Label.Text = 'Pourcentage test';
            app.PourcentagetestEditField_1 = uieditfield(app.GridLayout10, 'numeric');
            app.PourcentagetestEditField_1.Value = 5;
            app.PourcentageEntrainementEditFieldLabel = uilabel(app.GridLayout10);
            app.PourcentageEntrainementEditFieldLabel.Text = 'Pourcentage Entrainement';
            app.PourcentageEntrainementEditField_1 = uieditfield(app.GridLayout10, 'numeric');
            app.PourcentageEntrainementEditField_1.Value = 70;
            app.EpochsEditField_3Label = uilabel(app.GridLayout10);
            app.EpochsEditField_3Label.Text = 'Epochs';
            app.EpochsEditField_1 = uieditfield(app.GridLayout10, 'numeric');
            app.EpochsEditField_1.Value = 100;
            app.CouchesneuronnesEditField_7Label = uilabel(app.GridLayout10);
            app.CouchesneuronnesEditField_7Label.Text = 'Couches & neuronnes';
            app.CouchesneuronnesEditField_1 = uieditfield(app.GridLayout10, 'text');
            app.CouchesneuronnesEditField_1.ValueChangedFcn = createCallbackFcn(app, @CouchesneuronnesEditField_ValueChanged, true);
            app.CouchesneuronnesEditField_1.Value = '[1]';
            app.CouchesFormatLabel_1 = uilabel(app.GridLayout10);
            app.CouchesFormatLabel_1.Text = 'Format : [nbNeuronnes,nbNeuronnes,....,nbNeuronnes]';
            app.OptionsPanel = uipanel(app.EntrainementTab_5);
            app.OptionsPanel.Title = 'Options';
            app.GridLayout11 = uigridlayout(app.OptionsPanel);
            app.SlectionnerlabasededonneButton_1 = uibutton(app.GridLayout11, 'push');
            app.SlectionnerlabasededonneButton_1.ButtonPushedFcn = createCallbackFcn(app, @SlectionnerlabasededonneButton_Pushed, true);
            app.SlectionnerlabasededonneButton_1.Text = 'Sélectionner la base de donnée';
            app.EnregistrerlerseauButton_1 = uibutton(app.GridLayout11, 'push');
            app.EnregistrerlerseauButton_1.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerlerseauButton_Pushed, true);
            app.EnregistrerlerseauButton_1.Enable = 'off';
            app.EnregistrerlerseauButton_1.Text = 'Enregistrer le réseau';
            app.EntranerlerseauButton_1 = uibutton(app.GridLayout11, 'push');
            app.EntranerlerseauButton_1.ButtonPushedFcn = createCallbackFcn(app, @EntranerlerseauButton_Pushed, true);
            app.EntranerlerseauButton_1.Text = 'Entraîner le réseau';
            app.RechercheCouchenbNeuronnesoptimauxButton_1 = uibutton(app.GridLayout11, 'push');
            app.RechercheCouchenbNeuronnesoptimauxButton_1.ButtonPushedFcn = createCallbackFcn(app, @RechercheCouchenbNeuronnesoptimauxButton_Pushed, true);
            app.RechercheCouchenbNeuronnesoptimauxButton_1.Text = {'Recherche Couche & nbNeuronnes'; 'optimaux'};
            app.nomBdLabel_1 = uilabel(app.EntrainementTab_5);
            app.nomBdLabel_1.Text = 'bd.mat';
            app.BDdentranementLabel_1 = uilabel(app.EntrainementTab_5);
            app.BDdentranementLabel_1.Text = 'BD d''entraînement';
            app.FichierdesauvegardeLabel_1 = uilabel(app.EntrainementTab_5);
            app.FichierdesauvegardeLabel_1.Text = 'Fichier de sauvegarde';
            app.ChemSauvLabel_1 = uilabel(app.EntrainementTab_5);
            app.ChemSauvLabel_1.Text = '';
            app.CommandesPanel = uipanel(app.EntrainementTab_5);
            app.CommandesPanel.Title = 'Commandes';
            app.GridLayout12 = uigridlayout(app.CommandesPanel);
            app.WpButton_1 = uibutton(app.GridLayout12, 'push');
            app.WpButton_1.ButtonPushedFcn = createCallbackFcn(app, @WpButton_Pushed, true);
            app.WpButton_1.Text = 'Wp';
            app.LpButton_1 = uibutton(app.GridLayout12, 'push');
            app.LpButton_1.ButtonPushedFcn = createCallbackFcn(app, @LpButton_Pushed, true);
            app.LpButton_1.Text = 'Lp';
            app.Label_11 = uilabel(app.GridLayout12);
            app.Label_11.Text = '';
            app.Label_12 = uilabel(app.GridLayout12);
            app.Label_12.Text = '';
            app.ErreurLabel_1 = uilabel(app.EntrainementTab_5);
            app.ErreurLabel_1.Visible = 'off';
            app.ErreurLabel_1.Text = 'Erreur';
            app.ControlPlotButtonGroup = uibuttongroup(app.EntrainementTab_5);
            app.ControlPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ControlPlotButtonGroupSelectionChanged, true);
            app.ControlPlotButtonGroup.Title = 'Control Plot';
            app.PlotPerformanceButton_1 = uitogglebutton(app.ControlPlotButtonGroup);
            app.PlotPerformanceButton_1.Text = 'Plot Performance';
            app.PlotTrainingButton_1 = uitogglebutton(app.ControlPlotButtonGroup);
            app.PlotTrainingButton_1.Text = 'Plot Training';
            app.PlotGeneralPerformanceButton_1 = uitogglebutton(app.ControlPlotButtonGroup);
            app.PlotGeneralPerformanceButton_1.Text = 'Plot General Performance';
            app.PlotGeneralPerformanceButton_1.Value = true;
            app.CouchesoptimalesLabel_1 = uilabel(app.EntrainementTab_5);
            app.CouchesoptimalesLabel_1.Text = 'Couches optimales';
            app.OptiLabel1 = uilabel(app.EntrainementTab_5);
            app.OptiLabel1.Text = '';
            app.ValeurspardfautButton_1 = uibutton(app.EntrainementTab_5, 'push');
            app.ValeurspardfautButton_1.ButtonPushedFcn = createCallbackFcn(app, @ValeurspardfautButton_Pushed, true);
            app.ValeurspardfautButton_1.Text = 'Valeurs par défaut';
            app.ErreurmoyenneEditField_3Label = uilabel(app.EntrainementTab_5);
            app.ErreurmoyenneEditField_3Label.Text = 'Erreur moyenne';
            app.ErreurmoyenneEditField_1 = uieditfield(app.EntrainementTab_5, 'text');
            app.ErreurmoyenneEditField_1.Editable = 'off';
            app.ErreurmoyenneEditField_1.Value = '0';
            app.CalculTab = uitab(app.TabGroup2);
            app.CalculTab.Title = 'Calcul';
            app.UIAxesSij1 = uiaxes(app.CalculTab);
            title(app.UIAxesSij1, 'patch antenna at 2.45GHz')
            xlabel(app.UIAxesSij1, 'Frequency (GHz)')
            ylabel(app.UIAxesSij1, 'S11 (dB)')
            app.EntresPanel = uipanel(app.CalculTab);
            app.EntresPanel.Title = 'Entrées';
            app.GridLayout2 = uigridlayout(app.EntresPanel);
            app.mmLabel = uilabel(app.GridLayout2);
            app.mmLabel.Text = 'mm';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '';
            app.GHzLabel_15 = uilabel(app.GridLayout2);
            app.GHzLabel_15.Text = 'GHz';
            app.HEditField_10Label = uilabel(app.GridLayout2);
            app.HEditField_10Label.Text = 'H';
            app.HEditField_1 = uieditfield(app.GridLayout2, 'numeric');
            app.HEditField_1.ValueDisplayFormat = '%11.3f';
            app.HEditField_1.Value = 1.6;
            app.ErEditField_10Label = uilabel(app.GridLayout2);
            app.ErEditField_10Label.Text = 'Er';
            app.ErEditField_1 = uieditfield(app.GridLayout2, 'numeric');
            app.ErEditField_1.ValueDisplayFormat = '%11.3f';
            app.ErEditField_1.Value = 4.3;
            app.FrEditField_10Label = uilabel(app.GridLayout2);
            app.FrEditField_10Label.Text = 'Fr';
            app.FrEditField_1 = uieditfield(app.GridLayout2, 'numeric');
            app.FrEditField_1.ValueDisplayFormat = '%11.3f';
            app.FrEditField_1.Value = 2.45;
            app.DfautButton_1 = uibutton(app.CalculTab, 'push');
            app.DfautButton_1.ButtonPushedFcn = createCallbackFcn(app, @DfautButton_Pushed, true);
            app.DfautButton_1.Text = 'Défaut';
            app.Image_1 = uiimage(app.CalculTab);
            app.Image_1.ImageSource = 'antena_indic.jpg';
            app.EffacerlaligneButton_1 = uibutton(app.CalculTab, 'push');
            app.EffacerlaligneButton_1.ButtonPushedFcn = createCallbackFcn(app, @EffacerlaligneButton_Pushed, true);
            app.EffacerlaligneButton_1.Enable = 'off';
            app.EffacerlaligneButton_1.Text = 'Effacer la ligne';
            app.ToutEffacerButton_1 = uibutton(app.CalculTab, 'push');
            app.ToutEffacerButton_1.ButtonPushedFcn = createCallbackFcn(app, @ToutEffacerButton_Pushed, true);
            app.ToutEffacerButton_1.Enable = 'off';
            app.ToutEffacerButton_1.Text = 'Tout Effacer';
            app.TransfrerversCSTButton_1 = uibutton(app.CalculTab, 'push');
            app.TransfrerversCSTButton_1.ButtonPushedFcn = createCallbackFcn(app, @TransfrerversCSTButton_1Pushed, true);
            app.TransfrerversCSTButton_1.Enable = 'off';
            app.TransfrerversCSTButton_1.Text = 'Transférer vers CST';
            app.UITable_1 = uitable(app.CalculTab);
            app.UITable_1.ColumnName = {'N°'; 'H'; 'Er'; 'Fr'; 'Z0'; 'Wp'; 'Lp'; 'Fi'; 'Wf'; 'Gpf'; 'Lg'; 'Wg'; 'Err. moy.'};
            app.UITable_1.RowName = {''};
            app.UITable_1.CellSelectionCallback = createCallbackFcn(app, @UITable_CellSelection, true);
            app.DataPanel_3 = uipanel(app.CalculTab);
            app.DataPanel_3.Title = 'Data';
            app.GridLayout2_16 = uigridlayout(app.DataPanel_3);
            app.GHzLabel_6 = uilabel(app.GridLayout2_16);
            app.GHzLabel_6.Text = 'GHz';
            app.GHzLabel_7 = uilabel(app.GridLayout2_16);
            app.GHzLabel_7.Text = 'GHz';
            app.mLabel_3 = uilabel(app.GridLayout2_16);
            app.mLabel_3.Text = 'μm';
            app.FminEditField_5Label = uilabel(app.GridLayout2_16);
            app.FminEditField_5Label.Text = 'Fmin';
            app.FminEditField_1 = uieditfield(app.GridLayout2_16, 'numeric');
            app.FminEditField_1.ValueDisplayFormat = '%11.3f';
            app.FminEditField_1.Value = 1;
            app.FmaxEditField_5Label = uilabel(app.GridLayout2_16);
            app.FmaxEditField_5Label.Text = 'Fmax';
            app.FmaxEditField_1 = uieditfield(app.GridLayout2_16, 'numeric');
            app.FmaxEditField_1.ValueDisplayFormat = '%11.3f';
            app.FmaxEditField_1.Value = 3;
            app.tEditField_5Label = uilabel(app.GridLayout2_16);
            app.tEditField_5Label.Text = 't';
            app.tEditField_1 = uieditfield(app.GridLayout2_16, 'numeric');
            app.tEditField_1.ValueDisplayFormat = '%11.3f';
            app.tEditField_1.Value = 35;
            app.Label_48 = uilabel(app.GridLayout2_16);
            app.Label_48.Text = 'Ω';
            app.Z0EditField_10Label = uilabel(app.GridLayout2_16);
            app.Z0EditField_10Label.Text = 'Z0';
            app.Z0EditField_1 = uieditfield(app.GridLayout2_16, 'numeric');
            app.Z0EditField_1.ValueDisplayFormat = '%11.3f';
            app.Z0EditField_1.Editable = 'off';
            app.Z0EditField_1.Value = 50;
            app.SortiesRNN12sortiesPanel = uipanel(app.CalculTab);
            app.SortiesRNN12sortiesPanel.Title = 'Sorties RNN1 à 2 sorties';
            app.GridLayout3_19 = uigridlayout(app.SortiesRNN12sortiesPanel);
            app.mmLabel_143 = uilabel(app.GridLayout3_19);
            app.mmLabel_143.Text = 'mm';
            app.Label_641 = uilabel(app.GridLayout3_19);
            app.Label_641.Text = '';
            app.WpEditField_16Label = uilabel(app.GridLayout3_19);
            app.WpEditField_16Label.Text = 'Wp';
            app.WpEditField_1 = uieditfield(app.GridLayout3_19, 'numeric');
            app.WpEditField_1.ValueDisplayFormat = '%11.3f';
            app.WpEditField_1.Editable = 'off';
            app.LpEditField_16Label = uilabel(app.GridLayout3_19);
            app.LpEditField_16Label.Text = 'Lp';
            app.LpEditField_1 = uieditfield(app.GridLayout3_19, 'numeric');
            app.LpEditField_1.ValueDisplayFormat = '%11.3f';
            app.LpEditField_1.Editable = 'off';
            app.Err_moyEditFieldLabel_7 = uilabel(app.GridLayout3_19);
            app.Err_moyEditFieldLabel_7.Text = 'Err_moy';
            app.ErrmoyEditField_1 = uieditfield(app.GridLayout3_19, 'numeric');
            app.ErrmoyEditField_1.ValueDisplayFormat = '%11.e';
            app.ErrmoyEditField_1.Editable = 'off';
            app.AutresparamtresPanel_5 = uipanel(app.CalculTab);
            app.AutresparamtresPanel_5.Title = 'Autres paramètres';
            app.GridLayout3_20 = uigridlayout(app.AutresparamtresPanel_5);
            app.mmLabel_17 = uilabel(app.GridLayout3_20);
            app.mmLabel_17.Text = 'mm';
            app.mmLabel_145 = uilabel(app.GridLayout3_20);
            app.mmLabel_145.Text = 'mm';
            app.mmLabel_146 = uilabel(app.GridLayout3_20);
            app.mmLabel_146.Text = 'mm';
            app.mmLabel_147 = uilabel(app.GridLayout3_20);
            app.mmLabel_147.Text = 'mm';
            app.mmLabel_148 = uilabel(app.GridLayout3_20);
            app.mmLabel_148.Text = 'mm';
            app.FiEditField_17Label = uilabel(app.GridLayout3_20);
            app.FiEditField_17Label.Text = 'Fi';
            app.FiEditField_1 = uieditfield(app.GridLayout3_20, 'numeric');
            app.FiEditField_1.ValueDisplayFormat = '%11.3f';
            app.FiEditField_1.Editable = 'off';
            app.WfEditField_17Label = uilabel(app.GridLayout3_20);
            app.WfEditField_17Label.Text = 'Wf';
            app.WfEditField_1 = uieditfield(app.GridLayout3_20, 'numeric');
            app.WfEditField_1.ValueDisplayFormat = '%11.3f';
            app.WfEditField_1.Editable = 'off';
            app.GpfEditField_17Label = uilabel(app.GridLayout3_20);
            app.GpfEditField_17Label.Text = 'Gpf';
            app.GpfEditField_1 = uieditfield(app.GridLayout3_20, 'numeric');
            app.GpfEditField_1.ValueDisplayFormat = '%11.3f';
            app.GpfEditField_1.Editable = 'off';
            app.LgEditField_17Label = uilabel(app.GridLayout3_20);
            app.LgEditField_17Label.Text = 'Lg';
            app.LgEditField_1 = uieditfield(app.GridLayout3_20, 'numeric');
            app.LgEditField_1.ValueDisplayFormat = '%11.3f';
            app.LgEditField_1.Editable = 'off';
            app.WgEditField_17Label = uilabel(app.GridLayout3_20);
            app.WgEditField_17Label.Text = 'Wg';
            app.WgEditField_1 = uieditfield(app.GridLayout3_20, 'numeric');
            app.WgEditField_1.ValueDisplayFormat = '%11.3f';
            app.WgEditField_1.Editable = 'off';
            app.CalculButton_7 = uibutton(app.CalculTab, 'push');
            app.CalculButton_7.ButtonPushedFcn = createCallbackFcn(app, @CalculButton_Pushed, true);
            app.CalculButton_7.Text = 'Calcul';
            app.RNNInputHErFrOutputWpLpPanel = uipanel(app.CalculTab);
            app.RNNInputHErFrOutputWpLpPanel.Title = 'RNN : Input ( H,Er,Fr ) --- Output ( Wp,Lp )';
            app.ReseaudeneuronneButton_17 = uibutton(app.RNNInputHErFrOutputWpLpPanel, 'push');
            app.ReseaudeneuronneButton_17.ButtonPushedFcn = createCallbackFcn(app, @ReseaudeneuronneButton_Pushed, true);
            app.ReseaudeneuronneButton_17.Text = 'Reseau de neuronne';
            app.BasedeDonneeButton_17 = uibutton(app.RNNInputHErFrOutputWpLpPanel, 'push');
            app.BasedeDonneeButton_17.ButtonPushedFcn = createCallbackFcn(app, @BasedeDonneeButton_Pushed, true);
            app.BasedeDonneeButton_17.Text = 'Base de Donnee';
            app.neuron_label_1 = uilabel(app.RNNInputHErFrOutputWpLpPanel);
            app.neuron_label_1.Text = 'RNN1_10_100.mat';
            app.bd_label_1 = uilabel(app.RNNInputHErFrOutputWpLpPanel);
            app.bd_label_1.Text = 'bd.mat';
            app.E2S2Tab = uitab(app.TabGroup);
            app.E2S2Tab.Title = 'E2->S2';
            app.TabGroup2_5 = uitabgroup(app.E2S2Tab);
            app.EntrainementTab_6 = uitab(app.TabGroup2_5);
            app.EntrainementTab_6.Title = 'Entrainement';
            app.UIAxes_2 = uiaxes(app.EntrainementTab_6);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'Valeur de la base de donnée')
            ylabel(app.UIAxes_2, 'Valeur calculée')
            app.UIAxes_2.LineStyleOrder = {'-'};
            app.ParamtresPanel_2 = uipanel(app.EntrainementTab_6);
            app.ParamtresPanel_2.Title = 'Paramètres';
            app.GridLayout10_2 = uigridlayout(app.ParamtresPanel_2);
            app.PourcentagevalidationEditField_2Label = uilabel(app.GridLayout10_2);
            app.PourcentagevalidationEditField_2Label.Text = 'Pourcentage validation';
            app.PourcentagevalidationEditField_2 = uieditfield(app.GridLayout10_2, 'numeric');
            app.PourcentagevalidationEditField_2.Value = 25;
            app.PourcentagetestEditField_2Label = uilabel(app.GridLayout10_2);
            app.PourcentagetestEditField_2Label.Text = 'Pourcentage test';
            app.PourcentagetestEditField_2 = uieditfield(app.GridLayout10_2, 'numeric');
            app.PourcentagetestEditField_2.Value = 5;
            app.PourcentageEntrainementEditField_2Label = uilabel(app.GridLayout10_2);
            app.PourcentageEntrainementEditField_2Label.Text = 'Pourcentage Entrainement';
            app.PourcentageEntrainementEditField_2 = uieditfield(app.GridLayout10_2, 'numeric');
            app.PourcentageEntrainementEditField_2.Value = 70;
            app.EpochsEditField_2Label = uilabel(app.GridLayout10_2);
            app.EpochsEditField_2Label.Text = 'Epochs';
            app.EpochsEditField_2 = uieditfield(app.GridLayout10_2, 'numeric');
            app.EpochsEditField_2.Value = 100;
            app.CouchesneuronnesEditField_6Label = uilabel(app.GridLayout10_2);
            app.CouchesneuronnesEditField_6Label.Text = 'Couches & neuronnes';
            app.CouchesneuronnesEditField_2 = uieditfield(app.GridLayout10_2, 'text');
            app.CouchesneuronnesEditField_2.ValueChangedFcn = createCallbackFcn(app, @CouchesneuronnesEditField_ValueChanged, true);
            app.CouchesneuronnesEditField_2.Value = '[1]';
            app.CouchesFormatLabel_2 = uilabel(app.GridLayout10_2);
            app.CouchesFormatLabel_2.Text = 'Format : [nbNeuronnes,nbNeuronnes,....,nbNeuronnes]';
            app.OptionsPanel_2 = uipanel(app.EntrainementTab_6);
            app.OptionsPanel_2.Title = 'Options';
            app.GridLayout11_2 = uigridlayout(app.OptionsPanel_2);
            app.SlectionnerlabasededonneButton_2 = uibutton(app.GridLayout11_2, 'push');
            app.SlectionnerlabasededonneButton_2.ButtonPushedFcn = createCallbackFcn(app, @SlectionnerlabasededonneButton_Pushed, true);
            app.SlectionnerlabasededonneButton_2.Text = 'Sélectionner la base de donnée';
            app.EnregistrerlerseauButton_2 = uibutton(app.GridLayout11_2, 'push');
            app.EnregistrerlerseauButton_2.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerlerseauButton_Pushed, true);
            app.EnregistrerlerseauButton_2.Enable = 'off';
            app.EnregistrerlerseauButton_2.Text = 'Enregistrer le réseau';
            app.EntranerlerseauButton_2 = uibutton(app.GridLayout11_2, 'push');
            app.EntranerlerseauButton_2.ButtonPushedFcn = createCallbackFcn(app, @EntranerlerseauButton_Pushed, true);
            app.EntranerlerseauButton_2.Text = 'Entraîner le réseau';
            app.RechercheCouchenbNeuronnesoptimauxButton_2 = uibutton(app.GridLayout11_2, 'push');
            app.RechercheCouchenbNeuronnesoptimauxButton_2.ButtonPushedFcn = createCallbackFcn(app, @RechercheCouchenbNeuronnesoptimauxButton_Pushed, true);
            app.RechercheCouchenbNeuronnesoptimauxButton_2.Text = {'Recherche Couche & nbNeuronnes'; 'optimaux'};
            app.ErreurmoyenneEditField_2Label = uilabel(app.EntrainementTab_6);
            app.ErreurmoyenneEditField_2Label.Text = 'Erreur moyenne';
            app.ErreurmoyenneEditField_2 = uieditfield(app.EntrainementTab_6, 'text');
            app.ErreurmoyenneEditField_2.Editable = 'off';
            app.ErreurmoyenneEditField_2.Value = '0';
            app.nomBdLabel_2 = uilabel(app.EntrainementTab_6);
            app.nomBdLabel_2.Text = 'bd.mat';
            app.BDdentranementLabel_2 = uilabel(app.EntrainementTab_6);
            app.BDdentranementLabel_2.Text = 'BD d''entraînement';
            app.FichierdesauvegardeLabel = uilabel(app.EntrainementTab_6);
            app.FichierdesauvegardeLabel.Text = 'Fichier de sauvegarde';
            app.ChemSauvLabel_2 = uilabel(app.EntrainementTab_6);
            app.ChemSauvLabel_2.Text = '';
            app.ErreurLabel_2 = uilabel(app.EntrainementTab_6);
            app.ErreurLabel_2.Visible = 'off';
            app.ErreurLabel_2.Text = 'Erreur';
            app.ControlPlotButtonGroup_2 = uibuttongroup(app.EntrainementTab_6);
            app.ControlPlotButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ControlPlotButtonGroup_2SelectionChanged, true);
            app.ControlPlotButtonGroup_2.Title = 'Control Plot';
            app.PlotPerformanceButton_2 = uitogglebutton(app.ControlPlotButtonGroup_2);
            app.PlotPerformanceButton_2.Text = 'Plot Performance';
            app.PlotTrainingButton_2 = uitogglebutton(app.ControlPlotButtonGroup_2);
            app.PlotTrainingButton_2.Text = 'Plot Training';
            app.PlotGeneralPerformanceButton_2 = uitogglebutton(app.ControlPlotButtonGroup_2);
            app.PlotGeneralPerformanceButton_2.Text = 'Plot General Performance';
            app.PlotGeneralPerformanceButton_2.Value = true;
            app.CouchesoptimalesLabel_2 = uilabel(app.EntrainementTab_6);
            app.CouchesoptimalesLabel_2.Text = 'Couches optimales';
            app.OptiLabel1_2 = uilabel(app.EntrainementTab_6);
            app.OptiLabel1_2.Text = '';
            app.ValeurspardfautButton_2 = uibutton(app.EntrainementTab_6, 'push');
            app.ValeurspardfautButton_2.ButtonPushedFcn = createCallbackFcn(app, @ValeurspardfautButton_Pushed, true);
            app.ValeurspardfautButton_2.Text = 'Valeurs par défaut';
            app.CommandesPanel_2 = uipanel(app.EntrainementTab_6);
            app.CommandesPanel_2.Title = 'Commandes';
            app.GridLayout12_4 = uigridlayout(app.CommandesPanel_2);
            app.HButton_2 = uibutton(app.GridLayout12_4, 'push');
            app.HButton_2.ButtonPushedFcn = createCallbackFcn(app, @HButton_Pushed, true);
            app.HButton_2.Text = 'H';
            app.ErButton_2 = uibutton(app.GridLayout12_4, 'push');
            app.ErButton_2.ButtonPushedFcn = createCallbackFcn(app, @ErButton_Pushed, true);
            app.ErButton_2.Text = 'Er';
            app.Label_20 = uilabel(app.GridLayout12_4);
            app.Label_20.Text = '';
            app.Label_21 = uilabel(app.GridLayout12_4);
            app.Label_21.Text = '';
            app.CalculTab_6 = uitab(app.TabGroup2_5);
            app.CalculTab_6.Title = 'Calcul';
            app.UIAxesSij2 = uiaxes(app.CalculTab_6);
            title(app.UIAxesSij2, 'patch antenna at 2.45GHz')
            xlabel(app.UIAxesSij2, 'Frequency (GHz)')
            ylabel(app.UIAxesSij2, 'S11 (dB)')
            app.EntresPanel_10 = uipanel(app.CalculTab_6);
            app.EntresPanel_10.Title = 'Entrées';
            app.GridLayout2_10 = uigridlayout(app.EntresPanel_10);
            app.mmLabel_77 = uilabel(app.GridLayout2_10);
            app.mmLabel_77.Text = 'mm';
            app.mmLabel_85 = uilabel(app.GridLayout2_10);
            app.mmLabel_85.Text = 'mm';
            app.GHzLabel_25 = uilabel(app.GridLayout2_10);
            app.GHzLabel_25.Text = 'GHz';
            app.WpEditField_12Label = uilabel(app.GridLayout2_10);
            app.WpEditField_12Label.Text = 'Wp';
            app.WpEditField_2 = uieditfield(app.GridLayout2_10, 'numeric');
            app.WpEditField_2.ValueDisplayFormat = '%.3f';
            app.WpEditField_2.ValueChangedFcn = createCallbackFcn(app, @WpEditField_2ValueChanged, true);
            app.WpEditField_2.Value = 37.61;
            app.LpEditField_12Label = uilabel(app.GridLayout2_10);
            app.LpEditField_12Label.Text = 'Lp';
            app.LpEditField_2 = uieditfield(app.GridLayout2_10, 'numeric');
            app.LpEditField_2.ValueDisplayFormat = '%11.3f';
            app.LpEditField_2.ValueChangedFcn = createCallbackFcn(app, @LpEditField_2ValueChanged, true);
            app.LpEditField_2.Value = 29.159;
            app.FrEditField_10Label_2 = uilabel(app.GridLayout2_10);
            app.FrEditField_10Label_2.Text = 'Fr';
            app.FrEditField_2 = uieditfield(app.GridLayout2_10, 'numeric');
            app.FrEditField_2.ValueDisplayFormat = '%11.3f';
            app.FrEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrEditField_2ValueChanged, true);
            app.FrEditField_2.Value = 2.45;
            app.SortiesPanel_10 = uipanel(app.CalculTab_6);
            app.SortiesPanel_10.Title = 'Sorties';
            app.GridLayout3_10 = uigridlayout(app.SortiesPanel_10);
            app.mmLabel_78 = uilabel(app.GridLayout3_10);
            app.mmLabel_78.Text = 'mm';
            app.Label_27 = uilabel(app.GridLayout3_10);
            app.Label_27.Text = '';
            app.HEditField_10Label_2 = uilabel(app.GridLayout3_10);
            app.HEditField_10Label_2.Text = 'H';
            app.HEditField_2 = uieditfield(app.GridLayout3_10, 'numeric');
            app.HEditField_2.ValueDisplayFormat = '%11.3f';
            app.HEditField_2.Editable = 'off';
            app.ErEditField_10Label_2 = uilabel(app.GridLayout3_10);
            app.ErEditField_10Label_2.Text = 'Er';
            app.ErEditField_2 = uieditfield(app.GridLayout3_10, 'numeric');
            app.ErEditField_2.ValueDisplayFormat = '%11.3f';
            app.ErEditField_2.Editable = 'off';
            app.Err_moyEditFieldLabel_2 = uilabel(app.GridLayout3_10);
            app.Err_moyEditFieldLabel_2.Text = 'Err_moy';
            app.ErrmoyEditField_2 = uieditfield(app.GridLayout3_10, 'numeric');
            app.ErrmoyEditField_2.ValueDisplayFormat = '%11.e';
            app.ErrmoyEditField_2.Editable = 'off';
            app.DfautButton_2 = uibutton(app.CalculTab_6, 'push');
            app.DfautButton_2.ButtonPushedFcn = createCallbackFcn(app, @DfautButton_Pushed, true);
            app.DfautButton_2.Text = 'Défaut';
            app.Image_2 = uiimage(app.CalculTab_6);
            app.Image_2.ImageSource = 'antena_indic.jpg';
            app.EffacerlaligneButton_2 = uibutton(app.CalculTab_6, 'push');
            app.EffacerlaligneButton_2.ButtonPushedFcn = createCallbackFcn(app, @EffacerlaligneButton_Pushed, true);
            app.EffacerlaligneButton_2.Enable = 'off';
            app.EffacerlaligneButton_2.Text = 'Effacer la ligne';
            app.ToutEffacerButton_2 = uibutton(app.CalculTab_6, 'push');
            app.ToutEffacerButton_2.ButtonPushedFcn = createCallbackFcn(app, @ToutEffacerButton_Pushed, true);
            app.ToutEffacerButton_2.Enable = 'off';
            app.ToutEffacerButton_2.Text = 'Tout Effacer';
            app.TransfrerversCSTButton_2 = uibutton(app.CalculTab_6, 'push');
            app.TransfrerversCSTButton_2.ButtonPushedFcn = createCallbackFcn(app, @TransfrerversCSTButton_2Pushed, true);
            app.TransfrerversCSTButton_2.Enable = 'off';
            app.TransfrerversCSTButton_2.Text = 'Transférer vers CST';
            app.CalculButton_2 = uibutton(app.CalculTab_6, 'push');
            app.CalculButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculButton_Pushed, true);
            app.CalculButton_2.Text = 'Calcul';
            app.UITable_2 = uitable(app.CalculTab_6);
            app.UITable_2.ColumnName = {'N°'; 'H'; 'Er'; 'Fr'; 'Z0'; 'Wp'; 'Lp'; 'Fi'; 'Wf'; 'Gpf'; 'Lg'; 'Wg'; 'Err. moy.'};
            app.UITable_2.RowName = {};
            app.UITable_2.CellSelectionCallback = createCallbackFcn(app, @UITable_CellSelection, true);
            app.RNNInputFrWpLpOutputHErPanel = uipanel(app.CalculTab_6);
            app.RNNInputFrWpLpOutputHErPanel.Title = 'RNN : Input ( Fr,Wp,Lp ) --- Output ( H,Er )';
            app.ReseaudeneuronneButton_2 = uibutton(app.RNNInputFrWpLpOutputHErPanel, 'push');
            app.ReseaudeneuronneButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReseaudeneuronneButton_Pushed, true);
            app.ReseaudeneuronneButton_2.Text = 'Reseau de neuronne';
            app.BasedeDonneeButton_2 = uibutton(app.RNNInputFrWpLpOutputHErPanel, 'push');
            app.BasedeDonneeButton_2.ButtonPushedFcn = createCallbackFcn(app, @BasedeDonneeButton_Pushed, true);
            app.BasedeDonneeButton_2.Text = 'Base de Donnee';
            app.neuron_label_2 = uilabel(app.RNNInputFrWpLpOutputHErPanel);
            app.neuron_label_2.Text = 'RNN2_10_1000.mat';
            app.bd_label_2 = uilabel(app.RNNInputFrWpLpOutputHErPanel);
            app.bd_label_2.Text = 'bd.mat';
            app.DataPanel_2 = uipanel(app.CalculTab_6);
            app.DataPanel_2.Title = 'Data';
            app.GridLayout2_15 = uigridlayout(app.DataPanel_2);
            app.GHzLabel_4 = uilabel(app.GridLayout2_15);
            app.GHzLabel_4.Text = 'GHz';
            app.GHzLabel_5 = uilabel(app.GridLayout2_15);
            app.GHzLabel_5.Text = 'GHz';
            app.mLabel_2 = uilabel(app.GridLayout2_15);
            app.mLabel_2.Text = 'μm';
            app.FminEditField_4Label = uilabel(app.GridLayout2_15);
            app.FminEditField_4Label.Text = 'Fmin';
            app.FminEditField_2 = uieditfield(app.GridLayout2_15, 'numeric');
            app.FminEditField_2.ValueDisplayFormat = '%11.3f';
            app.FminEditField_2.Value = 1;
            app.FmaxEditField_4Label = uilabel(app.GridLayout2_15);
            app.FmaxEditField_4Label.Text = 'Fmax';
            app.FmaxEditField_2 = uieditfield(app.GridLayout2_15, 'numeric');
            app.FmaxEditField_2.ValueDisplayFormat = '%11.3f';
            app.FmaxEditField_2.Value = 3;
            app.tEditField_4Label = uilabel(app.GridLayout2_15);
            app.tEditField_4Label.Text = 't';
            app.tEditField_2 = uieditfield(app.GridLayout2_15, 'numeric');
            app.tEditField_2.ValueDisplayFormat = '%11.3f';
            app.tEditField_2.Value = 35;
            app.Label_47 = uilabel(app.GridLayout2_15);
            app.Label_47.Text = 'Ω';
            app.Z0EditField_10Label_2 = uilabel(app.GridLayout2_15);
            app.Z0EditField_10Label_2.Text = 'Z0';
            app.Z0EditField_2 = uieditfield(app.GridLayout2_15, 'numeric');
            app.Z0EditField_2.ValueDisplayFormat = '%11.3f';
            app.Z0EditField_2.Editable = 'off';
            app.Z0EditField_2.Value = 50;
            app.AutresparamtresPanel = uipanel(app.CalculTab_6);
            app.AutresparamtresPanel.Title = 'Autres paramètres';
            app.GridLayout3_14 = uigridlayout(app.AutresparamtresPanel);
            app.mmLabel_115 = uilabel(app.GridLayout3_14);
            app.mmLabel_115.Text = 'mm';
            app.mmLabel_116 = uilabel(app.GridLayout3_14);
            app.mmLabel_116.Text = 'mm';
            app.mmLabel_117 = uilabel(app.GridLayout3_14);
            app.mmLabel_117.Text = 'mm';
            app.mmLabel_118 = uilabel(app.GridLayout3_14);
            app.mmLabel_118.Text = 'mm';
            app.mmLabel_119 = uilabel(app.GridLayout3_14);
            app.mmLabel_119.Text = 'mm';
            app.FiEditField_10Label_2 = uilabel(app.GridLayout3_14);
            app.FiEditField_10Label_2.Text = 'Fi';
            app.FiEditField_2 = uieditfield(app.GridLayout3_14, 'numeric');
            app.FiEditField_2.ValueDisplayFormat = '%11.3f';
            app.FiEditField_2.Editable = 'off';
            app.WfEditField_10Label_2 = uilabel(app.GridLayout3_14);
            app.WfEditField_10Label_2.Text = 'Wf';
            app.WfEditField_2 = uieditfield(app.GridLayout3_14, 'numeric');
            app.WfEditField_2.ValueDisplayFormat = '%11.3f';
            app.WfEditField_2.Editable = 'off';
            app.GpfEditField_10Label_2 = uilabel(app.GridLayout3_14);
            app.GpfEditField_10Label_2.Text = 'Gpf';
            app.GpfEditField_2 = uieditfield(app.GridLayout3_14, 'numeric');
            app.GpfEditField_2.ValueDisplayFormat = '%11.3f';
            app.GpfEditField_2.Editable = 'off';
            app.LgEditField_10Label_2 = uilabel(app.GridLayout3_14);
            app.LgEditField_10Label_2.Text = 'Lg';
            app.LgEditField_2 = uieditfield(app.GridLayout3_14, 'numeric');
            app.LgEditField_2.ValueDisplayFormat = '%11.3f';
            app.LgEditField_2.Editable = 'off';
            app.WgEditField_10Label_2 = uilabel(app.GridLayout3_14);
            app.WgEditField_10Label_2.Text = 'Wg';
            app.WgEditField_2 = uieditfield(app.GridLayout3_14, 'numeric');
            app.WgEditField_2.ValueDisplayFormat = '%11.3f';
            app.WgEditField_2.Editable = 'off';
            app.E3S3Tab = uitab(app.TabGroup);
            app.E3S3Tab.Title = 'E3->S3';
            app.TabGroup2_6 = uitabgroup(app.E3S3Tab);
            app.EntrainementTab_7 = uitab(app.TabGroup2_6);
            app.EntrainementTab_7.Title = 'Entrainement';
            app.UIAxes_3 = uiaxes(app.EntrainementTab_7);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'Valeur de la base de donnée')
            ylabel(app.UIAxes_3, 'Valeur calculée')
            app.UIAxes_3.LineStyleOrder = {'-'};
            app.ParamtresPanel_3 = uipanel(app.EntrainementTab_7);
            app.ParamtresPanel_3.Title = 'Paramètres';
            app.GridLayout10_3 = uigridlayout(app.ParamtresPanel_3);
            app.PourcentagevalidationEditField_3Label_2 = uilabel(app.GridLayout10_3);
            app.PourcentagevalidationEditField_3Label_2.Text = 'Pourcentage validation';
            app.PourcentagevalidationEditField_3 = uieditfield(app.GridLayout10_3, 'numeric');
            app.PourcentagevalidationEditField_3.Value = 25;
            app.PourcentagetestEditField_3Label_2 = uilabel(app.GridLayout10_3);
            app.PourcentagetestEditField_3Label_2.Text = 'Pourcentage test';
            app.PourcentagetestEditField_3 = uieditfield(app.GridLayout10_3, 'numeric');
            app.PourcentagetestEditField_3.Value = 5;
            app.PourcentageEntrainementEditField_3Label = uilabel(app.GridLayout10_3);
            app.PourcentageEntrainementEditField_3Label.Text = 'Pourcentage Entrainement';
            app.PourcentageEntrainementEditField_3 = uieditfield(app.GridLayout10_3, 'numeric');
            app.PourcentageEntrainementEditField_3.Value = 70;
            app.EpochsEditField_3Label_2 = uilabel(app.GridLayout10_3);
            app.EpochsEditField_3Label_2.Text = 'Epochs';
            app.EpochsEditField_3 = uieditfield(app.GridLayout10_3, 'numeric');
            app.EpochsEditField_3.Value = 100;
            app.CouchesneuronnesEditFieldLabel = uilabel(app.GridLayout10_3);
            app.CouchesneuronnesEditFieldLabel.Text = 'Couches & neuronnes';
            app.CouchesneuronnesEditField_3 = uieditfield(app.GridLayout10_3, 'text');
            app.CouchesneuronnesEditField_3.ValueChangedFcn = createCallbackFcn(app, @CouchesneuronnesEditField_ValueChanged, true);
            app.CouchesneuronnesEditField_3.Value = '[13]';
            app.CouchesFormatLabel_3 = uilabel(app.GridLayout10_3);
            app.CouchesFormatLabel_3.Text = 'Format : [nbNeuronnes,nbNeuronnes,....,nbNeuronnes]';
            app.OptionsPanel_3 = uipanel(app.EntrainementTab_7);
            app.OptionsPanel_3.Title = 'Options';
            app.GridLayout11_3 = uigridlayout(app.OptionsPanel_3);
            app.SlectionnerlabasededonneButton_3 = uibutton(app.GridLayout11_3, 'push');
            app.SlectionnerlabasededonneButton_3.ButtonPushedFcn = createCallbackFcn(app, @SlectionnerlabasededonneButton_Pushed, true);
            app.SlectionnerlabasededonneButton_3.Text = 'Sélectionner la base de donnée';
            app.EnregistrerlerseauButton_3 = uibutton(app.GridLayout11_3, 'push');
            app.EnregistrerlerseauButton_3.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerlerseauButton_Pushed, true);
            app.EnregistrerlerseauButton_3.Enable = 'off';
            app.EnregistrerlerseauButton_3.Text = 'Enregistrer le réseau';
            app.EntranerlerseauButton_3 = uibutton(app.GridLayout11_3, 'push');
            app.EntranerlerseauButton_3.ButtonPushedFcn = createCallbackFcn(app, @EntranerlerseauButton_Pushed, true);
            app.EntranerlerseauButton_3.Text = 'Entraîner le réseau';
            app.RechercheCouchenbNeuronnesoptimauxButton_3 = uibutton(app.GridLayout11_3, 'push');
            app.RechercheCouchenbNeuronnesoptimauxButton_3.ButtonPushedFcn = createCallbackFcn(app, @RechercheCouchenbNeuronnesoptimauxButton_Pushed, true);
            app.RechercheCouchenbNeuronnesoptimauxButton_3.Text = {'Recherche Couche & nbNeuronnes'; 'optimaux'};
            app.ErreurmoyenneEditField_4Label = uilabel(app.EntrainementTab_7);
            app.ErreurmoyenneEditField_4Label.Text = 'Erreur moyenne';
            app.ErreurmoyenneEditField_3 = uieditfield(app.EntrainementTab_7, 'text');
            app.ErreurmoyenneEditField_3.Editable = 'off';
            app.ErreurmoyenneEditField_3.Value = '0';
            app.nomBdLabel_3 = uilabel(app.EntrainementTab_7);
            app.nomBdLabel_3.Text = 'bd.mat';
            app.BDdentranementLabel_3 = uilabel(app.EntrainementTab_7);
            app.BDdentranementLabel_3.Text = 'BD d''entraînement';
            app.FichierdesauvegardeLabel_3 = uilabel(app.EntrainementTab_7);
            app.FichierdesauvegardeLabel_3.Text = 'Fichier de sauvegarde';
            app.ChemSauvLabel_3 = uilabel(app.EntrainementTab_7);
            app.ChemSauvLabel_3.Text = '';
            app.CommandesPanel_3 = uipanel(app.EntrainementTab_7);
            app.CommandesPanel_3.Title = 'Commandes';
            app.GridLayout12_3 = uigridlayout(app.CommandesPanel_3);
            app.HButton_3 = uibutton(app.GridLayout12_3, 'push');
            app.HButton_3.ButtonPushedFcn = createCallbackFcn(app, @HButton_Pushed, true);
            app.HButton_3.Text = 'H';
            app.Label_30 = uilabel(app.GridLayout12_3);
            app.Label_30.Text = '';
            app.ErreurLabel_3 = uilabel(app.EntrainementTab_7);
            app.ErreurLabel_3.Visible = 'off';
            app.ErreurLabel_3.Text = 'Erreur';
            app.ControlPlotButtonGroup_3 = uibuttongroup(app.EntrainementTab_7);
            app.ControlPlotButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ControlPlotButtonGroup_3SelectionChanged, true);
            app.ControlPlotButtonGroup_3.Title = 'Control Plot';
            app.PlotPerformanceButton_3 = uitogglebutton(app.ControlPlotButtonGroup_3);
            app.PlotPerformanceButton_3.Text = 'Plot Performance';
            app.PlotPerformanceButton_3.Value = true;
            app.PlotTrainingButton_3 = uitogglebutton(app.ControlPlotButtonGroup_3);
            app.PlotTrainingButton_3.Text = 'Plot Training';
            app.PlotGeneralPerformanceButton_3 = uitogglebutton(app.ControlPlotButtonGroup_3);
            app.PlotGeneralPerformanceButton_3.Enable = 'off';
            app.PlotGeneralPerformanceButton_3.Text = 'Plot General Performance';
            app.CouchesoptimalesLabel_3 = uilabel(app.EntrainementTab_7);
            app.CouchesoptimalesLabel_3.Text = 'Couches optimales';
            app.OptiLabel1_3 = uilabel(app.EntrainementTab_7);
            app.OptiLabel1_3.Text = '';
            app.ValeurspardfautButton_3 = uibutton(app.EntrainementTab_7, 'push');
            app.ValeurspardfautButton_3.ButtonPushedFcn = createCallbackFcn(app, @ValeurspardfautButton_Pushed, true);
            app.ValeurspardfautButton_3.Text = 'Valeurs par défaut';
            app.CalculTab_3 = uitab(app.TabGroup2_6);
            app.CalculTab_3.Title = 'Calcul';
            app.UIAxesSij3 = uiaxes(app.CalculTab_3);
            title(app.UIAxesSij3, 'patch antenna at 2.45GHz')
            xlabel(app.UIAxesSij3, 'Frequency (GHz)')
            ylabel(app.UIAxesSij3, 'S11 (dB)')
            app.EntresPanel_11 = uipanel(app.CalculTab_3);
            app.EntresPanel_11.Title = 'Entrées';
            app.GridLayout2_11 = uigridlayout(app.EntresPanel_11);
            app.Label_32 = uilabel(app.GridLayout2_11);
            app.Label_32.Text = '';
            app.GHzLabel = uilabel(app.GridLayout2_11);
            app.GHzLabel.Text = 'GHz';
            app.mmLabel_95 = uilabel(app.GridLayout2_11);
            app.mmLabel_95.Text = 'mm';
            app.ErEditField_14Label = uilabel(app.GridLayout2_11);
            app.ErEditField_14Label.Text = 'Er';
            app.ErEditField_3 = uieditfield(app.GridLayout2_11, 'numeric');
            app.ErEditField_3.ValueDisplayFormat = '%11.3f';
            app.ErEditField_3.ValueChangedFcn = createCallbackFcn(app, @ErEditField_3ValueChanged, true);
            app.ErEditField_3.Value = 4.3;
            app.FrEditField_14Label = uilabel(app.GridLayout2_11);
            app.FrEditField_14Label.Text = 'Fr';
            app.FrEditField_3 = uieditfield(app.GridLayout2_11, 'numeric');
            app.FrEditField_3.ValueDisplayFormat = '%11.3f';
            app.FrEditField_3.ValueChangedFcn = createCallbackFcn(app, @FrEditField_3ValueChanged, true);
            app.FrEditField_3.Value = 2.45;
            app.WpEditField_13Label = uilabel(app.GridLayout2_11);
            app.WpEditField_13Label.Text = 'Wp';
            app.WpEditField_3 = uieditfield(app.GridLayout2_11, 'numeric');
            app.WpEditField_3.ValueDisplayFormat = '%11.3f';
            app.WpEditField_3.ValueChangedFcn = createCallbackFcn(app, @WpEditField_3ValueChanged, true);
            app.WpEditField_3.Value = 37.61;
            app.LpEditField_13Label = uilabel(app.GridLayout2_11);
            app.LpEditField_13Label.Text = 'Lp';
            app.LpEditField_3 = uieditfield(app.GridLayout2_11, 'numeric');
            app.LpEditField_3.ValueDisplayFormat = '%11.3f';
            app.LpEditField_3.ValueChangedFcn = createCallbackFcn(app, @LpEditField_3ValueChanged, true);
            app.LpEditField_3.Value = 29.159;
            app.mmLabel_94 = uilabel(app.GridLayout2_11);
            app.mmLabel_94.Text = 'mm';
            app.SortiesPanel_11 = uipanel(app.CalculTab_3);
            app.SortiesPanel_11.Title = 'Sorties';
            app.GridLayout3_11 = uigridlayout(app.SortiesPanel_11);
            app.mmLabel_88 = uilabel(app.GridLayout3_11);
            app.mmLabel_88.Text = 'mm';
            app.Label_29 = uilabel(app.GridLayout3_11);
            app.Label_29.Text = '';
            app.HEditField_13Label = uilabel(app.GridLayout3_11);
            app.HEditField_13Label.Text = 'H';
            app.HEditField_3 = uieditfield(app.GridLayout3_11, 'numeric');
            app.HEditField_3.ValueDisplayFormat = '%11.3f';
            app.HEditField_3.ValueChangedFcn = createCallbackFcn(app, @HEditField_3ValueChanged, true);
            app.HEditField_3.Editable = 'off';
            app.Err_moyEditFieldLabel_3 = uilabel(app.GridLayout3_11);
            app.Err_moyEditFieldLabel_3.Text = 'Err_moy';
            app.ErrmoyEditField_3 = uieditfield(app.GridLayout3_11, 'numeric');
            app.ErrmoyEditField_3.ValueDisplayFormat = '%11.e';
            app.ErrmoyEditField_3.Editable = 'off';
            app.EffacerlaligneButton_3 = uibutton(app.CalculTab_3, 'push');
            app.EffacerlaligneButton_3.ButtonPushedFcn = createCallbackFcn(app, @EffacerlaligneButton_Pushed, true);
            app.EffacerlaligneButton_3.Enable = 'off';
            app.EffacerlaligneButton_3.Text = 'Effacer la ligne';
            app.ToutEffacerButton_3 = uibutton(app.CalculTab_3, 'push');
            app.ToutEffacerButton_3.ButtonPushedFcn = createCallbackFcn(app, @ToutEffacerButton_Pushed, true);
            app.ToutEffacerButton_3.Enable = 'off';
            app.ToutEffacerButton_3.Text = 'Tout Effacer';
            app.TransfrerversCSTButton_3 = uibutton(app.CalculTab_3, 'push');
            app.TransfrerversCSTButton_3.ButtonPushedFcn = createCallbackFcn(app, @TransfrerversCSTButton_3Pushed, true);
            app.TransfrerversCSTButton_3.Enable = 'off';
            app.TransfrerversCSTButton_3.Text = 'Transférer vers CST';
            app.CalculButton_3 = uibutton(app.CalculTab_3, 'push');
            app.CalculButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculButton_Pushed, true);
            app.CalculButton_3.Text = 'Calcul';
            app.UITable_3 = uitable(app.CalculTab_3);
            app.UITable_3.ColumnName = {'N°'; 'H'; 'Er'; 'Fr'; 'Z0'; 'Wp'; 'Lp'; 'Fi'; 'Wf'; 'Gpf'; 'Lg'; 'Wg'; 'Err. moy.'};
            app.UITable_3.RowName = {};
            app.UITable_3.CellSelectionCallback = createCallbackFcn(app, @UITable_CellSelection, true);
            app.DfautButton_3 = uibutton(app.CalculTab_3, 'push');
            app.DfautButton_3.ButtonPushedFcn = createCallbackFcn(app, @DfautButton_Pushed, true);
            app.DfautButton_3.Text = 'Défaut';
            app.RNNInputErFrWpLpOutputHPanel = uipanel(app.CalculTab_3);
            app.RNNInputErFrWpLpOutputHPanel.Title = 'RNN : Input ( Er,Fr,Wp,Lp ) --- Output ( H )';
            app.ReseaudeneuronneButton_3 = uibutton(app.RNNInputErFrWpLpOutputHPanel, 'push');
            app.ReseaudeneuronneButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReseaudeneuronneButton_Pushed, true);
            app.ReseaudeneuronneButton_3.Text = 'Reseau de neuronne';
            app.neuron_label_3 = uilabel(app.RNNInputErFrWpLpOutputHPanel);
            app.neuron_label_3.Text = 'RNN3_09_1000.mat';
            app.BasedeDonneeButton_3 = uibutton(app.RNNInputErFrWpLpOutputHPanel, 'push');
            app.BasedeDonneeButton_3.ButtonPushedFcn = createCallbackFcn(app, @BasedeDonneeButton_Pushed, true);
            app.BasedeDonneeButton_3.Text = 'Base de Donnee';
            app.bd_label_3 = uilabel(app.RNNInputErFrWpLpOutputHPanel);
            app.bd_label_3.Text = 'bd.mat';
            app.Image_6 = uiimage(app.CalculTab_3);
            app.Image_6.ImageSource = 'antena_indic.jpg';
            app.DataPanel = uipanel(app.CalculTab_3);
            app.DataPanel.Title = 'Data';
            app.GridLayout2_14 = uigridlayout(app.DataPanel);
            app.GHzLabel_3 = uilabel(app.GridLayout2_14);
            app.GHzLabel_3.Text = 'GHz';
            app.GHzLabel_2 = uilabel(app.GridLayout2_14);
            app.GHzLabel_2.Text = 'GHz';
            app.mLabel = uilabel(app.GridLayout2_14);
            app.mLabel.Text = 'μm';
            app.FminEditFieldLabel = uilabel(app.GridLayout2_14);
            app.FminEditFieldLabel.Text = 'Fmin';
            app.FminEditField_3 = uieditfield(app.GridLayout2_14, 'numeric');
            app.FminEditField_3.ValueDisplayFormat = '%11.3f';
            app.FminEditField_3.Value = 1;
            app.FmaxEditFieldLabel = uilabel(app.GridLayout2_14);
            app.FmaxEditFieldLabel.Text = 'Fmax';
            app.FmaxEditField_3 = uieditfield(app.GridLayout2_14, 'numeric');
            app.FmaxEditField_3.ValueDisplayFormat = '%11.3f';
            app.FmaxEditField_3.Value = 3;
            app.tEditFieldLabel = uilabel(app.GridLayout2_14);
            app.tEditFieldLabel.Text = 't';
            app.tEditField_3 = uieditfield(app.GridLayout2_14, 'numeric');
            app.tEditField_3.ValueDisplayFormat = '%11.3f';
            app.tEditField_3.Value = 35;
            app.Z0EditField_13Label = uilabel(app.GridLayout2_14);
            app.Z0EditField_13Label.Text = 'Z0';
            app.Z0EditField_3 = uieditfield(app.GridLayout2_14, 'numeric');
            app.Z0EditField_3.ValueDisplayFormat = '%11.2f';
            app.Z0EditField_3.ValueChangedFcn = createCallbackFcn(app, @Z0EditField_3ValueChanged, true);
            app.Z0EditField_3.Editable = 'off';
            app.Z0EditField_3.Value = 50;
            app.Label_46 = uilabel(app.GridLayout2_14);
            app.Label_46.Text = 'Ω';
            app.AutresparamtresPanel_2 = uipanel(app.CalculTab_3);
            app.AutresparamtresPanel_2.Title = 'Autres paramètres';
            app.GridLayout3_15 = uigridlayout(app.AutresparamtresPanel_2);
            app.mmLabel_120 = uilabel(app.GridLayout3_15);
            app.mmLabel_120.Text = 'mm';
            app.mmLabel_121 = uilabel(app.GridLayout3_15);
            app.mmLabel_121.Text = 'mm';
            app.mmLabel_122 = uilabel(app.GridLayout3_15);
            app.mmLabel_122.Text = 'mm';
            app.mmLabel_123 = uilabel(app.GridLayout3_15);
            app.mmLabel_123.Text = 'mm';
            app.mmLabel_124 = uilabel(app.GridLayout3_15);
            app.mmLabel_124.Text = 'mm';
            app.FiEditField_13Label = uilabel(app.GridLayout3_15);
            app.FiEditField_13Label.Text = 'Fi';
            app.FiEditField_3 = uieditfield(app.GridLayout3_15, 'numeric');
            app.FiEditField_3.ValueDisplayFormat = '%11.3f';
            app.FiEditField_3.Editable = 'off';
            app.WfEditField_13Label = uilabel(app.GridLayout3_15);
            app.WfEditField_13Label.Text = 'Wf';
            app.WfEditField_3 = uieditfield(app.GridLayout3_15, 'numeric');
            app.WfEditField_3.ValueDisplayFormat = '%11.3f';
            app.WfEditField_3.Editable = 'off';
            app.GpfEditField_13Label = uilabel(app.GridLayout3_15);
            app.GpfEditField_13Label.Text = 'Gpf';
            app.GpfEditField_3 = uieditfield(app.GridLayout3_15, 'numeric');
            app.GpfEditField_3.ValueDisplayFormat = '%11.3f';
            app.GpfEditField_3.Editable = 'off';
            app.LgEditField_13Label = uilabel(app.GridLayout3_15);
            app.LgEditField_13Label.Text = 'Lg';
            app.LgEditField_3 = uieditfield(app.GridLayout3_15, 'numeric');
            app.LgEditField_3.ValueDisplayFormat = '%11.3f';
            app.LgEditField_3.Editable = 'off';
            app.WgEditField_13Label = uilabel(app.GridLayout3_15);
            app.WgEditField_13Label.Text = 'Wg';
            app.WgEditField_3 = uieditfield(app.GridLayout3_15, 'numeric');
            app.WgEditField_3.ValueDisplayFormat = '%11.3f';
            app.WgEditField_3.Editable = 'off';
            app.E4S4Tab = uitab(app.TabGroup);
            app.E4S4Tab.Title = 'E4->S4';
            app.TabGroup2_7 = uitabgroup(app.E4S4Tab);
            app.EntrainementTab_8 = uitab(app.TabGroup2_7);
            app.EntrainementTab_8.Title = 'Entrainement';
            app.UIAxes_4 = uiaxes(app.EntrainementTab_8);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'Valeur de la base de donnée')
            ylabel(app.UIAxes_4, 'Valeur calculée')
            app.UIAxes_4.LineStyleOrder = {'-'};
            app.ParamtresPanel_4 = uipanel(app.EntrainementTab_8);
            app.ParamtresPanel_4.Title = 'Paramètres';
            app.GridLayout10_4 = uigridlayout(app.ParamtresPanel_4);
            app.PourcentagevalidationEditField_4Label = uilabel(app.GridLayout10_4);
            app.PourcentagevalidationEditField_4Label.Text = 'Pourcentage validation';
            app.PourcentagevalidationEditField_4 = uieditfield(app.GridLayout10_4, 'numeric');
            app.PourcentagevalidationEditField_4.Value = 25;
            app.PourcentagetestEditField_4Label = uilabel(app.GridLayout10_4);
            app.PourcentagetestEditField_4Label.Text = 'Pourcentage test';
            app.PourcentagetestEditField_4 = uieditfield(app.GridLayout10_4, 'numeric');
            app.PourcentagetestEditField_4.Value = 5;
            app.PourcentageEntrainementEditField_4Label = uilabel(app.GridLayout10_4);
            app.PourcentageEntrainementEditField_4Label.Text = 'Pourcentage Entrainement';
            app.PourcentageEntrainementEditField_4 = uieditfield(app.GridLayout10_4, 'numeric');
            app.PourcentageEntrainementEditField_4.Value = 70;
            app.EpochsEditField_4Label = uilabel(app.GridLayout10_4);
            app.EpochsEditField_4Label.Text = 'Epochs';
            app.EpochsEditField_4 = uieditfield(app.GridLayout10_4, 'numeric');
            app.EpochsEditField_4.Value = 100;
            app.CouchesneuronnesEditField_2Label = uilabel(app.GridLayout10_4);
            app.CouchesneuronnesEditField_2Label.Text = 'Couches & neuronnes';
            app.CouchesneuronnesEditField_4 = uieditfield(app.GridLayout10_4, 'text');
            app.CouchesneuronnesEditField_4.ValueChangedFcn = createCallbackFcn(app, @CouchesneuronnesEditField_ValueChanged, true);
            app.CouchesneuronnesEditField_4.Value = '[13]';
            app.CouchesFormatLabel_4 = uilabel(app.GridLayout10_4);
            app.CouchesFormatLabel_4.Text = 'Format : [nbNeuronnes,nbNeuronnes,....,nbNeuronnes]';
            app.OptionsPanel_4 = uipanel(app.EntrainementTab_8);
            app.OptionsPanel_4.Title = 'Options';
            app.GridLayout11_4 = uigridlayout(app.OptionsPanel_4);
            app.SlectionnerlabasededonneButton_4 = uibutton(app.GridLayout11_4, 'push');
            app.SlectionnerlabasededonneButton_4.ButtonPushedFcn = createCallbackFcn(app, @SlectionnerlabasededonneButton_Pushed, true);
            app.SlectionnerlabasededonneButton_4.Text = 'Sélectionner la base de donnée';
            app.EnregistrerlerseauButton_4 = uibutton(app.GridLayout11_4, 'push');
            app.EnregistrerlerseauButton_4.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerlerseauButton_Pushed, true);
            app.EnregistrerlerseauButton_4.Enable = 'off';
            app.EnregistrerlerseauButton_4.Text = 'Enregistrer le réseau';
            app.EntranerlerseauButton_4 = uibutton(app.GridLayout11_4, 'push');
            app.EntranerlerseauButton_4.ButtonPushedFcn = createCallbackFcn(app, @EntranerlerseauButton_Pushed, true);
            app.EntranerlerseauButton_4.Text = 'Entraîner le réseau';
            app.RechercheCouchenbNeuronnesoptimauxButton_4 = uibutton(app.GridLayout11_4, 'push');
            app.RechercheCouchenbNeuronnesoptimauxButton_4.ButtonPushedFcn = createCallbackFcn(app, @RechercheCouchenbNeuronnesoptimauxButton_Pushed, true);
            app.RechercheCouchenbNeuronnesoptimauxButton_4.Text = {'Recherche Couche & nbNeuronnes'; 'optimaux'};
            app.ErreurmoyenneEditField_4Label_2 = uilabel(app.EntrainementTab_8);
            app.ErreurmoyenneEditField_4Label_2.Text = 'Erreur moyenne';
            app.ErreurmoyenneEditField_4 = uieditfield(app.EntrainementTab_8, 'text');
            app.ErreurmoyenneEditField_4.Editable = 'off';
            app.ErreurmoyenneEditField_4.Value = '0';
            app.nomBdLabel_4 = uilabel(app.EntrainementTab_8);
            app.nomBdLabel_4.Text = 'bd.mat';
            app.BDdentranementLabel_4 = uilabel(app.EntrainementTab_8);
            app.BDdentranementLabel_4.Text = 'BD d''entraînement';
            app.FichierdesauvegardeLabel_4 = uilabel(app.EntrainementTab_8);
            app.FichierdesauvegardeLabel_4.Text = 'Fichier de sauvegarde';
            app.ChemSauvLabel_4 = uilabel(app.EntrainementTab_8);
            app.ChemSauvLabel_4.Text = '';
            app.ErreurLabel_4 = uilabel(app.EntrainementTab_8);
            app.ErreurLabel_4.Visible = 'off';
            app.ErreurLabel_4.Text = 'Erreur';
            app.ControlPlotButtonGroup_4 = uibuttongroup(app.EntrainementTab_8);
            app.ControlPlotButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @ControlPlotButtonGroup_4SelectionChanged, true);
            app.ControlPlotButtonGroup_4.Title = 'Control Plot';
            app.PlotPerformanceButton_4 = uitogglebutton(app.ControlPlotButtonGroup_4);
            app.PlotPerformanceButton_4.Text = 'Plot Performance';
            app.PlotPerformanceButton_4.Value = true;
            app.PlotTrainingButton_4 = uitogglebutton(app.ControlPlotButtonGroup_4);
            app.PlotTrainingButton_4.Text = 'Plot Training';
            app.PlotGeneralPerformanceButton_4 = uitogglebutton(app.ControlPlotButtonGroup_4);
            app.PlotGeneralPerformanceButton_4.Text = 'Plot General Performance';
            app.CouchesoptimalesLabel_4 = uilabel(app.EntrainementTab_8);
            app.CouchesoptimalesLabel_4.Text = 'Couches optimales';
            app.OptiLabel1_4 = uilabel(app.EntrainementTab_8);
            app.OptiLabel1_4.Text = '';
            app.ValeurspardfautButton_4 = uibutton(app.EntrainementTab_8, 'push');
            app.ValeurspardfautButton_4.ButtonPushedFcn = createCallbackFcn(app, @ValeurspardfautButton_Pushed, true);
            app.ValeurspardfautButton_4.Text = 'Valeurs par défaut';
            app.CommandesPanel_5 = uipanel(app.EntrainementTab_8);
            app.CommandesPanel_5.Title = 'Commandes';
            app.GridLayout12_5 = uigridlayout(app.CommandesPanel_5);
            app.HButton_4 = uibutton(app.GridLayout12_5, 'push');
            app.HButton_4.ButtonPushedFcn = createCallbackFcn(app, @HButton_Pushed, true);
            app.HButton_4.Text = 'H';
            app.WpButton_4 = uibutton(app.GridLayout12_5, 'push');
            app.WpButton_4.ButtonPushedFcn = createCallbackFcn(app, @WpButton_Pushed, true);
            app.WpButton_4.Text = 'Wp';
            app.Label_41 = uilabel(app.GridLayout12_5);
            app.Label_41.Text = '';
            app.Label_42 = uilabel(app.GridLayout12_5);
            app.Label_42.Text = '';
            app.LpButton_4 = uibutton(app.GridLayout12_5, 'push');
            app.LpButton_4.ButtonPushedFcn = createCallbackFcn(app, @LpButton_Pushed, true);
            app.LpButton_4.Text = 'Lp';
            app.Label_44 = uilabel(app.GridLayout12_5);
            app.Label_44.Text = '';
            app.CalculTab_7 = uitab(app.TabGroup2_7);
            app.CalculTab_7.Title = 'Calcul';
            app.UIAxesSij4 = uiaxes(app.CalculTab_7);
            title(app.UIAxesSij4, 'patch antenna at 2.45GHz')
            xlabel(app.UIAxesSij4, 'Frequency (GHz)')
            ylabel(app.UIAxesSij4, 'S11 (dB)')
            app.EntresPanel_12 = uipanel(app.CalculTab_7);
            app.EntresPanel_12.Title = 'Entrées';
            app.GridLayout2_12 = uigridlayout(app.EntresPanel_12);
            app.Label_34 = uilabel(app.GridLayout2_12);
            app.Label_34.Text = '';
            app.GHzLabel_26 = uilabel(app.GridLayout2_12);
            app.GHzLabel_26.Text = 'GHz';
            app.ErEditField_15Label = uilabel(app.GridLayout2_12);
            app.ErEditField_15Label.Text = 'Er';
            app.ErEditField_4 = uieditfield(app.GridLayout2_12, 'numeric');
            app.ErEditField_4.ValueDisplayFormat = '%11.3f';
            app.ErEditField_4.ValueChangedFcn = createCallbackFcn(app, @ErEditField_4ValueChanged, true);
            app.ErEditField_4.Value = 4.3;
            app.FrEditField_15Label = uilabel(app.GridLayout2_12);
            app.FrEditField_15Label.Text = 'Fr';
            app.FrEditField_4 = uieditfield(app.GridLayout2_12, 'numeric');
            app.FrEditField_4.ValueDisplayFormat = '%11.3f';
            app.FrEditField_4.ValueChangedFcn = createCallbackFcn(app, @FrEditField_4ValueChanged, true);
            app.FrEditField_4.Value = 2.45;
            app.SortiesPanel_12 = uipanel(app.CalculTab_7);
            app.SortiesPanel_12.Title = 'Sorties';
            app.GridLayout3_12 = uigridlayout(app.SortiesPanel_12);
            app.mmLabel_98 = uilabel(app.GridLayout3_12);
            app.mmLabel_98.Text = 'mm';
            app.HEditField_14Label = uilabel(app.GridLayout3_12);
            app.HEditField_14Label.Text = 'H';
            app.HEditField_4 = uieditfield(app.GridLayout3_12, 'numeric');
            app.HEditField_4.ValueDisplayFormat = '%11.3f';
            app.HEditField_4.ValueChangedFcn = createCallbackFcn(app, @HEditField_4ValueChanged, true);
            app.HEditField_4.Editable = 'off';
            app.Err_moyEditFieldLabel_4 = uilabel(app.GridLayout3_12);
            app.Err_moyEditFieldLabel_4.Text = 'Err_moy';
            app.ErrmoyEditField_4 = uieditfield(app.GridLayout3_12, 'numeric');
            app.ErrmoyEditField_4.ValueDisplayFormat = '%11.e';
            app.ErrmoyEditField_4.Editable = 'off';
            app.WpEditField_14Label = uilabel(app.GridLayout3_12);
            app.WpEditField_14Label.Text = 'Wp';
            app.WpEditField_4 = uieditfield(app.GridLayout3_12, 'numeric');
            app.WpEditField_4.ValueDisplayFormat = '%11.3f';
            app.LpEditField_14Label = uilabel(app.GridLayout3_12);
            app.LpEditField_14Label.Text = 'Lp';
            app.LpEditField_4 = uieditfield(app.GridLayout3_12, 'numeric');
            app.LpEditField_4.ValueDisplayFormat = '%11.3f';
            app.mmLabel_104 = uilabel(app.GridLayout3_12);
            app.mmLabel_104.Text = 'mm';
            app.mmLabel_105 = uilabel(app.GridLayout3_12);
            app.mmLabel_105.Text = 'mm';
            app.Image_7 = uiimage(app.CalculTab_7);
            app.Image_7.ImageSource = 'antena_indic.jpg';
            app.EffacerlaligneButton_4 = uibutton(app.CalculTab_7, 'push');
            app.EffacerlaligneButton_4.ButtonPushedFcn = createCallbackFcn(app, @EffacerlaligneButton_Pushed, true);
            app.EffacerlaligneButton_4.Enable = 'off';
            app.EffacerlaligneButton_4.Text = 'Effacer la ligne';
            app.ToutEffacerButton_4 = uibutton(app.CalculTab_7, 'push');
            app.ToutEffacerButton_4.ButtonPushedFcn = createCallbackFcn(app, @ToutEffacerButton_Pushed, true);
            app.ToutEffacerButton_4.Enable = 'off';
            app.ToutEffacerButton_4.Text = 'Tout Effacer';
            app.TransfrerversCSTButton_4 = uibutton(app.CalculTab_7, 'push');
            app.TransfrerversCSTButton_4.ButtonPushedFcn = createCallbackFcn(app, @TransfrerversCSTButton_4Pushed, true);
            app.TransfrerversCSTButton_4.Enable = 'off';
            app.TransfrerversCSTButton_4.Text = 'Transférer vers CST';
            app.CalculButton_4 = uibutton(app.CalculTab_7, 'push');
            app.CalculButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculButton_Pushed, true);
            app.CalculButton_4.Text = 'Calcul';
            app.UITable_4 = uitable(app.CalculTab_7);
            app.UITable_4.ColumnName = {'N°'; 'H'; 'Er'; 'Fr'; 'Z0'; 'Wp'; 'Lp'; 'Fi'; 'Wf'; 'Gpf'; 'Lg'; 'Wg'; 'Err. moy.'};
            app.UITable_4.RowName = {};
            app.UITable_4.CellSelectionCallback = createCallbackFcn(app, @UITable_CellSelection, true);
            app.DfautButton_4 = uibutton(app.CalculTab_7, 'push');
            app.DfautButton_4.ButtonPushedFcn = createCallbackFcn(app, @DfautButton_Pushed, true);
            app.DfautButton_4.Text = 'Défaut';
            app.RNNInputErFrOutputHWpLpPanel = uipanel(app.CalculTab_7);
            app.RNNInputErFrOutputHWpLpPanel.Title = 'RNN : Input ( Er,Fr ) --- Output ( H,Wp,Lp )';
            app.ReseaudeneuronneButton_4 = uibutton(app.RNNInputErFrOutputHWpLpPanel, 'push');
            app.ReseaudeneuronneButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReseaudeneuronneButton_Pushed, true);
            app.ReseaudeneuronneButton_4.Text = 'Reseau de neuronne';
            app.neuron_label_4 = uilabel(app.RNNInputErFrOutputHWpLpPanel);
            app.neuron_label_4.Text = 'RNN4_19_1000.mat';
            app.BasedeDonneeButton_4 = uibutton(app.RNNInputErFrOutputHWpLpPanel, 'push');
            app.BasedeDonneeButton_4.ButtonPushedFcn = createCallbackFcn(app, @BasedeDonneeButton_Pushed, true);
            app.BasedeDonneeButton_4.Text = 'Base de Donnee';
            app.bd_label_4 = uilabel(app.RNNInputErFrOutputHWpLpPanel);
            app.bd_label_4.Text = 'bd.mat';
            app.DataPanel_4 = uipanel(app.CalculTab_7);
            app.DataPanel_4.Title = 'Data';
            app.GridLayout2_17 = uigridlayout(app.DataPanel_4);
            app.GHzLabel_8 = uilabel(app.GridLayout2_17);
            app.GHzLabel_8.Text = 'GHz';
            app.GHzLabel_9 = uilabel(app.GridLayout2_17);
            app.GHzLabel_9.Text = 'GHz';
            app.mLabel_4 = uilabel(app.GridLayout2_17);
            app.mLabel_4.Text = 'μm';
            app.FminEditField_4Label_2 = uilabel(app.GridLayout2_17);
            app.FminEditField_4Label_2.Text = 'Fmin';
            app.FminEditField_4 = uieditfield(app.GridLayout2_17, 'numeric');
            app.FminEditField_4.ValueDisplayFormat = '%11.3f';
            app.FminEditField_4.Value = 1;
            app.FmaxEditField_4Label_2 = uilabel(app.GridLayout2_17);
            app.FmaxEditField_4Label_2.Text = 'Fmax';
            app.FmaxEditField_4 = uieditfield(app.GridLayout2_17, 'numeric');
            app.FmaxEditField_4.ValueDisplayFormat = '%11.3f';
            app.FmaxEditField_4.Value = 3;
            app.tEditField_4Label_2 = uilabel(app.GridLayout2_17);
            app.tEditField_4Label_2.Text = 't';
            app.tEditField_4 = uieditfield(app.GridLayout2_17, 'numeric');
            app.tEditField_4.ValueDisplayFormat = '%11.3f';
            app.tEditField_4.Value = 35;
            app.Label_49 = uilabel(app.GridLayout2_17);
            app.Label_49.Text = 'Ω';
            app.Z0EditField_14Label = uilabel(app.GridLayout2_17);
            app.Z0EditField_14Label.Text = 'Z0';
            app.Z0EditField_14 = uieditfield(app.GridLayout2_17, 'numeric');
            app.Z0EditField_14.ValueDisplayFormat = '%11.2f';
            app.Z0EditField_14.Editable = 'off';
            app.Z0EditField_14.Value = 50;
            app.AutresparamtresPanel_3 = uipanel(app.CalculTab_7);
            app.AutresparamtresPanel_3.Title = 'Autres paramètres';
            app.GridLayout3_16 = uigridlayout(app.AutresparamtresPanel_3);
            app.mmLabel_125 = uilabel(app.GridLayout3_16);
            app.mmLabel_125.Text = 'mm';
            app.mmLabel_126 = uilabel(app.GridLayout3_16);
            app.mmLabel_126.Text = 'mm';
            app.mmLabel_127 = uilabel(app.GridLayout3_16);
            app.mmLabel_127.Text = 'mm';
            app.mmLabel_128 = uilabel(app.GridLayout3_16);
            app.mmLabel_128.Text = 'mm';
            app.mmLabel_129 = uilabel(app.GridLayout3_16);
            app.mmLabel_129.Text = 'mm';
            app.FiEditField_14Label = uilabel(app.GridLayout3_16);
            app.FiEditField_14Label.Text = 'Fi';
            app.FiEditField_4 = uieditfield(app.GridLayout3_16, 'numeric');
            app.FiEditField_4.ValueDisplayFormat = '%11.3f';
            app.FiEditField_4.ValueChangedFcn = createCallbackFcn(app, @FiEditField_4ValueChanged, true);
            app.FiEditField_4.Editable = 'off';
            app.WfEditField_14Label = uilabel(app.GridLayout3_16);
            app.WfEditField_14Label.Text = 'Wf';
            app.WfEditField_4 = uieditfield(app.GridLayout3_16, 'numeric');
            app.WfEditField_4.ValueDisplayFormat = '%11.3f';
            app.WfEditField_4.Editable = 'off';
            app.GpfEditField_14Label = uilabel(app.GridLayout3_16);
            app.GpfEditField_14Label.Text = 'Gpf';
            app.GpfEditField_4 = uieditfield(app.GridLayout3_16, 'numeric');
            app.GpfEditField_4.ValueDisplayFormat = '%11.3f';
            app.GpfEditField_4.Editable = 'off';
            app.LgEditField_14Label = uilabel(app.GridLayout3_16);
            app.LgEditField_14Label.Text = 'Lg';
            app.LgEditField_4 = uieditfield(app.GridLayout3_16, 'numeric');
            app.LgEditField_4.ValueDisplayFormat = '%11.3f';
            app.LgEditField_4.Editable = 'off';
            app.WgEditField_14Label = uilabel(app.GridLayout3_16);
            app.WgEditField_14Label.Text = 'Wg';
            app.WgEditField_4 = uieditfield(app.GridLayout3_16, 'numeric');
            app.WgEditField_4.ValueDisplayFormat = '%11.3f';
            app.WgEditField_4.Editable = 'off';
            app.E5S5Tab = uitab(app.TabGroup);
            app.E5S5Tab.Title = 'E5->S5';
            app.TabGroup2_8 = uitabgroup(app.E5S5Tab);
            app.EntrainementTab_9 = uitab(app.TabGroup2_8);
            app.EntrainementTab_9.Title = 'Entrainement';
            app.UIAxes_5 = uiaxes(app.EntrainementTab_9);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'Valeur de la base de donnée')
            ylabel(app.UIAxes_5, 'Valeur calculée')
            app.UIAxes_5.LineStyleOrder = {'-'};
            app.ParamtresPanel_5 = uipanel(app.EntrainementTab_9);
            app.ParamtresPanel_5.Title = 'Paramètres';
            app.GridLayout10_5 = uigridlayout(app.ParamtresPanel_5);
            app.PourcentagevalidationEditField_5Label = uilabel(app.GridLayout10_5);
            app.PourcentagevalidationEditField_5Label.Text = 'Pourcentage validation';
            app.PourcentagevalidationEditField_5 = uieditfield(app.GridLayout10_5, 'numeric');
            app.PourcentagevalidationEditField_5.Value = 25;
            app.PourcentagetestEditField_5Label = uilabel(app.GridLayout10_5);
            app.PourcentagetestEditField_5Label.Text = 'Pourcentage test';
            app.PourcentagetestEditField_5 = uieditfield(app.GridLayout10_5, 'numeric');
            app.PourcentagetestEditField_5.Value = 5;
            app.PourcentageEntrainementEditField_5Label = uilabel(app.GridLayout10_5);
            app.PourcentageEntrainementEditField_5Label.Text = 'Pourcentage Entrainement';
            app.PourcentageEntrainementEditField_5 = uieditfield(app.GridLayout10_5, 'numeric');
            app.PourcentageEntrainementEditField_5.Value = 70;
            app.EpochsEditField_5Label = uilabel(app.GridLayout10_5);
            app.EpochsEditField_5Label.Text = 'Epochs';
            app.EpochsEditField_5 = uieditfield(app.GridLayout10_5, 'numeric');
            app.EpochsEditField_5.Value = 100;
            app.CouchesneuronnesEditField_5Label = uilabel(app.GridLayout10_5);
            app.CouchesneuronnesEditField_5Label.Text = 'Couches & neuronnes';
            app.CouchesneuronnesEditField_5 = uieditfield(app.GridLayout10_5, 'text');
            app.CouchesneuronnesEditField_5.ValueChangedFcn = createCallbackFcn(app, @CouchesneuronnesEditField_ValueChanged, true);
            app.CouchesneuronnesEditField_5.Value = '[13]';
            app.CouchesFormatLabel_5 = uilabel(app.GridLayout10_5);
            app.CouchesFormatLabel_5.Text = 'Format : [nbNeuronnes,nbNeuronnes,....,nbNeuronnes]';
            app.OptionsPanel_5 = uipanel(app.EntrainementTab_9);
            app.OptionsPanel_5.Title = 'Options';
            app.GridLayout11_5 = uigridlayout(app.OptionsPanel_5);
            app.SlectionnerlabasededonneButton_5 = uibutton(app.GridLayout11_5, 'push');
            app.SlectionnerlabasededonneButton_5.ButtonPushedFcn = createCallbackFcn(app, @SlectionnerlabasededonneButton_Pushed, true);
            app.SlectionnerlabasededonneButton_5.Text = 'Sélectionner la base de donnée';
            app.EnregistrerlerseauButton_5 = uibutton(app.GridLayout11_5, 'push');
            app.EnregistrerlerseauButton_5.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerlerseauButton_Pushed, true);
            app.EnregistrerlerseauButton_5.Enable = 'off';
            app.EnregistrerlerseauButton_5.Text = 'Enregistrer le réseau';
            app.EntranerlerseauButton_5 = uibutton(app.GridLayout11_5, 'push');
            app.EntranerlerseauButton_5.ButtonPushedFcn = createCallbackFcn(app, @EntranerlerseauButton_Pushed, true);
            app.EntranerlerseauButton_5.Text = 'Entraîner le réseau';
            app.RechercheCouchenbNeuronnesoptimauxButton_5 = uibutton(app.GridLayout11_5, 'push');
            app.RechercheCouchenbNeuronnesoptimauxButton_5.ButtonPushedFcn = createCallbackFcn(app, @RechercheCouchenbNeuronnesoptimauxButton_Pushed, true);
            app.RechercheCouchenbNeuronnesoptimauxButton_5.Text = {'Recherche Couche & nbNeuronnes'; 'optimaux'};
            app.ErreurmoyenneEditField_5Label = uilabel(app.EntrainementTab_9);
            app.ErreurmoyenneEditField_5Label.Text = 'Erreur moyenne';
            app.ErreurmoyenneEditField_5 = uieditfield(app.EntrainementTab_9, 'text');
            app.ErreurmoyenneEditField_5.Editable = 'off';
            app.ErreurmoyenneEditField_5.Value = '0';
            app.nomBdLabel_5 = uilabel(app.EntrainementTab_9);
            app.nomBdLabel_5.Text = 'bd.mat';
            app.BDdentranementLabel_5 = uilabel(app.EntrainementTab_9);
            app.BDdentranementLabel_5.Text = 'BD d''entraînement';
            app.FichierdesauvegardeLabel_5 = uilabel(app.EntrainementTab_9);
            app.FichierdesauvegardeLabel_5.Text = 'Fichier de sauvegarde';
            app.ChemSauvLabel_5 = uilabel(app.EntrainementTab_9);
            app.ChemSauvLabel_5.Text = '';
            app.CommandesPanel_6 = uipanel(app.EntrainementTab_9);
            app.CommandesPanel_6.Title = 'Commandes';
            app.GridLayout12_6 = uigridlayout(app.CommandesPanel_6);
            app.ErButton_3 = uibutton(app.GridLayout12_6, 'push');
            app.ErButton_3.ButtonPushedFcn = createCallbackFcn(app, @ErButton_Pushed, true);
            app.ErButton_3.Text = 'Er';
            app.Label_51 = uilabel(app.GridLayout12_6);
            app.Label_51.Text = '';
            app.ErreurLabel_5 = uilabel(app.EntrainementTab_9);
            app.ErreurLabel_5.Visible = 'off';
            app.ErreurLabel_5.Text = 'Erreur';
            app.ControlPlotButtonGroup_5 = uibuttongroup(app.EntrainementTab_9);
            app.ControlPlotButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @ControlPlotButtonGroup_5SelectionChanged, true);
            app.ControlPlotButtonGroup_5.Title = 'Control Plot';
            app.PlotPerformanceButton_5 = uitogglebutton(app.ControlPlotButtonGroup_5);
            app.PlotPerformanceButton_5.Text = 'Plot Performance';
            app.PlotPerformanceButton_5.Value = true;
            app.PlotTrainingButton_5 = uitogglebutton(app.ControlPlotButtonGroup_5);
            app.PlotTrainingButton_5.Text = 'Plot Training';
            app.PlotGeneralPerformanceButton_5 = uitogglebutton(app.ControlPlotButtonGroup_5);
            app.PlotGeneralPerformanceButton_5.Enable = 'off';
            app.PlotGeneralPerformanceButton_5.Text = 'Plot General Performance';
            app.CouchesoptimalesLabel_5 = uilabel(app.EntrainementTab_9);
            app.CouchesoptimalesLabel_5.Text = 'Couches optimales';
            app.OptiLabel1_5 = uilabel(app.EntrainementTab_9);
            app.OptiLabel1_5.Text = '';
            app.ValeurspardfautButton_5 = uibutton(app.EntrainementTab_9, 'push');
            app.ValeurspardfautButton_5.ButtonPushedFcn = createCallbackFcn(app, @ValeurspardfautButton_Pushed, true);
            app.ValeurspardfautButton_5.Text = 'Valeurs par défaut';
            app.CalculTab_8 = uitab(app.TabGroup2_8);
            app.CalculTab_8.Title = 'Calcul';
            app.UIAxesSij5 = uiaxes(app.CalculTab_8);
            title(app.UIAxesSij5, 'patch antenna at 2.45GHz')
            xlabel(app.UIAxesSij5, 'Frequency (GHz)')
            ylabel(app.UIAxesSij5, 'S11 (dB)')
            app.EntresPanel_13 = uipanel(app.CalculTab_8);
            app.EntresPanel_13.Title = 'Entrées';
            app.GridLayout2_18 = uigridlayout(app.EntresPanel_13);
            app.Label_665 = uilabel(app.GridLayout2_18);
            app.Label_665.Text = '';
            app.GHzLabel_16 = uilabel(app.GridLayout2_18);
            app.GHzLabel_16.Text = 'GHz';
            app.mmLabel_149 = uilabel(app.GridLayout2_18);
            app.mmLabel_149.Text = 'mm';
            app.HEditField_7Label = uilabel(app.GridLayout2_18);
            app.HEditField_7Label.Text = 'H';
            app.HEditField_7 = uieditfield(app.GridLayout2_18, 'numeric');
            app.HEditField_7.ValueDisplayFormat = '%11.3f';
            app.HEditField_7.Value = 1.6;
            app.FrEditField_15Label_2 = uilabel(app.GridLayout2_18);
            app.FrEditField_15Label_2.Text = 'Fr';
            app.FrEditField_5 = uieditfield(app.GridLayout2_18, 'numeric');
            app.FrEditField_5.ValueDisplayFormat = '%11.3f';
            app.FrEditField_5.Value = 2.45;
            app.WpEditField_18Label = uilabel(app.GridLayout2_18);
            app.WpEditField_18Label.Text = 'Wp';
            app.WpEditField_5 = uieditfield(app.GridLayout2_18, 'numeric');
            app.WpEditField_5.ValueDisplayFormat = '%11.3f';
            app.WpEditField_5.Value = 37.61;
            app.LpEditField_18Label = uilabel(app.GridLayout2_18);
            app.LpEditField_18Label.Text = 'Lp';
            app.LpEditField_5 = uieditfield(app.GridLayout2_18, 'numeric');
            app.LpEditField_5.ValueDisplayFormat = '%11.3f';
            app.LpEditField_5.Value = 29.159;
            app.mmLabel_150 = uilabel(app.GridLayout2_18);
            app.mmLabel_150.Text = 'mm';
            app.SortiesPanel_13 = uipanel(app.CalculTab_8);
            app.SortiesPanel_13.Title = 'Sorties';
            app.GridLayout3_21 = uigridlayout(app.SortiesPanel_13);
            app.mmLabel_151 = uilabel(app.GridLayout3_21);
            app.mmLabel_151.Text = 'mm';
            app.Label_675 = uilabel(app.GridLayout3_21);
            app.Label_675.Text = '';
            app.ErEditField_7Label = uilabel(app.GridLayout3_21);
            app.ErEditField_7Label.Text = 'Er';
            app.ErEditField_7 = uieditfield(app.GridLayout3_21, 'numeric');
            app.ErEditField_7.ValueDisplayFormat = '%11.3f';
            app.ErEditField_7.Editable = 'off';
            app.Err_moyEditFieldLabel_8 = uilabel(app.GridLayout3_21);
            app.Err_moyEditFieldLabel_8.Text = 'Err_moy';
            app.ErrmoyEditField_5 = uieditfield(app.GridLayout3_21, 'numeric');
            app.ErrmoyEditField_5.ValueDisplayFormat = '%11.e';
            app.ErrmoyEditField_5.Editable = 'off';
            app.EffacerlaligneButton_5 = uibutton(app.CalculTab_8, 'push');
            app.EffacerlaligneButton_5.ButtonPushedFcn = createCallbackFcn(app, @EffacerlaligneButton_Pushed, true);
            app.EffacerlaligneButton_5.Enable = 'off';
            app.EffacerlaligneButton_5.Text = 'Effacer la ligne';
            app.ToutEffacerButton_5 = uibutton(app.CalculTab_8, 'push');
            app.ToutEffacerButton_5.ButtonPushedFcn = createCallbackFcn(app, @ToutEffacerButton_Pushed, true);
            app.ToutEffacerButton_5.Enable = 'off';
            app.ToutEffacerButton_5.Text = 'Tout Effacer';
            app.TransfrerversCSTButton_5 = uibutton(app.CalculTab_8, 'push');
            app.TransfrerversCSTButton_5.ButtonPushedFcn = createCallbackFcn(app, @TransfrerversCSTButton_5Pushed, true);
            app.TransfrerversCSTButton_5.Enable = 'off';
            app.TransfrerversCSTButton_5.Text = 'Transférer vers CST';
            app.CalculButton_8 = uibutton(app.CalculTab_8, 'push');
            app.CalculButton_8.ButtonPushedFcn = createCallbackFcn(app, @CalculButton_Pushed, true);
            app.CalculButton_8.Text = 'Calcul';
            app.UITable_5 = uitable(app.CalculTab_8);
            app.UITable_5.ColumnName = {'N°'; 'H'; 'Er'; 'Fr'; 'Z0'; 'Wp'; 'Lp'; 'Fi'; 'Wf'; 'Gpf'; 'Lg'; 'Wg'; 'Err. moy.'};
            app.UITable_5.RowName = {};
            app.UITable_5.CellSelectionCallback = createCallbackFcn(app, @UITable_CellSelection, true);
            app.DfautButton_5 = uibutton(app.CalculTab_8, 'push');
            app.DfautButton_5.ButtonPushedFcn = createCallbackFcn(app, @DfautButton_Pushed, true);
            app.DfautButton_5.Text = 'Défaut';
            app.RNNInputErFrWpLpOutputHPanel_2 = uipanel(app.CalculTab_8);
            app.RNNInputErFrWpLpOutputHPanel_2.Title = 'RNN : Input ( Er,Fr,Wp,Lp ) --- Output ( H )';
            app.ReseaudeneuronneButton_18 = uibutton(app.RNNInputErFrWpLpOutputHPanel_2, 'push');
            app.ReseaudeneuronneButton_18.ButtonPushedFcn = createCallbackFcn(app, @ReseaudeneuronneButton_Pushed, true);
            app.ReseaudeneuronneButton_18.Text = 'Reseau de neuronne';
            app.neuron_label_5 = uilabel(app.RNNInputErFrWpLpOutputHPanel_2);
            app.neuron_label_5.Text = 'RNN5_09_100.mat.mat';
            app.BasedeDonneeButton_18 = uibutton(app.RNNInputErFrWpLpOutputHPanel_2, 'push');
            app.BasedeDonneeButton_18.ButtonPushedFcn = createCallbackFcn(app, @BasedeDonneeButton_Pushed, true);
            app.BasedeDonneeButton_18.Text = 'Base de Donnee';
            app.bd_label_5 = uilabel(app.RNNInputErFrWpLpOutputHPanel_2);
            app.bd_label_5.Text = 'bd.mat';
            app.Image_8 = uiimage(app.CalculTab_8);
            app.Image_8.ImageSource = 'antena_indic.jpg';
            app.DataPanel_5 = uipanel(app.CalculTab_8);
            app.DataPanel_5.Title = 'Data';
            app.GridLayout2_19 = uigridlayout(app.DataPanel_5);
            app.GHzLabel_17 = uilabel(app.GridLayout2_19);
            app.GHzLabel_17.Text = 'GHz';
            app.GHzLabel_18 = uilabel(app.GridLayout2_19);
            app.GHzLabel_18.Text = 'GHz';
            app.mLabel_5 = uilabel(app.GridLayout2_19);
            app.mLabel_5.Text = 'μm';
            app.FminEditField_5Label_2 = uilabel(app.GridLayout2_19);
            app.FminEditField_5Label_2.Text = 'Fmin';
            app.FminEditField_5 = uieditfield(app.GridLayout2_19, 'numeric');
            app.FminEditField_5.ValueDisplayFormat = '%11.3f';
            app.FminEditField_5.Value = 1;
            app.FmaxEditField_5Label_2 = uilabel(app.GridLayout2_19);
            app.FmaxEditField_5Label_2.Text = 'Fmax';
            app.FmaxEditField_5 = uieditfield(app.GridLayout2_19, 'numeric');
            app.FmaxEditField_5.ValueDisplayFormat = '%11.3f';
            app.FmaxEditField_5.Value = 3;
            app.tEditField_5Label_2 = uilabel(app.GridLayout2_19);
            app.tEditField_5Label_2.Text = 't';
            app.tEditField_5 = uieditfield(app.GridLayout2_19, 'numeric');
            app.tEditField_5.ValueDisplayFormat = '%11.3f';
            app.tEditField_5.Value = 35;
            app.Z0EditField_15Label = uilabel(app.GridLayout2_19);
            app.Z0EditField_15Label.Text = 'Z0';
            app.Z0EditField_15 = uieditfield(app.GridLayout2_19, 'numeric');
            app.Z0EditField_15.ValueDisplayFormat = '%11.2f';
            app.Z0EditField_15.Editable = 'off';
            app.Z0EditField_15.Value = 50;
            app.Label_68 = uilabel(app.GridLayout2_19);
            app.Label_68.Text = 'Ω';
            app.AutresparamtresPanel_6 = uipanel(app.CalculTab_8);
            app.AutresparamtresPanel_6.Title = 'Autres paramètres';
            app.GridLayout3_22 = uigridlayout(app.AutresparamtresPanel_6);
            app.mmLabel_152 = uilabel(app.GridLayout3_22);
            app.mmLabel_152.Text = 'mm';
            app.mmLabel_153 = uilabel(app.GridLayout3_22);
            app.mmLabel_153.Text = 'mm';
            app.mmLabel_154 = uilabel(app.GridLayout3_22);
            app.mmLabel_154.Text = 'mm';
            app.mmLabel_155 = uilabel(app.GridLayout3_22);
            app.mmLabel_155.Text = 'mm';
            app.mmLabel_156 = uilabel(app.GridLayout3_22);
            app.mmLabel_156.Text = 'mm';
            app.FiEditField_18Label = uilabel(app.GridLayout3_22);
            app.FiEditField_18Label.Text = 'Fi';
            app.FiEditField_5 = uieditfield(app.GridLayout3_22, 'numeric');
            app.FiEditField_5.ValueDisplayFormat = '%11.3f';
            app.FiEditField_5.Editable = 'off';
            app.WfEditField_18Label = uilabel(app.GridLayout3_22);
            app.WfEditField_18Label.Text = 'Wf';
            app.WfEditField_5 = uieditfield(app.GridLayout3_22, 'numeric');
            app.WfEditField_5.ValueDisplayFormat = '%11.3f';
            app.WfEditField_5.Editable = 'off';
            app.GpfEditField_18Label = uilabel(app.GridLayout3_22);
            app.GpfEditField_18Label.Text = 'Gpf';
            app.GpfEditField_5 = uieditfield(app.GridLayout3_22, 'numeric');
            app.GpfEditField_5.ValueDisplayFormat = '%11.3f';
            app.GpfEditField_5.Editable = 'off';
            app.LgEditField_18Label = uilabel(app.GridLayout3_22);
            app.LgEditField_18Label.Text = 'Lg';
            app.LgEditField_5 = uieditfield(app.GridLayout3_22, 'numeric');
            app.LgEditField_5.ValueDisplayFormat = '%11.3f';
            app.LgEditField_5.Editable = 'off';
            app.WgEditField_18Label = uilabel(app.GridLayout3_22);
            app.WgEditField_18Label.Text = 'Wg';
            app.WgEditField_5 = uieditfield(app.GridLayout3_22, 'numeric');
            app.WgEditField_5.ValueDisplayFormat = '%11.3f';
            app.WgEditField_5.Editable = 'off';
            app.E6S6Tab = uitab(app.TabGroup);
            app.E6S6Tab.Title = 'E6->S6';
            app.TabGroup2_10 = uitabgroup(app.E6S6Tab);
            app.EntrainementTab_10 = uitab(app.TabGroup2_10);
            app.EntrainementTab_10.Title = 'Entrainement';
            app.UIAxes_6 = uiaxes(app.EntrainementTab_10);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'Valeur de la base de donnée')
            ylabel(app.UIAxes_6, 'Valeur calculée')
            app.UIAxes_6.LineStyleOrder = {'-'};
            app.ParamtresPanel_6 = uipanel(app.EntrainementTab_10);
            app.ParamtresPanel_6.Title = 'Paramètres';
            app.GridLayout10_6 = uigridlayout(app.ParamtresPanel_6);
            app.PourcentagevalidationEditField_6Label = uilabel(app.GridLayout10_6);
            app.PourcentagevalidationEditField_6Label.Text = 'Pourcentage validation';
            app.PourcentagevalidationEditField_6 = uieditfield(app.GridLayout10_6, 'numeric');
            app.PourcentagevalidationEditField_6.Value = 25;
            app.PourcentagetestEditField_6Label = uilabel(app.GridLayout10_6);
            app.PourcentagetestEditField_6Label.Text = 'Pourcentage test';
            app.PourcentagetestEditField_6 = uieditfield(app.GridLayout10_6, 'numeric');
            app.PourcentagetestEditField_6.Value = 5;
            app.PourcentageEntrainementEditField_6Label = uilabel(app.GridLayout10_6);
            app.PourcentageEntrainementEditField_6Label.Text = 'Pourcentage Entrainement';
            app.PourcentageEntrainementEditField_6 = uieditfield(app.GridLayout10_6, 'numeric');
            app.PourcentageEntrainementEditField_6.Value = 70;
            app.EpochsEditField_6Label = uilabel(app.GridLayout10_6);
            app.EpochsEditField_6Label.Text = 'Epochs';
            app.EpochsEditField_6 = uieditfield(app.GridLayout10_6, 'numeric');
            app.EpochsEditField_6.Value = 100;
            app.CouchesneuronnesEditField_6Label_2 = uilabel(app.GridLayout10_6);
            app.CouchesneuronnesEditField_6Label_2.Text = 'Couches & neuronnes';
            app.CouchesneuronnesEditField_6 = uieditfield(app.GridLayout10_6, 'text');
            app.CouchesneuronnesEditField_6.ValueChangedFcn = createCallbackFcn(app, @CouchesneuronnesEditField_ValueChanged, true);
            app.CouchesneuronnesEditField_6.Value = '[13]';
            app.CouchesFormatLabel_6 = uilabel(app.GridLayout10_6);
            app.CouchesFormatLabel_6.Text = 'Format : [nbNeuronnes,nbNeuronnes,....,nbNeuronnes]';
            app.OptionsPanel_6 = uipanel(app.EntrainementTab_10);

            app.OptionsPanel_6.Title = 'Options';
            app.GridLayout11_6 = uigridlayout(app.OptionsPanel_6);
            app.SlectionnerlabasededonneButton_6 = uibutton(app.GridLayout11_6, 'push');
            app.SlectionnerlabasededonneButton_6.ButtonPushedFcn = createCallbackFcn(app, @SlectionnerlabasededonneButton_Pushed, true);
            app.SlectionnerlabasededonneButton_6.Text = 'Sélectionner la base de donnée';
            app.EnregistrerlerseauButton_6 = uibutton(app.GridLayout11_6, 'push');
            app.EnregistrerlerseauButton_6.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerlerseauButton_Pushed, true);
            app.EnregistrerlerseauButton_6.Enable = 'off';
            app.EnregistrerlerseauButton_6.Text = 'Enregistrer le réseau';
            app.EntranerlerseauButton_6 = uibutton(app.GridLayout11_6, 'push');
            app.EntranerlerseauButton_6.ButtonPushedFcn = createCallbackFcn(app, @EntranerlerseauButton_Pushed, true);
            app.EntranerlerseauButton_6.Text = 'Entraîner le réseau';
            app.RechercheCouchenbNeuronnesoptimauxButton_6 = uibutton(app.GridLayout11_6, 'push');
            app.RechercheCouchenbNeuronnesoptimauxButton_6.ButtonPushedFcn = createCallbackFcn(app, @RechercheCouchenbNeuronnesoptimauxButton_Pushed, true);
            app.RechercheCouchenbNeuronnesoptimauxButton_6.Text = {'Recherche Couche & nbNeuronnes'; 'optimaux'};
            app.nomBdLabel_6 = uilabel(app.EntrainementTab_10);
            app.nomBdLabel_6.Text = 'bd.mat';
            app.BDdentranementLabel_6 = uilabel(app.EntrainementTab_10);
            app.BDdentranementLabel_6.Text = 'BD d''entraînement';
            app.FichierdesauvegardeLabel_6 = uilabel(app.EntrainementTab_10);
            app.FichierdesauvegardeLabel_6.Text = 'Fichier de sauvegarde';
            app.ChemSauvLabel_6 = uilabel(app.EntrainementTab_10);
            app.ChemSauvLabel_6.Text = '';
            app.CommandesPanel_7 = uipanel(app.EntrainementTab_10);
            app.CommandesPanel_7.Title = 'Commandes';
            app.GridLayout12_7 = uigridlayout(app.CommandesPanel_7);
            app.WpButton_6 = uibutton(app.GridLayout12_7, 'push');
            app.WpButton_6.ButtonPushedFcn = createCallbackFcn(app, @WpButton_Pushed, true);
            app.WpButton_6.Text = 'Wp';
            app.LpButton_6 = uibutton(app.GridLayout12_7, 'push');
            app.LpButton_6.ButtonPushedFcn = createCallbackFcn(app, @LpButton_Pushed, true);
            app.LpButton_6.Text = 'Lp';
            app.Label_61 = uilabel(app.GridLayout12_7);
            app.Label_61.Text = '';
            app.Label_62 = uilabel(app.GridLayout12_7);
            app.Label_62.Text = '';
            app.FiButton_6 = uibutton(app.GridLayout12_7, 'push');
            app.FiButton_6.ButtonPushedFcn = createCallbackFcn(app, @FiButton_1Pushed, true);
            app.FiButton_6.Text = 'Fi';
            app.WfButton_6 = uibutton(app.GridLayout12_7, 'push');
            app.WfButton_6.ButtonPushedFcn = createCallbackFcn(app, @WfButton_1Pushed, true);
            app.WfButton_6.Text = 'Wf';
            app.GpfButton_6 = uibutton(app.GridLayout12_7, 'push');
            app.GpfButton_6.ButtonPushedFcn = createCallbackFcn(app, @GpfButton_1Pushed, true);
            app.GpfButton_6.Text = 'Gpf';
            app.LgButton_6 = uibutton(app.GridLayout12_7, 'push');
            app.LgButton_6.ButtonPushedFcn = createCallbackFcn(app, @LgButton_1Pushed, true);
            app.LgButton_6.Text = 'Lg';
            app.WgButton_6 = uibutton(app.GridLayout12_7, 'push');
            app.WgButton_6.ButtonPushedFcn = createCallbackFcn(app, @WgButton_1Pushed, true);
            app.WgButton_6.Text = 'Wg';
            app.Label_63 = uilabel(app.GridLayout12_7);
            app.Label_63.Text = '';
            app.Label_64 = uilabel(app.GridLayout12_7);
            app.Label_64.Text = '';
            app.Label_65 = uilabel(app.GridLayout12_7);
            app.Label_65.Text = '';
            app.Label_66 = uilabel(app.GridLayout12_7);
            app.Label_66.Text = '';
            app.Label_67 = uilabel(app.GridLayout12_7);
            app.Label_67.Text = '';
            app.ErreurLabel_6 = uilabel(app.EntrainementTab_10);
            app.ErreurLabel_6.Visible = 'off';
            app.ErreurLabel_6.Text = 'Erreur';
            app.ControlPlotButtonGroup_6 = uibuttongroup(app.EntrainementTab_10);
            app.ControlPlotButtonGroup_6.SelectionChangedFcn = createCallbackFcn(app, @ControlPlotButtonGroup_6SelectionChanged, true);
            app.ControlPlotButtonGroup_6.Title = 'Control Plot';
            app.PlotPerformanceButton_6 = uitogglebutton(app.ControlPlotButtonGroup_6);
            app.PlotPerformanceButton_6.Text = 'Plot Performance';
            app.PlotPerformanceButton_6.Value = true;
            app.PlotTrainingButton_6 = uitogglebutton(app.ControlPlotButtonGroup_6);
            app.PlotTrainingButton_6.Text = 'Plot Training';
            app.PlotGeneralPerformanceButton_6 = uitogglebutton(app.ControlPlotButtonGroup_6);
            app.PlotGeneralPerformanceButton_6.Text = 'Plot General Performance';
            app.CouchesoptimalesLabel_6 = uilabel(app.EntrainementTab_10);
            app.CouchesoptimalesLabel_6.Text = 'Couches optimales';
            app.OptiLabel1_6 = uilabel(app.EntrainementTab_10);
            app.OptiLabel1_6.Text = '';
            app.ValeurspardfautButton_6 = uibutton(app.EntrainementTab_10, 'push');
            app.ValeurspardfautButton_6.ButtonPushedFcn = createCallbackFcn(app, @ValeurspardfautButton_Pushed, true);
            app.ValeurspardfautButton_6.Text = 'Valeurs par défaut';
            app.ErreurmoyenneEditField_6Label = uilabel(app.EntrainementTab_10);
            app.ErreurmoyenneEditField_6Label.Text = 'Erreur moyenne';
            app.ErreurmoyenneEditField_6 = uieditfield(app.EntrainementTab_10, 'text');
            app.ErreurmoyenneEditField_6.Editable = 'off';
            app.ErreurmoyenneEditField_6.Value = '0';
            app.CalculTab_10 = uitab(app.TabGroup2_10);
            app.CalculTab_10.Title = 'Calcul';
            app.UIAxesSij6 = uiaxes(app.CalculTab_10);
            title(app.UIAxesSij6, 'patch antenna at 2.45GHz')
            xlabel(app.UIAxesSij6, 'Frequency (GHz)')
            ylabel(app.UIAxesSij6, 'S11 (dB)')
            app.EntresPanel_15 = uipanel(app.CalculTab_10);
            app.EntresPanel_15.Title = 'Entrées';
            app.GridLayout2_22 = uigridlayout(app.EntresPanel_15);
            app.mmLabel_165 = uilabel(app.GridLayout2_22);
            app.mmLabel_165.Text = 'mm';
            app.Label_80 = uilabel(app.GridLayout2_22);
            app.Label_80.Text = '';
            app.GHzLabel_22 = uilabel(app.GridLayout2_22);
            app.GHzLabel_22.Text = 'GHz';
            app.HEditField_17Label = uilabel(app.GridLayout2_22);
            app.HEditField_17Label.Text = 'H';
            app.HEditField_6 = uieditfield(app.GridLayout2_22, 'numeric');
            app.HEditField_6.ValueDisplayFormat = '%11.3f';
            app.HEditField_6.Value = 1.6;
            app.ErEditField_17Label = uilabel(app.GridLayout2_22);
            app.ErEditField_17Label.Text = 'Er';
            app.ErEditField_6 = uieditfield(app.GridLayout2_22, 'numeric');
            app.ErEditField_6.ValueDisplayFormat = '%11.3f';
            app.ErEditField_6.Value = 4.3;
            app.FrEditField_17Label = uilabel(app.GridLayout2_22);
            app.FrEditField_17Label.Text = 'Fr';
            app.FrEditField_6 = uieditfield(app.GridLayout2_22, 'numeric');
            app.FrEditField_6.ValueDisplayFormat = '%11.3f';
            app.FrEditField_6.Value = 2.45;
            app.SortiesRNN17sortiesPanel_2 = uipanel(app.CalculTab_10);
            app.SortiesRNN17sortiesPanel_2.Title = 'Sorties RNN1 à 7 sorties';
            app.GridLayout3_24 = uigridlayout(app.SortiesRNN17sortiesPanel_2);
            app.mmLabel_166 = uilabel(app.GridLayout3_24);
            app.mmLabel_166.Text = 'mm';
            app.mmLabel_167 = uilabel(app.GridLayout3_24);
            app.mmLabel_167.Text = 'mm';
            app.mmLabel_168 = uilabel(app.GridLayout3_24);
            app.mmLabel_168.Text = 'mm';
            app.mmLabel_169 = uilabel(app.GridLayout3_24);
            app.mmLabel_169.Text = 'mm';
            app.mmLabel_170 = uilabel(app.GridLayout3_24);
            app.mmLabel_170.Text = 'mm';
            app.mmLabel_171 = uilabel(app.GridLayout3_24);
            app.mmLabel_171.Text = 'mm';
            app.mmLabel_172 = uilabel(app.GridLayout3_24);
            app.mmLabel_172.Text = 'mm';
            app.WpEditField_20Label = uilabel(app.GridLayout3_24);
            app.WpEditField_20Label.Text = 'Wp';
            app.WpEditField_6 = uieditfield(app.GridLayout3_24, 'numeric');
            app.WpEditField_6.ValueDisplayFormat = '%11.3f';
            app.WpEditField_6.Editable = 'off';
            app.LpEditField_20Label = uilabel(app.GridLayout3_24);
            app.LpEditField_20Label.Text = 'Lp';
            app.LpEditField_6 = uieditfield(app.GridLayout3_24, 'numeric');
            app.LpEditField_6.ValueDisplayFormat = '%11.3f';
            app.LpEditField_6.Editable = 'off';
            app.FiEditField_20Label = uilabel(app.GridLayout3_24);
            app.FiEditField_20Label.Text = 'Fi';
            app.FiEditField_6 = uieditfield(app.GridLayout3_24, 'numeric');
            app.FiEditField_6.ValueDisplayFormat = '%11.3f';
            app.FiEditField_6.Editable = 'off';
            app.WfEditField_20Label = uilabel(app.GridLayout3_24);
            app.WfEditField_20Label.Text = 'Wf';
            app.WfEditField_6 = uieditfield(app.GridLayout3_24, 'numeric');
            app.WfEditField_6.ValueDisplayFormat = '%11.3f';
            app.WfEditField_6.Editable = 'off';
            app.GpfEditField_20Label = uilabel(app.GridLayout3_24);
            app.GpfEditField_20Label.Text = 'Gpf';
            app.GpfEditField_6 = uieditfield(app.GridLayout3_24, 'numeric');
            app.GpfEditField_6.ValueDisplayFormat = '%11.3f';
            app.GpfEditField_6.Editable = 'off';
            app.LgEditField_20Label = uilabel(app.GridLayout3_24);
            app.LgEditField_20Label.Text = 'Lg';
            app.LgEditField_6 = uieditfield(app.GridLayout3_24, 'numeric');
            app.LgEditField_6.ValueDisplayFormat = '%11.3f';
            app.LgEditField_6.Editable = 'off';
            app.WgEditField_20Label = uilabel(app.GridLayout3_24);
            app.WgEditField_20Label.Text = 'Wg';
            app.WgEditField_6 = uieditfield(app.GridLayout3_24, 'numeric');
            app.WgEditField_6.ValueDisplayFormat = '%11.3f';
            app.WgEditField_6.Editable = 'off';
            app.Err_moyEditFieldLabel_9 = uilabel(app.GridLayout3_24);
            app.Err_moyEditFieldLabel_9.Text = 'Err_moy';
            app.ErrmoyEditField_6 = uieditfield(app.GridLayout3_24, 'numeric');
            app.ErrmoyEditField_6.ValueDisplayFormat = '%11.e';
            app.ErrmoyEditField_6.Editable = 'off';
            app.DfautButton_7 = uibutton(app.CalculTab_10, 'push');
            app.DfautButton_7.ButtonPushedFcn = createCallbackFcn(app, @DfautButton_Pushed, true);
            app.DfautButton_7.Text = 'Défaut';
            app.Image_10 = uiimage(app.CalculTab_10);
            app.Image_10.ImageSource = 'antena_indic.jpg';
            app.EffacerlaligneButton_6 = uibutton(app.CalculTab_10, 'push');
            app.EffacerlaligneButton_6.ButtonPushedFcn = createCallbackFcn(app, @EffacerlaligneButton_Pushed, true);
            app.EffacerlaligneButton_6.Enable = 'off';
            app.EffacerlaligneButton_6.Text = 'Effacer la ligne';
            app.ToutEffacerButton_6 = uibutton(app.CalculTab_10, 'push');
            app.ToutEffacerButton_6.ButtonPushedFcn = createCallbackFcn(app, @ToutEffacerButton_Pushed, true);
            app.ToutEffacerButton_6.Enable = 'off';
            app.ToutEffacerButton_6.Text = 'Tout Effacer';
            app.TransfrerversCSTButton_6 = uibutton(app.CalculTab_10, 'push');
            app.TransfrerversCSTButton_6.ButtonPushedFcn = createCallbackFcn(app, @TransfrerversCSTButton_6Pushed, true);
            app.TransfrerversCSTButton_6.Enable = 'off';
            app.TransfrerversCSTButton_6.Text = 'Transférer vers CST';
            app.UITable_6 = uitable(app.CalculTab_10);
            app.UITable_6.ColumnName = {'N°'; 'H'; 'Er'; 'Fr'; 'Z0'; 'Wp'; 'Lp'; 'Fi'; 'Wf'; 'Gpf'; 'Lg'; 'Wg'; 'Err. moy.'};
            app.UITable_6.RowName = {''};
            app.UITable_6.CellSelectionCallback = createCallbackFcn(app, @UITable_CellSelection, true);
            app.DataPanel_7 = uipanel(app.CalculTab_10);
            app.DataPanel_7.Title = 'Data';
            app.GridLayout2_23 = uigridlayout(app.DataPanel_7);
            app.GHzLabel_23 = uilabel(app.GridLayout2_23);
            app.GHzLabel_23.Text = 'GHz';
            app.GHzLabel_24 = uilabel(app.GridLayout2_23);
            app.GHzLabel_24.Text = 'GHz';
            app.mLabel_7 = uilabel(app.GridLayout2_23);
            app.mLabel_7.Text = 'μm';
            app.FminEditField_7Label = uilabel(app.GridLayout2_23);
            app.FminEditField_7Label.Text = 'Fmin';
            app.FminEditField_6 = uieditfield(app.GridLayout2_23, 'numeric');
            app.FminEditField_6.ValueDisplayFormat = '%11.3f';
            app.FminEditField_6.Value = 1;
            app.FmaxEditField_7Label = uilabel(app.GridLayout2_23);
            app.FmaxEditField_7Label.Text = 'Fmax';
            app.FmaxEditField_6 = uieditfield(app.GridLayout2_23, 'numeric');
            app.FmaxEditField_6.ValueDisplayFormat = '%11.3f';
            app.FmaxEditField_6.Value = 3;
            app.tEditField_7Label = uilabel(app.GridLayout2_23);
            app.tEditField_7Label.Text = 't';
            app.tEditField_6 = uieditfield(app.GridLayout2_23, 'numeric');
            app.tEditField_6.ValueDisplayFormat = '%11.3f';
            app.tEditField_6.Value = 35;
            app.Label_81 = uilabel(app.GridLayout2_23);
            app.Label_81.Text = 'Ω';
            app.Z0EditField_17Label = uilabel(app.GridLayout2_23);
            app.Z0EditField_17Label.Text = 'Z0';
            app.Z0EditField_6 = uieditfield(app.GridLayout2_23, 'numeric');
            app.Z0EditField_6.ValueDisplayFormat = '%11.3f';
            app.Z0EditField_6.Editable = 'off';
            app.Z0EditField_6.Value = 50;
            app.RNNInputHErFrOutputWpLpFiWfGpfLgWgPanel_2 = uipanel(app.CalculTab_10);
            app.RNNInputHErFrOutputWpLpFiWfGpfLgWgPanel_2.Title = 'RNN : Input ( H,Er,Fr ) --- Output ( Wp,Lp,Fi,Wf,Gpf,Lg,Wg )';
            app.ReseaudeneuronneButton_6 = uibutton(app.RNNInputHErFrOutputWpLpFiWfGpfLgWgPanel_2, 'push');
            app.ReseaudeneuronneButton_6.ButtonPushedFcn = createCallbackFcn(app, @ReseaudeneuronneButton_Pushed, true);
            app.ReseaudeneuronneButton_6.Text = 'Reseau de neuronne';
            app.BasedeDonneeButton_6 = uibutton(app.RNNInputHErFrOutputWpLpFiWfGpfLgWgPanel_2, 'push');
            app.BasedeDonneeButton_6.ButtonPushedFcn = createCallbackFcn(app, @BasedeDonneeButton_Pushed, true);
            app.BasedeDonneeButton_6.Text = 'Base de Donnee';
            app.neuron_label_6 = uilabel(app.RNNInputHErFrOutputWpLpFiWfGpfLgWgPanel_2);
            app.neuron_label_6.Text = 'RNN6_10_60.mat';
            app.bd_label_6 = uilabel(app.RNNInputHErFrOutputWpLpFiWfGpfLgWgPanel_2);
            app.bd_label_6.Text = 'bd.mat';
            app.CalculButton_11 = uibutton(app.CalculTab_10, 'push');
            app.CalculButton_11.ButtonPushedFcn = createCallbackFcn(app, @CalculButton_Pushed, true);
            app.CalculButton_11.Text = 'Calcul';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end